classdef CalibNeckM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DatacntEditField           matlab.ui.control.NumericEditField
        DatacntEditFieldLabel      matlab.ui.control.Label
        MotioncompleteButton       matlab.ui.control.Button
        InstructionsTextArea       matlab.ui.control.TextArea
        InstructionsTextAreaLabel  matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        NeckcalibratorGEN3Label    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DataType % Description
        NeckCanId % Description
        LpCanId % Description
        RecStruct  % Description
        NGeom % Description
        CreateStruct  % Description
        ExperimentSafeLimits % Description
        CBit  % Description
        RecNames % Description
        TypeFlags % Description
        mytimer % Description
        datacnt % Description
        rslt % Description
        tclk % Description
    end
    
    methods (Access = private)
        
        function DispMsgBox(~,msg)
              h = msgbox( ['\fontsize{12}',msg]) ;
              while( isvalid(h))
                  pause(0.2) ; 
              end
        end
        
        function  update_neckcalib_display(app)
            try
                roll1 = FetchObj([hex2dec('2204'),54,app.LpCanId],app.DataType.float,'Roll') ;
                r = GetSnap(app.RecNames,app.TypeFlags,app.NeckCanId) ; 
                roll2 = FetchObj([hex2dec('2204'),54,app.LpCanId],app.DataType.float,'Roll') ;
                roll = 0.5 * (roll1 + roll2) ;
                cnt = app.datacnt ; 
                cnt = cnt+ 1 ; 
                app.datacnt = cnt ; 
                app.rslt(1,cnt) = r.UserPos ;
                app.rslt(2,cnt) = r.PotPosition1 ;
                app.rslt(3,cnt) = r.PotRat1 ; 
                app.rslt(4,cnt) = r.PotPosition2  ;
                app.rslt(5,cnt) = r.PotRat2 ; 
                app.rslt(6,cnt) = etime(clock , app.tclk )  ; %#ok<CLOCK,DETIM> 
                app.rslt(7,cnt) = roll ; 

                app.DatacntEditField.Value = app.datacnt ;
                
                if ( cnt > 10000 )
                    app.MotioncompleteButtonPushed() ; 
                end
            catch
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InstructionsTextArea.Value = "Press start to release the brake";

            global RecStruct %#ok<GVMIS> 
            app.DataType = GetDataType(); 
            app.CreateStruct =  struct('Interpreter','tex','WindowStyle' , 'modal') ; 

            app.NeckCanId = 30; 
            app.LpCanId = 124 ; 
            app.RecStruct = RecStruct  ;
            app.NGeom = struct('Pot1Rat2Rad',1,'Pot2Rat2Rad',1,'Pot1RatCenter',0.5,'Pot2RatCenter',0.5) ;
            app.NGeom.Pot1Rat2Rad = FetchObj([hex2dec('2225'),1,app.NeckCanId],app.DataType.float,'Pot1Rat2Rad') ; 
            app.NGeom.Pot2Rat2Rad = FetchObj([hex2dec('2225'),2,app.NeckCanId],app.DataType.float,'Pot2Rat2Rad') ; 
            
            app.NGeom.Pot1RatCenter = FetchObj([hex2dec('2225'),3,app.NeckCanId],app.DataType.float,'Pot1RatCenter') ; 
            app.NGeom.Pot2RatCenter = FetchObj([hex2dec('2225'),4,app.NeckCanId],app.DataType.float,'Pot2RatCenter') ; 
            
            if ~isequal( app.RecStruct.Axis ,'Neck')
                app.DispMsgBox('This application is only for NECK')  ;
                app.UIFigureCloseRequest() ;
            end
            app.ExperimentSafeLimits = struct( 'OverCurrent' , 16 , 'Undervoltage' , 20  , 'Overvoltage' , 60) ; 

            s = SGetState() ; 
            app.CBit = s.Bit ; % FetchObj([hex2dec('2226'),51,app.LpCanId],app.DataType.long,'CBIT') ; 
            if ~app.CBit.Configured % Just approve what we have 
                MyCgf = SaveCfg([],'StamCfg.jsn') ; %#ok<NASGU> 
                ProgCfg('StamCfg.jsn') ; 
            end
            SendObj([hex2dec('2225'),5,app.NeckCanId],app.ExperimentSafeLimits.OverCurrent,app.DataType.float,'ClaControlPars.PhaseOverCurrent') ; 
            SendObj([hex2dec('2225'),6,app.NeckCanId],app.ExperimentSafeLimits.Overvoltage,app.DataType.float,'ClaControlPars.VDcMax') ; 
            SendObj([hex2dec('2225'),7,app.NeckCanId],app.ExperimentSafeLimits.Undervoltage,app.DataType.float,'ClaControlPars.VDcMin') ; 
            
            % Be a master blaster
            SendObj([hex2dec('2220'),5,app.LpCanId],hex2dec('1234'),app.DataType.long,'Brake set/release') ; 
            % Block servo communication 
            SendObj([hex2dec('2220'),13,app.LpCanId],1,app.DataType.long,'Block servo communication') ; 
            
            % Set to manual mode
            SendObj([hex2dec('2220'),12,app.NeckCanId],RecStruct.Enums.SysModes.E_SysMotionModeManual,app.DataType.long,'Set system mode to manual') ;
            
            % Set motor off 
            SendObj([hex2dec('2220'),4,app.NeckCanId],0,app.DataType.long,'Set motor enable/disable') ;
            
            % Set to open loop commutation 
            % SendObj([hex2dec('220d'),RecStruct.CfgTable.CommutationMode.Ind],RecStruct.Enums.CommutationModes.COM_OPEN_LOOP ,DataType.long,'Set open loop commutation') ;
            
            
            % Reset any possible failure 
            SendObj([hex2dec('2220'),10,app.NeckCanId],1,app.DataType.long,'Reset fault') ;
            
            %TestStructFields(ExpMng,{'CurLevel','nCycle','WaitTime','CycleTime','npp'},'ExpMng') ; 
            
            % Get Vdc 
            Vdc = FetchObj([hex2dec('2221'),36,app.NeckCanId],app.DataType.float,'Pot1Rat2Rad') ; %#ok<NASGU> 

            app.MotioncompleteButton.Visible = 'off' ;

            % Setup the recorder 
            % RecInitAction = struct( 'InitRec' , 1 , 'BringRec' , 0 ,'ProgRec' , 1 ,'Struct' , 1 ,'T' , RecTime ,'MaxLen', MaxRecLen ) ; 
            % RecBringAction = struct( 'InitRec' , 0 , 'BringRec' , 1 ,'ProgRec' , 0 ,'Struct' , 1 ,'BlockUpLoad', 1  ) ; 
            app.RecNames = {'Iq','PotRat1','PotRat2','UserPos','CBit','PotPosition1','PotPosition2'}  ; 
            % L_RecStruct = RecStruct ;
            % L_RecStruct.BlockUpLoad = 0 ; 
            app.TypeFlags = SetSnap( app.RecNames , app.NeckCanId ) ; 
                        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.MotioncompleteButton.Visible = 'on' ;
            SendObj([hex2dec('2223'),8,app.NeckCanId],1,app.DataType.long,'Disable potentiometer match test') ; 
            SendObj([hex2dec('2220'),23,app.NeckCanId],2,app.DataType.long,'Brake BrakeControlOverride') ; 
            SendObj([hex2dec('2220'),20,app.NeckCanId],1,app.DataType.long,'Brake set/release') ; 
            app.InstructionsTextArea.Value = {'Move the tail to to 90deg and back', 'repeat twice, then go to center'};

            app.mytimer = timer(...
                'ExecutionMode', 'fixedSpacing', ...       % Run timer repeatedly.
                'Period', 0.15) ; 
                %'TimerFcn', @update_neckcalib_display); % Specify callback function.% Update handles structure
                app.mytimer.TimerFcn = @(~,~)app.update_neckcalib_display;

            app.datacnt = 0 ; 
            app.rslt = zeros( 8, 10000) ; 
            app.tclk = clock ; %#ok<CLOCK> 

            start (app.mytimer) ;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isequal ( class(app.mytimer) ,'timer' ) && isvalid(app.mytimer)   
                delete( app.mytimer) ; 
            end
            delete(app)
            
        end

        % Button pushed function: MotioncompleteButton
        function MotioncompleteButtonPushed(app, event)
            stop (app.mytimer) ;
            delete( app.mytimer);

            cnt = app.datacnt  ;
            app.rslt = app.rslt(:,1:cnt); 
            UserPos = app.rslt(1,:) ;
            PotPosition1 = app.rslt(2,:) ;
            PotRat1 = app.rslt(3,:) ; 
            PotPosition2 =app.rslt(4,:) ;
            PotRat2 = app.rslt(5,:)  ; 
            t = app.rslt(6,:)   ; 
            
            Roll =  app.rslt(7,:); 

            Geom = app.NGeom  ;
            save  NeckCalibRslt.mat Geom Roll UserPos PotRat1 PotRat2 PotPosition1 PotPosition2 t

            plot( app.UIAxes, t , Roll,t , UserPos - UserPos(1), ...
                t, (PotRat1 - PotRat1(1)) * app.NGeom.Pot1Rat2Rad  , ...
                t, (PotRat2 - PotRat2(1)) * app.NGeom.Pot2Rat2Rad ) ; 

            AnalyzeNeckCalib('NeckCalibRslt',[]) ; 

            crslt = load('AnalyzeNeckRslt.mat'); 
            if ~isempty(crslt.errMsg)
                app.InstructionsTextArea.Value = {'Error in calibration:', crslt.errMsg} ; 
            else
                legend( app.UIAxes, 'Roll','UserPos','Pot1','Pot2') ; 
                app.MotioncompleteButton.Visible = 'off' ;

                Calib0 = SaveCalib([]) ; 
                CalibM = MergeStruct( Calib0 , crslt.Calib , 1  ); 

                 ButtonName = questdlg('\fontsize{12}Where to save results?', ...
                                     'Quality Decisiom', ...
                                     'Flash','RAM','Cancel', struct('Default','Cancel','Interpreter','tex') );
                SaveCalib(CalibM,'CalibTmp.jsn') ;
                cfg = struct('ProjType','Neck') ; 
                if isequal(ButtonName,'Flash') 
                    ProgCalib('CalibTmp.jsn',1,cfg) ; 
                    app.InstructionsTextArea.Value = 'Calibration saved to flash' ; 
                elseif isequal(ButtonName,'RAM') 
                    ProgCalib('CalibTmp.jsn',0,cfg) ; 
                    app.InstructionsTextArea.Value = 'Calibration saved to flash' ; 
                else
                    app.InstructionsTextArea.Value = 'Calibration Not in use' ; 
                end

            end
            
            %ClaState.Pot.Position[0] = ( ClaState.Pot.PotFilt[0] - Calib.PotCenter1 - Geom.Pot1RatCenter) * ( 1.0f + Calib.PotGainFac1) * Geom.Pot1Rat2Rad ;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Results')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Angle')
            zlabel(app.UIAxes, 'Z')
            app.NeckcalibratorGEN3Label = uilabel(app.UIFigure);
            app.NeckcalibratorGEN3Label.Text = 'Neck calibrator GEN3 ';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.MotioncompleteButton = uibutton(app.UIFigure, 'push');
            app.MotioncompleteButton.ButtonPushedFcn = createCallbackFcn(app, @MotioncompleteButtonPushed, true);
            app.MotioncompleteButton.Text = 'Motion complete';
            app.DatacntEditFieldLabel = uilabel(app.UIFigure);
            app.DatacntEditFieldLabel.Text = 'Data cnt';
            app.DatacntEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalibNeckM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end