classdef CurrentGainCalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CalculateButton              matlab.ui.control.Button
        CorrectionfactorsvsoldPanel  matlab.ui.container.Panel
        ErrorTextArea                matlab.ui.control.TextArea
        CEditField                   matlab.ui.control.NumericEditField
        CEditFieldLabel              matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        BEditFieldLabel              matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        AEditFieldLabel              matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        PeaktopeakcurrentmeasurementresultsPanel  matlab.ui.container.Panel
        NominalresultEditField       matlab.ui.control.NumericEditField
        NominalresultEditFieldLabel  matlab.ui.control.Label
        LowestresultEditField        matlab.ui.control.NumericEditField
        LowestresultEditFieldLabel   matlab.ui.control.Label
        HighestresultEditField       matlab.ui.control.NumericEditField
        HighestresultEditFieldLabel  matlab.ui.control.Label
        CurrentgainscalibratorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        AmpNominal % Description
        v % Description
        errmsg % Description
        DataType  % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AmpNominal)
            app.NominalresultEditField.Value = 2 * AmpNominal ; 
            app.AmpNominal = AmpNominal ; 
            app.errmsg  = "Not approved" ; 
            app.DataType = GetDataType() ; 
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % Load programmed calibration from flash 
            s = SGetState();
            if s.Bit.NoCalib 
                % No calibration yet exists ; invent a null calibration
                junk = SaveCalib(0) ; 
            else
                % Normal, take all the other values from the flash
                SendObj( [hex2dec('2302'),251] , 1, app.DataType.long ,'Read calibrations program from flash') ;  
                junk = SaveCalib([]) ; 
            end
            junk.ACurGainCorr = app.v(1)-1;
            junk.BCurGainCorr = app.v(2)-1;
            junk.CCurGainCorr = app.v(3)-1;

            ProgCalib( junk ,1,struct('ProjType','Neck')) ; 

            %SendObj( [hex2dec('2302'),253] , 1, app.DataType.long ,'Program calib to flash') ;  

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            motor(0); 
            AmpNom = mean([app.HighestresultEditField.Value ,app.LowestresultEditField.Value]) / 2 ;
            [app.v,app.errmsg] = AtpAnalyze('CurrentScales',AmpNom) ; 
            if isempty(app.errmsg) 
                app.AEditField.Visible = "on";
                app.BEditField.Visible = "on";
                app.CEditField.Visible = "on";
                app.ErrorTextArea.Visible = 'off' ; 
                app.AEditField.Value = app.v(1) ; 
                app.BEditField.Value = app.v(2) ; 
                app.CEditField.Value = app.v(3) ;
                app.CalibrateButton.Visible = "on" ; 
            else
                app.AEditField.Visible = "off";
                app.BEditField.Visible = "off";
                app.CEditField.Visible = "off";
                app.CalibrateButton.Visible = "off" ; 
                app.ErrorTextArea.Visible = 'on' ;                 
                app.ErrorTextArea.Value = app.errmsg ; 
            end 

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            rslt = struct('v',app.v,'errmsg',app.errmsg)  ; 
            save CurCalibMsg rslt ; 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CurrentgainscalibratorLabel = uilabel(app.UIFigure);
            app.CurrentgainscalibratorLabel.Text = 'Current gains calibrator';
            app.PeaktopeakcurrentmeasurementresultsPanel = uipanel(app.UIFigure);
            app.PeaktopeakcurrentmeasurementresultsPanel.Title = 'Peak to peak current measurement results';
            app.HighestresultEditFieldLabel = uilabel(app.PeaktopeakcurrentmeasurementresultsPanel);
            app.HighestresultEditFieldLabel.Text = 'Highest result';
            app.HighestresultEditField = uieditfield(app.PeaktopeakcurrentmeasurementresultsPanel, 'numeric');
            app.LowestresultEditFieldLabel = uilabel(app.PeaktopeakcurrentmeasurementresultsPanel);
            app.LowestresultEditFieldLabel.Text = 'Lowest result';
            app.LowestresultEditField = uieditfield(app.PeaktopeakcurrentmeasurementresultsPanel, 'numeric');
            app.NominalresultEditFieldLabel = uilabel(app.PeaktopeakcurrentmeasurementresultsPanel);
            app.NominalresultEditFieldLabel.Text = 'Nominal result';
            app.NominalresultEditField = uieditfield(app.PeaktopeakcurrentmeasurementresultsPanel, 'numeric');
            app.NominalresultEditField.Editable = 'off';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Visible = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.CorrectionfactorsvsoldPanel = uipanel(app.UIFigure);
            app.CorrectionfactorsvsoldPanel.Title = 'Correction factors vs: old';
            app.AEditFieldLabel = uilabel(app.CorrectionfactorsvsoldPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.CorrectionfactorsvsoldPanel, 'numeric');
            app.AEditField.Visible = 'off';
            app.BEditFieldLabel = uilabel(app.CorrectionfactorsvsoldPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.CorrectionfactorsvsoldPanel, 'numeric');
            app.BEditField.Visible = 'off';
            app.CEditFieldLabel = uilabel(app.CorrectionfactorsvsoldPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.CorrectionfactorsvsoldPanel, 'numeric');
            app.CEditField.Visible = 'off';
            app.ErrorTextArea = uitextarea(app.CorrectionfactorsvsoldPanel);
            app.ErrorTextArea.Visible = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentGainCalib(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end