classdef SelectFromList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DoneButton           matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        ListBoxLabel         matlab.ui.control.Label
        SelectFromLIstLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        arg % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg)
            app.arg = arg ; 
            app.ListBox.Items = arg.DataArea.List ; 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.arg.DataArea.Value = [] ; 
            UIFigureCloseRequest(app) ; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.arg.DataArea.Value = app.ListBox.Value; 
            UIFigureCloseRequest(app) ; 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectFromLIstLabel = uilabel(app.UIFigure);
            app.SelectFromLIstLabel.Text = 'Select From LIst';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectFromList(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end