classdef LoopDes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ActiveAxisLabel                matlab.ui.control.Label
        GoPositionButton               matlab.ui.control.Button
        McircEditField                 matlab.ui.control.NumericEditField
        McircEditFieldLabel            matlab.ui.control.Label
        PIDPanel                       matlab.ui.container.Panel
        MaximumaccelerationEditField   matlab.ui.control.NumericEditField
        MaxaccelerationEditFieldLabel  matlab.ui.control.Label
        PosKpEditField                 matlab.ui.control.NumericEditField
        PosKpEditFieldLabel            matlab.ui.control.Label
        SpeedKiEditField               matlab.ui.control.NumericEditField
        SpeedKiEditFieldLabel          matlab.ui.control.Label
        SpeedKpEditField               matlab.ui.control.NumericEditField
        SpeedKpEditFieldLabel          matlab.ui.control.Label
        SpeedGainEditField             matlab.ui.control.NumericEditField
        SpeedGainEditFieldLabel        matlab.ui.control.Label
        SpeedZeroEditField             matlab.ui.control.NumericEditField
        SpeedZeroEditFieldLabel        matlab.ui.control.Label
        UploadfromdriveButton          matlab.ui.control.Button
        RequirementsPanel              matlab.ui.container.Panel
        ForceSpeedBwCheckBox           matlab.ui.control.CheckBox
        ForcePosBwCheckBox             matlab.ui.control.CheckBox
        PosBwHzEditField               matlab.ui.control.NumericEditField
        PosBwHzEditFieldLabel          matlab.ui.control.Label
        ClosedloopBWHzEditField        matlab.ui.control.NumericEditField
        SpeedBWHzEditFieldLabel        matlab.ui.control.Label
        DownloadtodriveButton          matlab.ui.control.Button
        LoadDesignButton               matlab.ui.control.Button
        SaveDesignButton               matlab.ui.control.Button
        ndorderblock2Panel             matlab.ui.container.Panel
        DenominatorPanel_2             matlab.ui.container.Panel
        SimplepoleEditField_2          matlab.ui.control.NumericEditField
        SimplepoleEditField_2Label     matlab.ui.control.Label
        SimplepoleCheckBox_2           matlab.ui.control.CheckBox
        Xi1EditField_2                 matlab.ui.control.NumericEditField
        Xi1EditField_2Label            matlab.ui.control.Label
        F1EditField_2                  matlab.ui.control.NumericEditField
        F1EditField_2Label             matlab.ui.control.Label
        IncludeCheckBox_2              matlab.ui.control.CheckBox
        NumeratorPanel_2               matlab.ui.container.Panel
        Xi2EditField_2                 matlab.ui.control.NumericEditField
        Xi2EditField_2Label            matlab.ui.control.Label
        F2EditField_2                  matlab.ui.control.NumericEditField
        F2EditField_2Label             matlab.ui.control.Label
        SimpleZEditField_2             matlab.ui.control.NumericEditField
        SimpleZEditField_2Label        matlab.ui.control.Label
        SimplezeroCheckBox_2           matlab.ui.control.CheckBox
        nDisplayPointsEditField        matlab.ui.control.NumericEditField
        nDisplayPointsEditFieldLabel   matlab.ui.control.Label
        TsusecEditField                matlab.ui.control.NumericEditField
        TsusecEditFieldLabel           matlab.ui.control.Label
        MessageEditField               matlab.ui.control.EditField
        MessageEditFieldLabel          matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        PlantfileEditField             matlab.ui.control.EditField
        PlantfileEditFieldLabel        matlab.ui.control.Label
        GoSpeedButton                  matlab.ui.control.Button
        ndorderblock1Panel             matlab.ui.container.Panel
        DenominatorPanel               matlab.ui.container.Panel
        SimplepoleEditField            matlab.ui.control.NumericEditField
        SimplepoleEditField_3Label     matlab.ui.control.Label
        SimplepoleCheckBox             matlab.ui.control.CheckBox
        Xi1EditField                   matlab.ui.control.NumericEditField
        Xi1EditFieldLabel              matlab.ui.control.Label
        F1EditField                    matlab.ui.control.NumericEditField
        F1EditFieldLabel               matlab.ui.control.Label
        IncludeCheckBox                matlab.ui.control.CheckBox
        NumeratorPanel                 matlab.ui.container.Panel
        Xi2EditField                   matlab.ui.control.NumericEditField
        Xi2EditFieldLabel              matlab.ui.control.Label
        F2EditField                    matlab.ui.control.NumericEditField
        F2EditFieldLabel               matlab.ui.control.Label
        SimpleZEditField               matlab.ui.control.NumericEditField
        SimpleZEditFieldLabel          matlab.ui.control.Label
        SimplezeroCheckBox             matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        bPlantSelect % Description
        CfgTable     % Description
        DataType     % Description
    end
    
    methods (Access = private)
        
        
        function [ctl,plant,errmsg] = GetController(app,str)

            if nargin < 2
                str = [] ; 
            end 

            errmsg = [] ; 
            Ts = app.TsusecEditField.Value * 1e-6;

            % Plant may be uploaded from Matlab or from Excel 
            fname = app.PlantfileEditField.Value ; 
            if endsWith(fname,'xlsx') 
                [pf1,pdb,p_deg] = GetPlantFromXls(app.PlantfileEditField.Value) ; 
            elseif endsWith(fname,'mat') 
                [pf1,pdb,p_deg,fp] = GetPlantFromMat(app.PlantfileEditField.Value) ; 
            end 

            [pf,ind]  = sort( pf1);
            pdb = pdb(ind) ; 
            p_deg = p_deg(ind); 
            plant = struct('F',pf,'w',2*pi*pf,'dB',pdb,'Deg',p_deg,'Ts',Ts) ; 

            pf = pf * 2 * pi ; 
            %pg  = 10.^(pdb / 20 ) .* exp( sqrt(-1) * pdeg * pi / 180 ) ; 

            rawctl = GetRawController(app,str) ; 
            PiCtl = rawctl.PI ; 
            pic   = rawctl.pic   ; 
            kp    = rawctl.Kp ;
            ki    = rawctl.Ki ; 
            z     = rawctl.z ; 
            g     = rawctl.g ; 

            gpic = freqresp(pic,pf) ; 
            gpic = gpic(:) ; 

            dbg  = 20 * log10( abs(gpic) ); 
            if app.ForceSpeedBwCheckBox.Value
                bw  = app.ClosedloopBWHzEditField.Value * 2 * pi ;
    
                %normalize
                if ( bw < pf(1) || bw > pf(end) )
                    errmsg = 'Plant frequency range does not cover bw ';
                    app.MessageEditField.Value = errmsg ;
                    return ; 
                end
                gp0 = interp1( log(pf) , dbg(:) + pdb(:)  , log(bw) ) ; 
                gainfac = 1 / 10^( gp0/20) ;
           else
                gainfac = 1 ; 
           end 


            gpic = gpic * gainfac ; % Normalize compensator frequency response  
            pic  = pic  * gainfac ;  % Normalize compensator 
               
            ctl = struct('PI',PiCtl * gainfac ,'Kp',kp* gainfac,'Ki',ki* gainfac ,'z',z,'g',g*gainfac,'pic',pic,'gpic',gpic) ; 
        end
        
        function ctl = GetRawController(app,str)

            Ts = app.TsusecEditField.Value * 1e-6;

            % Act according to force BW option 
            if nargin < 2 || isempty(str) 
                z = app.SpeedZeroEditField.Value ; 
                g = app.SpeedGainEditField.Value ; 
            else
                if isfield( str,'z' )
                    z = str.z ; 
                    g = str.g ; 
                else
                    [z,g] = KpKi2ZG(str.kp,str.ki) ; 
                end
            end 
            [pic,kp,ki] = GetPiFunc(z,Ts,g) ; 
            PiCtl = pic ; 

            if app.IncludeCheckBox.Value
                po2 = Get2ndOrder(Ts,app.SimplepoleCheckBox.Value,app.SimplezeroCheckBox.Value,app.F1EditField.Value,app.F2EditField.Value,...
                    app.Xi1EditField.Value,app.Xi2EditField.Value,app.SimplepoleEditField.Value,app.SimpleZEditField.Value) ; 
                pic = pic * po2 ; 
            end 
            if app.IncludeCheckBox_2.Value
                po3 = Get2ndOrder(Ts,app.SimplepoleCheckBox_2.Value,app.SimplezeroCheckBox_2.Value,app.F1EditField_2.Value,app.F2EditField_2.Value,...
                    app.Xi1EditField_2.Value,app.Xi2EditField_2.Value,app.SimplepoleEditField_2.Value,app.SimpleZEditField_2.Value) ; 
                pic = pic * po3 ; 
            end 
               
            ctl = struct('PI',PiCtl  ,'Kp',kp ,'Ki',ki ,'z',z,'g',g,'pic',pic) ; 
            
        end
        
        function [ctl,plant,errmsg] = GetPosController(app,str)
            if nargin < 2 
                str = [] ; 
            end 
            % Get the speed loop quantities 
            [ctl,plant,errmsg] = GetController(app,str) ;     
            if ~isempty( errmsg ) 
                return ; 
            end 
            Ts = app.TsusecEditField.Value * 1e-6;
%           plant = struct('F',pf,'w',2*pi*pf,'dB',pdb,'Deg',p_deg,'Ts',Ts) ; 

            gpic   = freqresp(ctl.pic,plant.w) ; 
            gplant = DbDeg2G(plant.dB,plant.Deg ) ;  
            gint   = freqresp(Ts * tf([1,0],[1,-1],Ts) ,plant.w) ; 

        
            if app.ForcePosBwCheckBox.Value
                bw = app.PosBwHzEditField.Value * 2 * pi ; 
                if ( bw < plant.w(1) || bw > plant.w(end) ) 
                    errmsg = 'Position bandwidth out of plant data ';
                    return ; 
                end 
                L = gpic(:) .* gplant(:) ; 
                gcl = gint(:) .* L ./ (1 +L ); %Append the integrator to the closed loop 
                gcl0 = interp1( log(plant.w) ,gcl  , log(bw) ) ; % Interpolate the open position loop at the desired BW
                kp = 1 / abs(gcl0) ; % Kp must be designed to bring the gain to unity at the desiref BW
            else
                kp = app.PosKpEditField.Value ;
            end
            % gint = gint * kp ; 
            ctl = struct('kpPos',kp) ; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global RecStruct %#ok<GVMIS> 
            app.bPlantSelect = 0 ; 
            app.CfgTable = RecStruct.CfgTable ; 
            app.DataType = GetDataType(); 
            try 
                MaxAcc = GetCfgPar('MaxAcc');
            catch 
                MaxAcc = 1100 ; 
            end 
            app.MaximumaccelerationEditField.Value = MaxAcc ;

            app.ActiveAxisLabel.Text = ['Controller design: Card: ',RecStruct.Card,' Side: ',RecStruct.Side,'  Axis: ',RecStruct.Axis] ; 


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx;*.mat'},'Select a file','..\IdentResult\'); 
            if isequal(file,0)
                app.PlantfileEditField.Value = 'Not selected';
                app.MessageEditField.Value = 'Plant File not selected' ; 
                app.bPlantSelect = 0 ; 
                return ; 
            end 
            if endsWith( file , '.mat')
                app.PlantfileEditField.Value = [path,file]; 
                app.MessageEditField.Value = 'Loaded ok';
                app.bPlantSelect = 1 ; 
            end 

            try
                sheets = sheetnames([path,file]); 
                if any(strcmp(sheets,'Plant'))  
                    app.PlantfileEditField.Value = [path,file]; 
                    app.MessageEditField.Value = 'Loaded ok';
                    app.bPlantSelect = 1 ; 
                else
                    app.PlantfileEditField.Value = 'Not selected';
                    app.bPlantSelect = 0 ; 
                end
            catch 
            end
            if app.bPlantSelect == 0
                app.MessageEditField.Value = 'Probably not a valid Plant File' ; 
            end 
        end

        % Button pushed function: GoSpeedButton
        function GoSpeedButtonPushed(app, event)
            if app.bPlantSelect == 0 
                app.MessageEditField.Value = 'Select a valid Plant File first' ; 
                return ;
            else
                % Calculate the speed conrtoller
                [ctl,plant,errmsg] = GetController(app); 
                if ~isempty(errmsg) 
                    return ;
                end 


                pf = plant.w ; 
                pdb = plant.dB ; 
                p_deg = plant.Deg ; 

                pic =  ctl.pic ; 
                gpic = ctl.gpic ; 

                nPoints  = app.nDisplayPointsEditField.Value ; 
                pfInterpIn = logspace( log10(pf(1)),log10(pf(end)),nPoints) ; 
                % Get a new F vector that includes both the fine grid and the given points 
                [pfInterp,InsertIdx] = InsertF(pfInterpIn,pf) ;              

                % Get the compensator at given frequencies 
                dbg  = 20 * log10( abs(gpic) ); 
                dbp  = unwrap(angle(gpic) ) * 180 / pi ; 

                % Get the compensator at the full frequency range  
                gpi_int = freqresp(pic,pfInterp) ; 
                gpi_int = gpi_int(:) ; 
                dbg_int  = 20 * log10( abs(gpi_int) ); 
                dbp_int  = unwrap(angle(gpi_int) ) * 180 / pi ; 

                % Interpolate plant to full frequency range 
                pdb_int = interp1( log(pf) , pdb , log(pfInterp) ) ; 
                p_deg_int = interp1( log(pf) , p_deg , log(pfInterp) ) ; 
                
                app.MessageEditField.Value = 'Calculated ok' ; 
                
                int_db  = dbg_int(:) + pdb_int(:); 
                int_deg = dbp_int(:) + p_deg_int(:);
                org_db  = dbg(:) + pdb(:) ; 
                org_deg = dbp(:) + p_deg(:); 
                pfInterp = pfInterp(:) ; 
                M = app.McircEditField.Value ; 

                figure(20) ; clf 
                subplot( 2,1,1) ; 
                semilogx( pfInterp , int_db , pf , org_db ,'d' ) ; grid on 
                subplot( 2,1,2) ; 
                semilogx( pfInterp ,  int_deg , pf ,  org_deg ,'d') ; grid on 
                dfltpos = get(gcf,'Position') ; 
                title('Speed: no pos fback') ; 

                ViewNic(21,pf,pfInterp,org_db, org_deg ,int_db,int_deg,M) ;
                title('Speed: no pos fback') ; 
                set( gcf ,'Position', dfltpos - 0.05 * dfltpos(1) * [1 ,0 ,0, 0]- 0.15 * dfltpos(2) * [0 ,1 ,0, 0] )
            end 
        end

        % Button pushed function: SaveDesignButton
        function SaveDesignButtonPushed(app, event)
            des = struct ( 'TS',app.TsusecEditField.Value , ...
                'BW',app.ClosedloopBWHzEditField.Value,...
                'PosBw',app.PosBwHzEditField.Value,...
                'MaxAcc',app.MaximumaccelerationEditField.Value , ...
                'PosGain',app.PosKpEditField.Value,...
                'PiGain',app.SpeedGainEditField.Value,...
                'PiZero',app.SpeedZeroEditField.Value,...
                'B1_Include',app.IncludeCheckBox.Value,...
                'B1_F1',app.F1EditField.Value,...
                'B1_Xi1',app.Xi1EditField.Value,...
                'B1_IsSimpleZero',app.SimplezeroCheckBox.Value,...
                'B1_SimpleZero',app.SimpleZEditField.Value,...
                'B1_F2',app.F2EditField.Value,...
                'B1_Xi2',app.Xi2EditField.Value,...
                'B2_Include',app.IncludeCheckBox_2.Value,...
                'B2_F1',app.F1EditField_2.Value,...
                'B2_Xi1',app.Xi1EditField_2.Value,...
                'B2_IsSimpleZero',app.SimplezeroCheckBox_2.Value,...
                'B2_SimpleZero',app.SimpleZEditField_2.Value,...
                'B2_F2',app.F2EditField_2.Value,...
                'B2_Xi2',app.Xi2EditField_2.Value,...
                'M',app.McircEditField.Value,...
                'nDispPts',app.nDisplayPointsEditField.Value ) ; 
                [file,path] = uiputfile('*.jsn','Save controller file','..\Designs\');
                if isequal(file,0) 
                    return ; 
                end 
                fname = [path,file];
                
                str = savejson('CtlDesign',des,struct('ParseLogical',1)) ; 
                fi = fopen( fname,'w') ;
                if isequal( fi, -1) 
                    error ( ['Could not open file [',fname,'] for write']) ; 
                end
                fwrite(fi,str,'char') ; 
                
                fclose(fi) ; 
                app.MessageEditField.Value = ['File ',fname, ' saved ok' ]; 
                

        end

        % Button pushed function: LoadDesignButton
        function LoadDesignButtonPushed(app, event)
            [fname,PathName] = uigetfile('*.jsn','Select the JSN calibration file','..\Designs\');
            fname = [PathName,fname] ; 

            fi = fopen( fname,'r') ;
            if isequal( fi, -1) 
                app.MessageEditField.Value = ['Could not open file [',fname,'] for read']; 
                return ; 
            end
            
            json_string = fread( fi , inf , 'uint8=>char') ;  
            
            fclose( fi) ; 
            json2data=loadjson(transpose(json_string(:))); 
            try 
                CtlDesign = json2data.CtlDesign ; 
            catch
                app.MessageEditField.Value = ['Could not open file [',fname,']: Unexpected format']; 
                return ; 
            end

            missList =TestStructFields(CtlDesign,{'BW','PosBw','PiZero','B1_Include','B1_Include','B1_F1','B1_Xi1','B1_IsSimpleZero','B1_SimpleZero',...
                'B1_F2','B1_Xi2','B2_Include','B2_F1','B2_Xi1','B2_IsSimpleZero','B2_SimpleZero','B2_F2','B2_Xi2',...
                'M','nDispPts'}); 
            if ~isempty(missList) 
                app.MessageEditField.Value = ['Could not open file [',fname,']: Missing fields']; 
                return ; 
            end

            try
                app.ClosedloopBWHzEditField.Value = CtlDesign.BW ;
                app.IncludeCheckBox.Value= CtlDesign.B1_Include ;
                app.F1EditField.Value= CtlDesign.B1_F1 ;
                app.Xi1EditField.Value= CtlDesign.B1_Xi1 ;
                app.SimplezeroCheckBox.Value= CtlDesign.B1_IsSimpleZero ;
                app.SimpleZEditField.Value= CtlDesign.B1_SimpleZero ;
                app.F2EditField.Value= CtlDesign.B1_F2 ;
                app.Xi2EditField.Value= CtlDesign.B1_Xi2 ;
                app.IncludeCheckBox_2.Value= CtlDesign.B2_Include ;
                app.F1EditField_2.Value= CtlDesign.B2_F1 ;
                app.Xi1EditField_2.Value= CtlDesign.B2_Xi1 ;
                app.SimplezeroCheckBox_2.Value= CtlDesign.B2_IsSimpleZero ;
                app.SimpleZEditField_2.Value= CtlDesign.B2_SimpleZero ;
                app.F2EditField_2.Value= CtlDesign.B2_F2;
                app.Xi2EditField_2.Value= CtlDesign.B2_Xi2 ;
                app.McircEditField.Value= CtlDesign.M ;
                app.nDisplayPointsEditField.Value = CtlDesign.nDispPts ; 
                app.PosBwHzEditField.Value = CtlDesign.PosBw ;
                app.MaximumaccelerationEditField.Value = CtlDesign.MaxAcc ; 
                app.PosKpEditField.Value = CtlDesign.PosGain ; 
                app.SpeedGainEditField.Value = CtlDesign.PiGain ; 
                app.SpeedZeroEditField.Value = CtlDesign.PiZero ; 
                [~,kp,ki] = GetPiFunc(CtlDesign.PiZero,app.TsusecEditField.Value*1e-6,CtlDesign.PiGain) ; 
                app.SpeedKiEditField.Value = ki ; 
                app.SpeedKpEditField.Value = kp ; 

            catch 
                app.MessageEditField.Value = ['Could get values from file [',fname,']']; 
                return ; 
            end 
            app.MessageEditField.Value = ['Design data from file [',fname,']: retrieved ok']; 

        end

        % Button pushed function: DownloadtodriveButton
        function DownloadtodriveButtonPushed(app, event)
            
            [ctl,~,errmsg] = GetController(app); 
            if ~isempty(errmsg) 
                app.MessageEditField.Value = 'Could NOT get controller';
                beep ; 
                return ;
            end 
            

            qf0PXi = app.Xi1EditField.Value ; 
            qf0ZXi = app.Xi2EditField.Value ; 
            qf0IsSimplePole = app.SimplepoleCheckBox.Value ; 
            qf0IsSimpleZero = app.SimplezeroCheckBox.Value ; 
            qf0InUse = app.IncludeCheckBox.Value ;
            qf1PXi = app.Xi1EditField_2.Value ; 
            qf1ZXi = app.Xi2EditField_2.Value ; 
            qf1IsSimplePole = app.SimplepoleCheckBox_2.Value ; 
            qf1IsSimpleZero = app.SimplezeroCheckBox_2.Value ; 
            qf1InUse = app.IncludeCheckBox_2.Value;


            if qf1IsSimplePole
                qf0PBw = min( app.SimplepoleEditField.Value , 1e5) ; 
            else
                qf0PBw = app.F1EditField.Value ; 
            end

            if qf0IsSimpleZero
                qf0ZBw = min( app.SimpleZEditField.Value , 1e5) ; 
            else
                qf0ZBw = app.F2EditField.Value ; 
            end

            if qf1IsSimplePole
               qf1PBw  =  min( app.SimplepoleEditField_2.Value , 1e5) ; 
            else
                qf1PBw = app.F1EditField_2.Value ; 
            end
            
            if qf1IsSimpleZero
                qf1ZBw = min(app.SimpleZEditField_2.Value, 1e5) ; 
            else
                qf1ZBw = app.F2EditField_2.Value ; 
            end
            
            MaxAcc  = app.MaximumaccelerationEditField.Value ; 

            SpeedKp = ctl.Kp ; 
            SpeedKi = ctl.Ki ; 
            PosKp   = app.PosKpEditField.Value ; 
  
            qf0Cfg = qf0InUse + qf0IsSimplePole * 4 + qf0IsSimpleZero * 16 ; 
            qf1Cfg = qf1InUse + qf1IsSimplePole * 4 + qf1IsSimpleZero * 16 ; 


            try 
            SendObj([hex2dec('220d'),app.CfgTable.qf0PBw.Ind],qf0PBw,app.DataType.float,'PBw parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf0PXi.Ind],qf0PXi,app.DataType.float,'PXi parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf0ZBw.Ind],qf0ZBw,app.DataType.float,'ZBw parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf0ZXi.Ind],qf0ZXi,app.DataType.float,'ZXi parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf1PBw.Ind],qf1PBw,app.DataType.float,'PBw parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf1PXi.Ind],qf1PXi,app.DataType.float,'PXi parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf1ZBw.Ind],qf1ZBw,app.DataType.float,'ZBw parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf1ZXi.Ind],qf1ZXi,app.DataType.float,'ZXi parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.SpeedKp.Ind],SpeedKp,app.DataType.float,'SpeedKp parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.SpeedKi.Ind],SpeedKi,app.DataType.float,'SpeedKi parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.PosKp.Ind],PosKp,app.DataType.float,'PosKp parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.MaxAcc.Ind],MaxAcc,app.DataType.float,'MaxAcc parameter') ; 
% The configurationb parameters are last because they enforce controller recalculation 
            SendObj([hex2dec('220d'),app.CfgTable.qf0Cfg.Ind],qf0Cfg,app.DataType.long,'qf0Cfg parameter') ; 
            SendObj([hex2dec('220d'),app.CfgTable.qf1Cfg.Ind],qf1Cfg,app.DataType.long,'qf1Cfg parameter') ; 
                app.MessageEditField.Value = 'Downloaded parameters to controller';
            catch 
                app.MessageEditField.Value = 'Could NOT download parameters to controller';
                beep ; 
            end

        end

        % Button pushed function: UploadfromdriveButton
        function UploadfromdriveButtonPushed(app, event)
            
            try 
            qf0PBw = FetchObj([hex2dec('220d'),app.CfgTable.qf0PBw.Ind],app.DataType.float,'PBw parameter') ; 
            qf0PXi = FetchObj([hex2dec('220d'),app.CfgTable.qf0PXi.Ind],app.DataType.float,'PXi parameter') ; 
            qf0ZBw = FetchObj([hex2dec('220d'),app.CfgTable.qf0ZBw.Ind],app.DataType.float,'ZBw parameter') ; 
            qf0ZXi = FetchObj([hex2dec('220d'),app.CfgTable.qf0ZXi.Ind],app.DataType.float,'ZXi parameter') ; 
            qf0Cfg = FetchObj([hex2dec('220d'),app.CfgTable.qf0Cfg.Ind],app.DataType.long,'qf0Cfg parameter') ; 
            qf1PBw = FetchObj([hex2dec('220d'),app.CfgTable.qf1PBw.Ind],app.DataType.float,'PBw parameter') ; 
            qf1PXi = FetchObj([hex2dec('220d'),app.CfgTable.qf1PXi.Ind],app.DataType.float,'PXi parameter') ; 
            qf1ZBw = FetchObj([hex2dec('220d'),app.CfgTable.qf1ZBw.Ind],app.DataType.float,'ZBw parameter') ; 
            qf1ZXi = FetchObj([hex2dec('220d'),app.CfgTable.qf1ZXi.Ind],app.DataType.float,'ZXi parameter') ; 
            qf1Cfg = FetchObj([hex2dec('220d'),app.CfgTable.qf1Cfg.Ind],app.DataType.long,'qf1Cfg parameter') ; 

%                 'PosGain',app.PosKpEditField.Value,...
%                 'PiGain',app.SpeedGainEditField.Value,...
%                 'PiZero',app.SpeedZeroEditField.Value,...

            
            SpeedKp = FetchObj([hex2dec('220d'),app.CfgTable.SpeedKp.Ind],app.DataType.float,'SpeedKp parameter') ; 
            SpeedKi = FetchObj([hex2dec('220d'),app.CfgTable.SpeedKi.Ind],app.DataType.float,'SpeedKi parameter') ; 
            PosKp = FetchObj([hex2dec('220d'),app.CfgTable.PosKp.Ind],app.DataType.float,'PosKp parameter') ; 
            MaxAcc = FetchObj([hex2dec('220d'),app.CfgTable.MaxAcc.Ind],app.DataType.float,'MaxAcc parameter') ; 
                app.MessageEditField.Value = 'Uploaded parameters from controller';
            catch 
                app.MessageEditField.Value = 'Could NOT upload parameters from controller';
                beep ; 
            end

            % Disable BW forcing 
            app.ForceSpeedBwCheckBox.Value = 0 ; 
            app.ForceSpeedBwCheckBox.Enable = 1 ; 
            app.ForcePosBwCheckBox.Value = 0 ; 
            app.ForcePosBwCheckBox.Enable = 1 ; 

            qf0InUse = bitand(qf0Cfg,1) ; 
            qf0IsSimplePole = bitand( fix(qf0Cfg/4),3) ; 
            qf0IsSimpleZero = bitand( fix(qf0Cfg/16),3) ; 

            qf1InUse = bitand(qf1Cfg,1) ; 
            qf1IsSimplePole = bitand( fix(qf1Cfg/4),3) ; 
            qf1IsSimpleZero = bitand( fix(qf1Cfg/16),3) ; 


            app.F1EditField.Value  = min(qf0PBw,10000) ; 
            app.Xi1EditField.Value = qf0PXi ; 
            app.F2EditField.Value = min(qf0ZBw,10000)  ; 
            app.Xi2EditField.Value = qf0ZXi ; 
            app.SimplepoleCheckBox.Value = qf0IsSimplePole ; 
            app.SimplezeroCheckBox.Value = qf0IsSimpleZero ; 
            app.IncludeCheckBox.Value = qf0InUse  ;
            app.F1EditField_2.Value = min(qf1PBw,10000) ; 
            app.Xi1EditField_2.Value = qf1PXi ; 
            app.F2EditField_2.Value = min(qf1ZBw,10000) ; 
            app.Xi2EditField_2.Value = qf1ZXi ; 
            app.SimplepoleCheckBox_2.Value = qf1IsSimplePole ; 
            app.SimplezeroCheckBox_2.Value = qf1IsSimpleZero ; 
            app.IncludeCheckBox_2.Value = qf1InUse ;
            app.MaximumaccelerationEditField.Value = MaxAcc ; 
            app.PosKpEditField.Value = PosKp ; 
            app.SpeedKpEditField.Value = SpeedKp ; 
            app.SpeedKiEditField.Value = SpeedKi ; 
            
            app.SimplepoleEditField.Value = SimpleValue(qf0IsSimplePole,qf0PBw) ; 
            app.SimpleZEditField.Value    = SimpleValue(qf0IsSimpleZero,qf0ZBw) ; 
            app.SimplepoleEditField_2.Value = SimpleValue(qf1IsSimplePole,qf1PBw) ; 
            app.SimpleZEditField_2.Value = SimpleValue(qf1IsSimpleZero,qf1ZBw) ; 

            % Recalc zero and gain 
            SpeedKiEditFieldValueChanged(app) ; 

        end

        % Value changed function: ForceSpeedBwCheckBox
        function ForceSpeedBwCheckBoxValueChanged(app, event)
            value = app.ForceSpeedBwCheckBox.Value;
            if value == 0 
                app.ForcePosBwCheckBox.Value = 0 ; 
                app.ForcePosBwCheckBox.Enable = 0 ;
%                 app.PosKpEditField.Enable = 1 ; 
                app.SpeedKiEditField.Enable = 1 ; 
                app.SpeedKpEditField.Enable = 1 ; 
            else
% %                 app.PosKpEditField.Enable = 0 ; 
                app.ForcePosBwCheckBox.Enable = 1 ;
                app.SpeedKiEditField.Enable = 0 ; 
                app.SpeedKpEditField.Enable = 0 ; 
                SpeedGainEditFieldValueChanged(app) ;
            end 

        end

        % Value changed function: ForcePosBwCheckBox
        function ForcePosBwCheckBoxValueChanged(app, event)
            value = app.ForcePosBwCheckBox.Value;
            if value == 0 
                app.PosKpEditField.Enable = 1 ; 

            else
                app.PosKpEditField.Enable = 0 ; 
                [ctl,~,errmsg] = GetPosController(app,[]) ; 
                if isempty( errmsg )
                    app.PosKpEditField.Value = ctl.kpPos ; 
                    PosKpEditFieldValueChanged(app) ; 
                else
                    app.MessageEditField.Value = ['Could not force position BW: ', errmsg] ; 
                end

            end
        end

        % Value changed function: SpeedKiEditField
        function SpeedKiEditFieldValueChanged(app, event)
            ki = app.SpeedKiEditField.Value ;
            kp = app.SpeedKpEditField.Value ; 
            if ki <= 1e-7 
                ki = 0   ; 
            end
            [z,g] = KpKi2ZG(kp,ki) ;
            app.SpeedKiEditField.Value = ki ; 
            app.SpeedZeroEditField.Value = z ; 
            app.SpeedGainEditField.Value = g ; 

        end

        % Button pushed function: GoPositionButton
        function GoPositionButtonPushed(app, event)
            if app.bPlantSelect == 0 
                app.MessageEditField.Value = 'Select a valid Plant File first' ; 
                return ;
            else
                [ctl,plant,errmsg] = GetController(app); 
                if ~isempty(errmsg) 
                    return ;
                end 

                
                pf = plant.w ; 
                pdb = plant.dB ; 
                p_deg = plant.Deg ; 

                pic =  ctl.pic ; 
                gpic = ctl.gpic ; 

                nPoints  = app.nDisplayPointsEditField.Value ; 
                pfInterpIn = logspace( log10(pf(1)),log10(pf(end)),nPoints) ; 
                % Get a new F vector that includes both the fine grid and the given points 
                [pfInterp,InsertIdx] = InsertF(pfInterpIn,pf) ;              

                % Get the compensator at given frequencies 
                % [dbg,dbp] = G2DbDeg(gpic,-90) ; 
 
                % Get the compensator at the full frequency range  
                gPI_int = freqresp(pic,pfInterp) ; 
                gPI_int = gPI_int(:) ; 
                
                % Interpolate plant to full frequency range 
                pdb_int = interp1( log(pf) , pdb , log(pfInterp) ) ; 
                p_deg_int = interp1( log(pf) , p_deg , log(pfInterp) ) ; 
                gplant   = DbDeg2G(pdb_int , p_deg_int) ; 
                
                Ts = app.TsusecEditField.Value * 1e-6 ; 
                g_integral = freqresp(tf([Ts,0],[1,-1],Ts) ,pfInterp) ; 
                g_integral = g_integral(:) ; 

                % Loop transfer, position , true position margins
                LposTrue = ( gPI_int .* gplant) .* (1 + app.PosKpEditField.Value .* g_integral)  ; 

                % Loop transfer, position , assume speed implementation as closed loop 
                Lpos     = ( gPI_int .* gplant) ./(1+gPI_int .* gplant) * app.PosKpEditField.Value .* g_integral ; 
                

                g_integral = freqresp(tf([Ts,0],[1,-1],Ts) ,pf) ; 
                g_integral = g_integral(:); 
                gplant   = DbDeg2G(pdb , p_deg) ; 
                LposTrueOrg = ( gpic .* gplant) .* (1 + app.PosKpEditField.Value .* g_integral)  ; 
                LposOrg     = ( gpic .* gplant) ./(1+gpic .* gplant) * app.PosKpEditField.Value .* g_integral ; 


                app.MessageEditField.Value = 'Calculated ok' ; 
                
                M = app.McircEditField.Value ; 

                [int_db,int_deg] = G2DbDeg(Lpos,-180)  ; 
                [org_db,org_deg] = G2DbDeg(LposOrg,-180)  ; 
                ViewNic(23,pf,pfInterp,org_db, org_deg ,int_db,int_deg,M) ;
                title('Poistion: assuming speed plant as is') ; 
                dfltpos = get(gcf,'Position') ; 

                [int_db,int_deg] = G2DbDeg(LposTrue,-180)  ; 
                [org_db,org_deg] = G2DbDeg(LposTrueOrg,-180)  ;                 
                ViewNic(24,pf,pfInterp,org_db, org_deg ,int_db,int_deg,M) ;
                set( gcf ,'Position', dfltpos - 0.05 * dfltpos(1) * [1 ,0 ,0, 0]- 0.15 * dfltpos(2) * [0 ,1 ,0, 0] )
                title('Poistion: true margins') ; 

                
                % LT_db,LT_ph] = G2DbDeg(LposTrue) ;
                % pfInterp = pfInterp(:) ; 
                % figure(20) ; clf 
                % subplot( 2,1,1) ; 
                % semilogx( pfInterp , int_db , pf , org_db ,'d' ) ; grid on 
                % subplot( 2,1,2) ; 
                % semilogx( pfInterp ,  int_deg , pf ,  org_deg ,'d') ; grid on 
            end 
            
        end

        % Value changed function: SpeedGainEditField
        function SpeedGainEditFieldValueChanged(app, event)
            g = app.SpeedGainEditField.Value;
            z = app.SpeedZeroEditField.Value ; 

            if ( app.ForceSpeedBwCheckBox.Value == 0 )
                 [~,kp,ki] = GetPiFunc(z,app.TsusecEditField.Value*1e-6,g) ; 
                app.SpeedKiEditField.Value = ki ; 
                app.SpeedKpEditField.Value = kp ; 
                app.SpeedZeroEditField.Value = z ; 
                app.SpeedGainEditField.Value = g ; 
            else
                ctl = GetController(app,struct('z',z,'g',g)) ; 
                app.SpeedKpEditField.Value = ctl.Kp ; 
                app.SpeedKiEditField.Value = ctl.Ki ; 
                app.SpeedZeroEditField.Value = ctl.z ; 
                app.SpeedGainEditField.Value = ctl.g ;
            end 
            
        end

        % Value changed function: SpeedZeroEditField
        function SpeedZeroEditFieldValueChanged(app, event)
             SpeedGainEditFieldValueChanged(app) ;
        end

        % Value changed function: SpeedKpEditField
        function SpeedKpEditFieldValueChanged(app, event)
            SpeedKiEditFieldValueChanged(app) ;
        end

        % Value changed function: ClosedloopBWHzEditField
        function ClosedloopBWHzEditFieldValueChanged(app, event)
            if app.ForceSpeedBwCheckBox.Value
                SpeedGainEditFieldValueChanged(app) ;
            end
        end

        % Value changed function: PosBwHzEditField
        function PosBwHzEditFieldValueChanged(app, event)
            ForcePosBwCheckBoxValueChanged(app);
        end

        % Value changed function: PosKpEditField
        function PosKpEditFieldValueChanged(app, event)
            value = app.PosKpEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ndorderblock1Panel = uipanel(app.UIFigure);
            app.ndorderblock1Panel.Title = '2nd order block #1';
            app.NumeratorPanel = uipanel(app.ndorderblock1Panel);
            app.NumeratorPanel.Title = 'Numerator';
            app.SimplezeroCheckBox = uicheckbox(app.NumeratorPanel);
            app.SimplezeroCheckBox.Text = 'Simple zero';
            app.SimplezeroCheckBox.Value = true;
            app.SimpleZEditFieldLabel = uilabel(app.NumeratorPanel);
            app.SimpleZEditFieldLabel.Text = 'Simple Z';
            app.SimpleZEditField = uieditfield(app.NumeratorPanel, 'numeric');
            app.SimpleZEditField.Limits = [0.1 Inf];
            app.SimpleZEditField.Value = Inf;
            app.F2EditFieldLabel = uilabel(app.NumeratorPanel);
            app.F2EditFieldLabel.Text = 'F2';
            app.F2EditField = uieditfield(app.NumeratorPanel, 'numeric');
            app.F2EditField.Limits = [0.1 20000];
            app.F2EditField.Value = 100;
            app.Xi2EditFieldLabel = uilabel(app.NumeratorPanel);
            app.Xi2EditFieldLabel.Text = 'Xi2';
            app.Xi2EditField = uieditfield(app.NumeratorPanel, 'numeric');
            app.Xi2EditField.Limits = [0.03 10];
            app.Xi2EditField.Value = 0.85;
            app.IncludeCheckBox = uicheckbox(app.ndorderblock1Panel);
            app.IncludeCheckBox.Text = 'Include';
            app.DenominatorPanel = uipanel(app.ndorderblock1Panel);
            app.DenominatorPanel.Title = 'Denominator';
            app.F1EditFieldLabel = uilabel(app.DenominatorPanel);
            app.F1EditFieldLabel.Text = 'F1';
            app.F1EditField = uieditfield(app.DenominatorPanel, 'numeric');
            app.F1EditField.Limits = [0.1 20000];
            app.F1EditField.Value = 100;
            app.Xi1EditFieldLabel = uilabel(app.DenominatorPanel);
            app.Xi1EditFieldLabel.Text = 'Xi1';
            app.Xi1EditField = uieditfield(app.DenominatorPanel, 'numeric');
            app.Xi1EditField.Limits = [0.05 10];
            app.Xi1EditField.Value = 0.15;
            app.SimplepoleCheckBox = uicheckbox(app.DenominatorPanel);
            app.SimplepoleCheckBox.Text = 'Simple pole';
            app.SimplepoleEditField_3Label = uilabel(app.DenominatorPanel);
            app.SimplepoleEditField_3Label.Text = 'Simple pole';
            app.SimplepoleEditField = uieditfield(app.DenominatorPanel, 'numeric');
            app.SimplepoleEditField.Limits = [1 Inf];
            app.SimplepoleEditField.Value = Inf;
            app.GoSpeedButton = uibutton(app.UIFigure, 'push');
            app.GoSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @GoSpeedButtonPushed, true);
            app.GoSpeedButton.Text = ' Go Speed';
            app.PlantfileEditFieldLabel = uilabel(app.UIFigure);
            app.PlantfileEditFieldLabel.Text = 'Plant file';
            app.PlantfileEditField = uieditfield(app.UIFigure, 'text');
            app.PlantfileEditField.Value = 'Not selected';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.TsusecEditFieldLabel = uilabel(app.UIFigure);
            app.TsusecEditFieldLabel.Text = 'Ts usec';
            app.TsusecEditField = uieditfield(app.UIFigure, 'numeric');
            app.TsusecEditField.Editable = 'off';
            app.TsusecEditField.Value = 50;
            app.nDisplayPointsEditFieldLabel = uilabel(app.UIFigure);
            app.nDisplayPointsEditFieldLabel.Text = 'nDisplayPoints';
            app.nDisplayPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.nDisplayPointsEditField.Limits = [3 10000];
            app.nDisplayPointsEditField.RoundFractionalValues = 'on';
            app.nDisplayPointsEditField.Value = 300;
            app.ndorderblock2Panel = uipanel(app.UIFigure);
            app.ndorderblock2Panel.Title = '2nd order block #2';
            app.NumeratorPanel_2 = uipanel(app.ndorderblock2Panel);
            app.NumeratorPanel_2.Title = 'Numerator';
            app.SimplezeroCheckBox_2 = uicheckbox(app.NumeratorPanel_2);
            app.SimplezeroCheckBox_2.Text = 'Simple zero';
            app.SimplezeroCheckBox_2.Value = true;
            app.SimpleZEditField_2Label = uilabel(app.NumeratorPanel_2);
            app.SimpleZEditField_2Label.Text = 'Simple Z';
            app.SimpleZEditField_2 = uieditfield(app.NumeratorPanel_2, 'numeric');
            app.SimpleZEditField_2.Limits = [0.1 Inf];
            app.SimpleZEditField_2.Value = Inf;
            app.F2EditField_2Label = uilabel(app.NumeratorPanel_2);
            app.F2EditField_2Label.Text = 'F2';
            app.F2EditField_2 = uieditfield(app.NumeratorPanel_2, 'numeric');
            app.F2EditField_2.Limits = [0.1 20000];
            app.F2EditField_2.Value = 100;
            app.Xi2EditField_2Label = uilabel(app.NumeratorPanel_2);
            app.Xi2EditField_2Label.Text = 'Xi2';
            app.Xi2EditField_2 = uieditfield(app.NumeratorPanel_2, 'numeric');
            app.Xi2EditField_2.Limits = [0.03 10];
            app.Xi2EditField_2.Value = 0.85;
            app.IncludeCheckBox_2 = uicheckbox(app.ndorderblock2Panel);
            app.IncludeCheckBox_2.Text = 'Include';
            app.DenominatorPanel_2 = uipanel(app.ndorderblock2Panel);
            app.DenominatorPanel_2.Title = 'Denominator';
            app.F1EditField_2Label = uilabel(app.DenominatorPanel_2);
            app.F1EditField_2Label.Text = 'F1';
            app.F1EditField_2 = uieditfield(app.DenominatorPanel_2, 'numeric');
            app.F1EditField_2.Limits = [0.1 20000];
            app.F1EditField_2.Value = 100;
            app.Xi1EditField_2Label = uilabel(app.DenominatorPanel_2);
            app.Xi1EditField_2Label.Text = 'Xi1';
            app.Xi1EditField_2 = uieditfield(app.DenominatorPanel_2, 'numeric');
            app.Xi1EditField_2.Limits = [0.05 10];
            app.Xi1EditField_2.Value = 0.15;
            app.SimplepoleCheckBox_2 = uicheckbox(app.DenominatorPanel_2);
            app.SimplepoleCheckBox_2.Text = 'Simple pole';
            app.SimplepoleEditField_2Label = uilabel(app.DenominatorPanel_2);
            app.SimplepoleEditField_2Label.Text = 'Simple pole';
            app.SimplepoleEditField_2 = uieditfield(app.DenominatorPanel_2, 'numeric');
            app.SimplepoleEditField_2.Limits = [1 Inf];
            app.SimplepoleEditField_2.Value = Inf;
            app.SaveDesignButton = uibutton(app.UIFigure, 'push');
            app.SaveDesignButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDesignButtonPushed, true);
            app.SaveDesignButton.Text = 'Save Design';
            app.LoadDesignButton = uibutton(app.UIFigure, 'push');
            app.LoadDesignButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDesignButtonPushed, true);
            app.LoadDesignButton.Text = 'Load Design';
            app.DownloadtodriveButton = uibutton(app.UIFigure, 'push');
            app.DownloadtodriveButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadtodriveButtonPushed, true);
            app.DownloadtodriveButton.Text = 'Download to drive';
            app.RequirementsPanel = uipanel(app.UIFigure);
            app.RequirementsPanel.Title = 'Requirements';
            app.SpeedBWHzEditFieldLabel = uilabel(app.RequirementsPanel);
            app.SpeedBWHzEditFieldLabel.Text = 'Speed BW Hz';
            app.ClosedloopBWHzEditField = uieditfield(app.RequirementsPanel, 'numeric');
            app.ClosedloopBWHzEditField.Limits = [1 800];
            app.ClosedloopBWHzEditField.ValueChangedFcn = createCallbackFcn(app, @ClosedloopBWHzEditFieldValueChanged, true);
            app.ClosedloopBWHzEditField.Value = 40;
            app.PosBwHzEditFieldLabel = uilabel(app.RequirementsPanel);
            app.PosBwHzEditFieldLabel.Text = 'Pos Bw Hz';
            app.PosBwHzEditField = uieditfield(app.RequirementsPanel, 'numeric');
            app.PosBwHzEditField.ValueChangedFcn = createCallbackFcn(app, @PosBwHzEditFieldValueChanged, true);
            app.PosBwHzEditField.Value = 10;
            app.ForcePosBwCheckBox = uicheckbox(app.RequirementsPanel);
            app.ForcePosBwCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcePosBwCheckBoxValueChanged, true);
            app.ForcePosBwCheckBox.Text = 'Force Pos Bw';
            app.ForceSpeedBwCheckBox = uicheckbox(app.RequirementsPanel);
            app.ForceSpeedBwCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceSpeedBwCheckBoxValueChanged, true);
            app.ForceSpeedBwCheckBox.Text = 'Force Speed Bw';
            app.UploadfromdriveButton = uibutton(app.UIFigure, 'push');
            app.UploadfromdriveButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfromdriveButtonPushed, true);
            app.UploadfromdriveButton.Text = 'Upload from drive';
            app.PIDPanel = uipanel(app.UIFigure);
            app.PIDPanel.Title = 'PID';
            app.SpeedZeroEditFieldLabel = uilabel(app.PIDPanel);
            app.SpeedZeroEditFieldLabel.Text = ' Speed Zero';
            app.SpeedZeroEditField = uieditfield(app.PIDPanel, 'numeric');
            app.SpeedZeroEditField.Limits = [0 Inf];
            app.SpeedZeroEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedZeroEditFieldValueChanged, true);
            app.SpeedZeroEditField.Value = 8;
            app.SpeedGainEditFieldLabel = uilabel(app.PIDPanel);
            app.SpeedGainEditFieldLabel.Text = 'Speed Gain';
            app.SpeedGainEditField = uieditfield(app.PIDPanel, 'numeric');
            app.SpeedGainEditField.Limits = [0 Inf];
            app.SpeedGainEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedGainEditFieldValueChanged, true);
            app.SpeedGainEditField.Value = 8;
            app.SpeedKpEditFieldLabel = uilabel(app.PIDPanel);
            app.SpeedKpEditFieldLabel.Text = 'Speed Kp';
            app.SpeedKpEditField = uieditfield(app.PIDPanel, 'numeric');
            app.SpeedKpEditField.Limits = [0 Inf];
            app.SpeedKpEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedKpEditFieldValueChanged, true);
            app.SpeedKpEditField.Value = 8;
            app.SpeedKiEditFieldLabel = uilabel(app.PIDPanel);
            app.SpeedKiEditFieldLabel.Text = 'Speed Ki';
            app.SpeedKiEditField = uieditfield(app.PIDPanel, 'numeric');
            app.SpeedKiEditField.Limits = [0 Inf];
            app.SpeedKiEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedKiEditFieldValueChanged, true);
            app.SpeedKiEditField.Value = 8;
            app.PosKpEditFieldLabel = uilabel(app.PIDPanel);
            app.PosKpEditFieldLabel.Text = 'PosKp';
            app.PosKpEditField = uieditfield(app.PIDPanel, 'numeric');
            app.PosKpEditField.Limits = [0 Inf];
            app.PosKpEditField.ValueChangedFcn = createCallbackFcn(app, @PosKpEditFieldValueChanged, true);
            app.PosKpEditField.Value = 8;
            app.MaxaccelerationEditFieldLabel = uilabel(app.PIDPanel);
            app.MaxaccelerationEditFieldLabel.Text = 'Max acceleration';
            app.MaximumaccelerationEditField = uieditfield(app.PIDPanel, 'numeric');
            app.McircEditFieldLabel = uilabel(app.UIFigure);
            app.McircEditFieldLabel.Text = 'Mcirc';
            app.McircEditField = uieditfield(app.UIFigure, 'numeric');
            app.McircEditField.Limits = [1.0001 100];
            app.McircEditField.ValueDisplayFormat = '%7.3g';
            app.McircEditField.Value = 1.2;
            app.GoPositionButton = uibutton(app.UIFigure, 'push');
            app.GoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GoPositionButtonPushed, true);
            app.GoPositionButton.Text = ' Go Position';
            app.ActiveAxisLabel = uilabel(app.UIFigure);
            app.ActiveAxisLabel.Text = 'ActiveAxis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoopDes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end