classdef Pd2Dash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ATPTextArea                 matlab.ui.control.TextArea
        ATPTextAreaLabel            matlab.ui.control.Label
        RecorderReadyLamp           matlab.ui.control.Lamp
        RecorderReadyLampLabel      matlab.ui.control.Label
        SWrevisionxxxxxxLabel       matlab.ui.control.Label
        HoldSelfenableLamp          matlab.ui.control.Lamp
        HoldSelfenableLampLabel     matlab.ui.control.Label
        EmergencyMushroomLamp       matlab.ui.control.Lamp
        EmergencyMushroomLampLabel  matlab.ui.control.Label
        SwitchesPanel               matlab.ui.container.Panel
        ShuntButton                 matlab.ui.control.Button
        LS2Button                   matlab.ui.control.Button
        LS1Button_2                 matlab.ui.control.Button
        HS12VButton                 matlab.ui.control.Button
        HS24VButton                 matlab.ui.control.Button
        ConnectedLabel              matlab.ui.control.Label
        BAT2Panel                   matlab.ui.container.Panel
        BAT1Panel                   matlab.ui.container.Panel
        TroubleTextArea             matlab.ui.control.TextArea
        TroubleTextAreaLabel        matlab.ui.control.Label
        InstructionTextArea         matlab.ui.control.TextArea
        InstructionTextAreaLabel    matlab.ui.control.Label
        TestsPanel                  matlab.ui.container.Panel
        BatSwitchTestButton         matlab.ui.control.Button
        EnablelocalcontrolCheckBox  matlab.ui.control.CheckBox
        OverrideswitchCheckBox      matlab.ui.control.CheckBox
        ShutDowntestButton          matlab.ui.control.Button
        USBtestButton               matlab.ui.control.Button
        I2CtestButton               matlab.ui.control.Button
        InputsectionPanel           matlab.ui.container.Panel
        ServoSWOnLamp               matlab.ui.control.Lamp
        ServoSWOnLampLabel          matlab.ui.control.Label
        ServoPowerEditField         matlab.ui.control.NumericEditField
        ServobuspowerLabel          matlab.ui.control.Label
        CurServoEditField           matlab.ui.control.NumericEditField
        ServobuscurrentLabel        matlab.ui.control.Label
        VServoEditField             matlab.ui.control.NumericEditField
        ServobusvoltageLabel        matlab.ui.control.Label
        VPanel_4                    matlab.ui.container.Panel
        Watt5EditField              matlab.ui.control.NumericEditField
        OutputWattsLabel_4          matlab.ui.control.Label
        ON5LSW1Button               matlab.ui.control.Button
        ON5Button                   matlab.ui.control.Button
        Load5V_1Lamp                matlab.ui.control.Lamp
        Load1Label                  matlab.ui.control.Label
        Cur5EditField               matlab.ui.control.NumericEditField
        OutputcurrentLabel_4        matlab.ui.control.Label
        OutputvoltageLabel_4        matlab.ui.control.Label
        V5EditField                 matlab.ui.control.NumericEditField
        ONLamp5                     matlab.ui.control.Lamp
        ONLampLabel_4               matlab.ui.control.Label
        VPanel_3                    matlab.ui.container.Panel
        OutputWattsLabel_3          matlab.ui.control.Label
        Watt12EditField             matlab.ui.control.NumericEditField
        ON12Button                  matlab.ui.control.Button
        Cur12EditField              matlab.ui.control.NumericEditField
        OutputcurrentLabel_3        matlab.ui.control.Label
        OutputvoltageLabel_3        matlab.ui.control.Label
        V12EditField                matlab.ui.control.NumericEditField
        ONLamp12                    matlab.ui.control.Lamp
        ONLampLabel_3               matlab.ui.control.Label
        VPanel_2                    matlab.ui.container.Panel
        OutputWattsLabel_2          matlab.ui.control.Label
        Watt18EditField             matlab.ui.control.NumericEditField
        ON18LSWButton               matlab.ui.control.Button
        ON18Button                  matlab.ui.control.Button
        Load18Lamp                  matlab.ui.control.Lamp
        LoadswitchLabel             matlab.ui.control.Label
        Cur18EditField              matlab.ui.control.NumericEditField
        OutputcurrentLabel_2        matlab.ui.control.Label
        OutputvoltageLabel_2        matlab.ui.control.Label
        V18EditField                matlab.ui.control.NumericEditField
        ONLamp18                    matlab.ui.control.Lamp
        ONLampLabel_2               matlab.ui.control.Label
        VPanel                      matlab.ui.container.Panel
        Watt24EditField             matlab.ui.control.NumericEditField
        OutputWattsLabel            matlab.ui.control.Label
        ON24Button                  matlab.ui.control.Button
        Cur24EditField              matlab.ui.control.NumericEditField
        OutputcurrentLabel          matlab.ui.control.Label
        OutputvoltageLabel          matlab.ui.control.Label
        V24EditField                matlab.ui.control.NumericEditField
        ONLamp24                    matlab.ui.control.Lamp
        ONLampLabel                 matlab.ui.control.Label
        Combiner2Panel              matlab.ui.container.Panel
        SeelctedLamp_2              matlab.ui.control.Lamp
        SeelctedLamp_2Label         matlab.ui.control.Label
        RegenButton_2               matlab.ui.control.Button
        EnableButton_2              matlab.ui.control.Button
        ChargeButton_2              matlab.ui.control.Button
        BatgoodLamp_2               matlab.ui.control.Lamp
        BatgoodLamp_2Label          matlab.ui.control.Label
        BattryvoltageLabel_2        matlab.ui.control.Label
        VBATEditField_2             matlab.ui.control.NumericEditField
        RegenerationLamp_2          matlab.ui.control.Lamp
        RegenerationLampLabel_2     matlab.ui.control.Label
        HotswapLamp_2               matlab.ui.control.Lamp
        HotswapLampLabel_2          matlab.ui.control.Label
        ChargeresistorLamp_2        matlab.ui.control.Lamp
        ChargeresistorLampLabel_3   matlab.ui.control.Label
        Combiner1Panel              matlab.ui.container.Panel
        SeelctedLamp                matlab.ui.control.Lamp
        SeelctedLampLabel           matlab.ui.control.Label
        RegenButton                 matlab.ui.control.Button
        EnableButton                matlab.ui.control.Button
        ChargeButton                matlab.ui.control.Button
        BatgoodLamp                 matlab.ui.control.Lamp
        BatgoodLampLabel            matlab.ui.control.Label
        BattryvoltageLabel          matlab.ui.control.Label
        VBATEditField               matlab.ui.control.NumericEditField
        RegenerationLamp            matlab.ui.control.Lamp
        RegenerationLampLabel       matlab.ui.control.Label
        HotswapLamp                 matlab.ui.control.Lamp
        HotswapLampLabel            matlab.ui.control.Label
        ChargeresistorLamp          matlab.ui.control.Lamp
        ChargeresistorLampLabel     matlab.ui.control.Label
        PD2dashboardLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Tmr % Description
        EvtObj % Description
        Lock % Description
        s % Description
        Trouble % Description
        CreateStruct % Description
    end
    
    methods (Access = private)
        
        function update_display(app)
            
            app.s = PGetState() ; 
            DataType = GetDataType() ; 

            WSrv  = FetchObj( [hex2dec('2105'),39] , DataType.float , 'Power Srv' )  ; 
            W24  = FetchObj( [hex2dec('2105'),37] , DataType.float , 'Power 24' )  ; 
            W18  = FetchObj( [hex2dec('2105'),35] , DataType.float , 'Power 18' )  ; 
            W12  = FetchObj( [hex2dec('2105'),33] , DataType.float , 'Power 12' )  ; 
            W5  = FetchObj( [hex2dec('2105'),31] , DataType.float , 'Power 5' )  ; 

            app.Watt24EditField.Value = W24 ; 
            app.Watt18EditField.Value = W18 ; 
            app.Watt12EditField.Value = W12 ; 
            app.Watt5EditField.Value = W5 ; 
            app.ServoPowerEditField.Value = WSrv; 

            app.Trouble  = ' '; 

            app.VBATEditField.Value = app.s.base.VBatIn1 ; 
            app.VBATEditField_2.Value = app.s.base.VBatIn2 ; 
            app.VServoEditField.Value = app.s.base.Vservo ; 
            app.V24EditField.Value = app.s.base.V24 ;
            app.V18EditField.Value = app.s.base.V18 ;
            app.V12EditField.Value = app.s.base.V12 ;
            app.V5EditField.Value = app.s.base.V5 ;

            app.Cur24EditField.Value = app.s.base.Cur24 ;
            app.Cur18EditField.Value = app.s.base.Cur18 ;
            app.Cur12EditField.Value= app.s.base.Cur12 ;
            app.Cur5EditField.Value= app.s.base.Cur5 ;
            app.CurServoEditField.Value= app.s.base.CurServo ;

            app.setLed( app.ChargeresistorLamp , app.s.Bit.ChargeSw1 , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.HotswapLamp , app.s.Bit.HotSwap1 , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.RegenerationLamp , app.s.Bit.Regen1 , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.BatgoodLamp , app.s.Bit.BatGood1 , [0,1,0],[1,0,0] ) ;  
            
            app.setLed( app.ChargeresistorLamp_2 , app.s.Bit.ChargeSw2 , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.HotswapLamp_2 , app.s.Bit.HotSwap2 , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.RegenerationLamp_2 , app.s.Bit.Regen2 , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.BatgoodLamp_2 , app.s.Bit.BatGood2 , [0,1,0],[1,0,0] ) ;  

            app.setLed( app.ONLamp24 , app.s.Bit.Sw24On , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.ONLamp18 , app.s.Bit.Sw18On , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.Load18Lamp , app.s.Bit.LSw18On , [0,1,0],[0,0,1] ) ;  
            % app.setLed( app.PowerGoodLamp18 , app.s.Bit.LSw18PowerGood , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.ONLamp12 , app.s.Bit.Sw12On , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.ONLamp5 , app.s.Bit.Sw5On , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.Load5V_1Lamp , app.s.Bit.LSw5_On , [0,1,0],[0,0,1] ) ;  
            % app.setLed( app.Load5V_2Lamp , app.s.Bit.LSw5_2On , [0,1,0],[0,0,1] ) ;  
            app.setLed( app.EmergencyMushroomLamp , app.s.Bit.Mushroom , [1,0,0],[0,1,0] ) ;  
            app.setLed( app.HoldSelfenableLamp , app.s.Bit.SelfSustain , [0,1,0],[0,0,1] ) ;  % Sw54On
            app.setLed(  app.ServoSWOnLamp, app.s.Bit.Sw54On , [0,1,0],[0,0,1] ) ;  % 

            app.setLed( app.SeelctedLamp , double(app.s.Bit.BatteryIndex == 1) , [0,1,0],[0,0,1] ) ;  % 
            app.setLed( app.SeelctedLamp_2 , double(app.s.Bit.BatteryIndex == 2) , [0,1,0],[0,0,1] ) ;  % 

            app.SetActuator( app.HS24VButton, app.s.Bit.HS24 , [0,1,0],[0,0,1] ,'HS24') ;
            app.SetActuator( app.HS12VButton, app.s.Bit.HS12 , [0,1,0],[0,0,1] ,'HS12') ; 
            app.SetActuator( app.LS1Button_2, app.s.Bit.LS1 , [0,1,0],[0,0,1] ,'LS1') ;
            app.SetActuator( app.LS2Button, app.s.Bit.LS2 , [0,1,0],[0,0,1] ,'LS2') ; 
            %app.SetActuator( app.ShuntButton, 0 , [0,1,0],[0,0,1],'Shunt' ) ;

            app.SetActuator( app.ON24Button, app.s.Bit.Buck24On , [0,1,0],[0,0,1] ,'V24',app.s.Bit.Buck24Exception) ;
            app.SetActuator( app.ON18Button , app.s.Bit.Buck18On , [0,1,0],[0,0,1] ,'V18',app.s.Bit.Buck18Exception) ;
            app.SetActuator( app.ON12Button , app.s.Bit.Buck12On , [0,1,0],[0,0,1] ,'V12',app.s.Bit.Buck12Exception) ;
            app.SetActuator( app.ON5Button , app.s.Bit.Buck5On , [0,1,0],[0,0,1] ,'V5',app.s.Bit.Buck5Exception) ;

            app.SetActuator( app.ON18LSWButton ,  app.s.Bit.LSw18On , [0,1,0],[0,0,1] ,'LSw18') ; 
            app.SetActuator( app.ON5LSW1Button,  app.s.Bit.LSw5_On , [0,1,0],[0,0,1] ,'LSw5') ; 
            % app.SetActuator( app.ON5LSW2Button ,  app.s.Bit.LSw5_2On , [0,1,0],[0,0,1] ,'LSw5_2') ; 

            app.SetActuator( app.EnableButton,  app.s.Bit.HotSwap1 , [0,1,0],[0,0,1] ,'HotSwap1') ; 
            app.SetActuator( app.EnableButton_2,  app.s.Bit.HotSwap2 , [0,1,0],[0,0,1] ,'HotSwap2') ; 

            app.OverrideswitchCheckBox.Value = logical(app.s.Bit.OverRideBatMachine) ; 

            if app.s.Bit.Mushroom || app.s.base.Vservo < 14.0
                app.ON24Button.Enable = 'off';
                app.ON18Button.Enable = 'off';
                app.ON12Button.Enable = 'off';
                app.ON5Button.Enable = 'off';
            else
                app.ON24Button.Enable = 'on';
                app.ON18Button.Enable = 'on';
                app.ON12Button.Enable = 'on';
                app.ON5Button.Enable = 'on';
            end

            % Discrete operations of router - only in debug mode 
            if ( app.s.Bit.OverRideBatMachine )
                app.SetActuator(app.ChargeButton,app.s.Bit.ChargeSw1 ,[0,1,0],[0,0,1] ,'Charge1') ;
                app.SetActuator(app.ChargeButton_2,app.s.Bit.ChargeSw2 ,[0,1,0],[0,0,1] ,'Charge2') ;

                app.SetActuator(app.RegenButton,app.s.Bit.Regen1 ,[0,1,0],[0,0,1] ,'Regen1') ;
                app.SetActuator(app.RegenButton_2,app.s.Bit.Regen2 ,[0,1,0],[0,0,1] ,'Regen2' ) ;

                app.SetActuator(app.RegenButton,app.s.Bit.Regen1 ,[0,1,0],[0,0,1] ,'Regen1') ;
                app.SetActuator(app.RegenButton_2,app.s.Bit.Regen2 ,[0,1,0],[0,0,1] ,'Regen2' ) ;

                app.SetActuator(app.EnableButton,app.s.Bit.HotSwap1 ,[0,1,0],[0,0,1] ,'Enable1') ;
                app.SetActuator(app.EnableButton_2,app.s.Bit.HotSwap2 ,[0,1,0],[0,0,1] ,'Enable2' ) ;

            else

                if (  app.EnablelocalcontrolCheckBox.Value)
                    if ( app.s.Bit.BatteryIndex == 1 )
                        app.EnableButton.Enable = "on" ; 
                    else
                        app.EnableButton.Enable = "off" ; 
                    end
                    if ( app.s.Bit.BatteryIndex == 2 )
                        app.EnableButton_2.Enable = "on" ; 
                    else
                        app.EnableButton_2.Enable = "off" ; 
                    end
                else
                    app.EnableButton.Enable = "off" ; 
                    app.EnableButton_2.Enable = "off" ; 
                end

                app.ChargeButton.Enable = "off" ; 
                app.ChargeButton_2.Enable = "off" ; 
                app.RegenButton.Enable = "off" ; 
                app.RegenButton_2.Enable = "off" ; 
            end

            app.InstructionTextArea.Value = app.Trouble ; 

            switch ( app.s.Bit.RecorderState)
                case 1
                    app.RecorderReadyLamp.Color = [0,1,0] ;
                case 0
                    app.RecorderReadyLamp.Color = [0,0,1] ;
                otherwise
                    app.RecorderReadyLamp.Color = [1,1,1] * 0.5  ;
            end
        end
        
        function UpdateDisplayWrapper(app,~,~ ) 
            try 
                update_display(app );
                app.ConnectedLabel.Text = 'Connected' ;
                app.ConnectedLabel.BackgroundColor = [0,1,0] ; 
            catch myerr
                x = myerr.message ; %#ok<NASGU> 
                app.ConnectedLabel.Text = 'NOT Connected' ;
                app.ConnectedLabel.BackgroundColor = [1,0,0] ; 
            end 
            app.Lock = 0 ; 
            
        end
        
        function setLed(~,control,state,c1,c2,fault,faultlabel)
            if nargin >= 6 
                if ~isempty(fault)
                    set(control,'Color',[1,0,0]) ;
                    set(faultlabel,'Text',fault,'BackgroundColor',[1,0,0],'FontColor',[1,1,1]); 
                    return 
                else
                    set(faultlabel,'Text','Ok','BackgroundColor',[0,1,0],'FontColor',[0,0,0]); 
                end
            end
            if state
                set(control,'Color',c1) ; 
            else
                set(control,'Color',c2) ; 
            end
        end
        
        function SetActuator(app,control,state,c1,c2,name,trouble)
            if nargin < 7 
                trouble = [ ]; 
            end 
            if state
                set(control,'BackgroundColor',c1,'FontColor',[1,1,1]-c1,'Text',[name,':OFF'],'Enable','on') ; 
            else
                if ( trouble)
                    trouble = min(trouble,7) ; 
                    TroubleText = ["Input undrvoltage","Input overvoltage","Output OverVolt","OverLoad","Mushroom","Unknown","Unknown"];
                    set(control,'BackgroundColor',[1,0,0],'FontColor',[1,1,1]-c2,'Text',[name,':On(Fault)'],'Enable','on') ; 
                    app.Trouble = [app.Trouble,' : ',name,' : ',char(TroubleText(trouble)),' : ' ]; 
                else
                    set(control,'BackgroundColor',c2,'FontColor',[1,1,1]-c2,'Text',[name,':ON'],'Enable','on') ; 
                end
            end           
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DispT %#ok<GVMIS> 
            
            evalin('base','AtpStart') ; % Will renew environment

            app.CreateStruct =  struct('Interpreter','tex','WindowStyle' , 'modal') ; 

            if ~exist('DispT','var') || ~isa(DispT,'DlgTimerObj')
                evalin('base','AtpStart') ; 
            end 
            
            app.Lock = 1 ; 
            app.Trouble = [] ; 

            app.EvtObj = DlgUserObj(@app.UpdateDisplayWrapper,findobj(app) );
            app.EvtObj.MylistenToTimer(DispT) ;   

            app.Lock = 0 ; 

            %app.FaultCombiner1Label.Visible = "off" ; 
            %app.FaultCombiner2Label.Visible = "off" ; 
            app.ShuntButton.Visible = 'off ' ; 
            app.ShuntButton.Enable = "off" ; 

            app.s = PGetState() ; 

            projtype = FetchObj( [hex2dec('2301'),5] , GetDataType('long') , 'ProjType' )  ; 
            vers  = FetchObj( [hex2dec('2301'),6] , GetDataType('long') , 'ProjVer' )  ; 

            Patch  = bitand(vers,15); 
            Subver = bitand(vers,15*16)/16; 
            Ver = bitand(vers,127*2^8)/2^8; 
            Day = bitand(vers,31*2^15)/2^15; 
            Month = bitand(vers,15*2^20)/2^20; 
            Yr = bitand(vers,255*2^24)/2^24; 

            if ~(37376==projtype)
                error('Unexpected target (Expected PD2)') ; 
            end
            MonName = { 'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'} ; 
            verstr = ['FW Date : 20',num2str(Yr),':',MonName{Month},':',num2str(Day),' Rev [',num2str(Ver),':',num2str(Subver),':',num2str(Patch),']'];
            app.SWrevisionxxxxxxLabel.Text = verstr ; 
            
        end

        % Button pushed function: ON18LSWButton
        function ON18LSWButtonPushed(app, event)
            
            value = double ( ~logical( app.s.Bit.LSw18On )) ; 
            SendObj( [hex2dec('2106'),10] , value, GetDataType('long') , 'Set Out switch for 18V' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global TmMgrTS %#ok<GVMIS> 
            try
                app.Lock = 1 ; 
                TmMgrTS.SetCounter('NBIT',-1000) ; 
                delete( app.EvtObj) ;
            %delete( app.Timer ) ;
            catch 
            end
            delete(app)
           
        end

        % Button pushed function: ChargeButton
        function ChargeButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.ChargeSw1 )) ; 
            SendObj( [hex2dec('2106'),2] , value, GetDataType('long') , 'Set Charger switch #1' ) ;
        end

        % Value changed function: OverrideswitchCheckBox
        function OverrideswitchCheckBoxValueChanged(app, event)
            %value = app.OverrideswitchCheckBox.Value;
            value = double ( ~logical( app.s.Bit.OverRideBatMachine )) ; 
            SendObj( [hex2dec('2106'),1] , value, GetDataType('long') , 'Set debug mode on battery router' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills 
        end

        % Button pushed function: ChargeButton_2
        function ChargeButton_2Pushed(app, event)
            value = double ( ~logical( app.s.Bit.ChargeSw2 )) ; 
            SendObj( [hex2dec('2106'),3], value, GetDataType('long') , 'Set Charger switch #2' ) ;            
        end

        % Button pushed function: ON24Button
        function ON24ButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.Buck24On )) ; 
            if ( app.s.Bit.OverRideBatMachine  )
                SendObj( [hex2dec('2106'),4] , value, GetDataType('long') , 'Set state for 24V' ) ; 
            else
                SendObj( [hex2dec('2004'),2] , value, GetDataType('long') , 'Set state for 24V' ) ; 
            end 
            app.s = PGetState() ; % Refresh values after possible kills 
            
        end

        % Button pushed function: ON18Button
        function ON18ButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.Buck18On )) ; 
            if ( app.s.Bit.OverRideBatMachine  )
                SendObj( [hex2dec('2106'),5] , value, GetDataType('long') , 'Set state for 18V' ) ; 
            else
                SendObj( [hex2dec('2004'),10] , value, GetDataType('long') , 'Set state for 18V' ) ; 
            end 
            app.s = PGetState() ; % Refresh values after possible kills 
            
        end

        % Button pushed function: ON12Button
        function ON12ButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.Buck12On )) ;             
            if ( app.s.Bit.OverRideBatMachine  )
                SendObj( [hex2dec('2106'),6] , value, GetDataType('long') , 'Set state for 12V' ) ; 
            else
                SendObj( [hex2dec('2004'),1] , value, GetDataType('long') , 'Set state for 12V' ) ; 
            end 
            app.s = PGetState() ; % Refresh values after possible kills 
            
        end

        % Button pushed function: ON5Button
        function ON5ButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.Buck5On )) ; 
            SendObj( [hex2dec('2106'),7] , value, GetDataType('long') , 'Set state for 5V' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills 
            
        end

        % Button pushed function: ON5LSW1Button
        function ON5LSW1ButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.LSw5_On )) ; 
            SendObj( [hex2dec('2106'),8] , value, GetDataType('long') , 'Set Out switch for 5V_1' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             
            
        end

        % Callback function
        function ON5LSW2ButtonPushed(app, event)

            value = double ( ~logical( app.s.Bit.LSw5_2On )) ; 
            SendObj( [hex2dec('2106'),9] , value, GetDataType('long') , 'Set Out switch for 5V_2' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills                         
            
        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)

            value = double ( ~logical( app.s.Bit.HotSwap1 )) ; 
            if ( app.s.Bit.OverRideBatMachine )
                SendObj( [hex2dec('2106'),11] , value, GetDataType('long') , 'Set HotSwap #1' ) ; 
            else
                SendObj( [hex2dec('2004'),3] , value, GetDataType('long') , 'Set Battery On' ) ;                 
            end
            app.s = PGetState() ; % Refresh values after possible kills             

        end

        % Button pushed function: EnableButton_2
        function EnableButton_2Pushed(app, event)

            value = double ( ~logical( app.s.Bit.HotSwap2)) ; 
            SendObj( [hex2dec('2106'),12] , value, GetDataType('long') , 'Set HotSwap #2' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             

            
        end

        % Button pushed function: HS12VButton
        function HS12VButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.HS12)) ; 
            SendObj( [hex2dec('2106'),15] , value, GetDataType('long') , 'Set HS12' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             
           
        end

        % Button pushed function: LS2Button
        function LS2ButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.LS2)) ; 
            SendObj( [hex2dec('2106'),17] , value, GetDataType('long') , 'Set LS2' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             
                   
        end

        % Button pushed function: ShuntButton
        function ShuntButtonPushed(app, event)
            
        end

        % Button pushed function: HS24VButton
        function HS24VButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.HS24)) ; 
            SendObj( [hex2dec('2106'),14] , value, GetDataType('long') , 'Set HS24' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             
            
        end

        % Button pushed function: LS1Button_2
        function LS1Button_2Pushed(app, event)
            value = double ( ~logical( app.s.Bit.LS1)) ; 
            SendObj( [hex2dec('2106'),16] , value, GetDataType('long') , 'Set LS1' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             
            
        end

        % Button pushed function: RegenButton
        function RegenButtonPushed(app, event)
            value = double ( ~logical( app.s.Bit.Regen1)) ; 
            SendObj( [hex2dec('2106'),18] , value, GetDataType('long') , 'Set Regen1' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             
            
        end

        % Button pushed function: RegenButton_2
        function RegenButton_2Pushed(app, event)
            value = double ( ~logical( app.s.Bit.Regen2)) ; 
            SendObj( [hex2dec('2106'),19] , value, GetDataType('long') , 'Set Regen2' ) ; 
            app.s = PGetState() ; % Refresh values after possible kills             
            
        end

        % Value changed function: EnablelocalcontrolCheckBox
        function EnablelocalcontrolCheckBoxValueChanged(app, event)
            value = app.EnablelocalcontrolCheckBox.Value;
            
        end

        % Button pushed function: I2CtestButton
        function I2CtestButtonPushed(app, event)
            txt = 'Testing I2C1' ;
            crlf = char([13,10]) ; 
            [Retcode1,~] = SendObj( [hex2dec('2106'),22] , 120, GetDataType('long') , 'Test I2C#1+Flash' ) ; 
            [Retcode2,~] = SendObj( [hex2dec('2106'),22] , 123, GetDataType('long') , 'Test I2C#1+Flash' ) ; 

            if Retcode1 || Retcode2  
                txt = [txt, crlf , 'Fail'] ;   
            else
                txt = [txt, crlf , 'Pass'] ;   
            end 
            txt = [ txt, crlf,  'Testing I2C2' ] ; 
            [Retcode1,~] = SendObj( [hex2dec('2106'),23] , 120, GetDataType('long') , 'Test I2C#2+Flash' ) ; 
            [Retcode2,~] = SendObj( [hex2dec('2106'),23] , 123, GetDataType('long') , 'Test I2C#2+Flash' ) ; 
            if Retcode1 || Retcode2  
                txt = [txt, crlf , 'Fail'] ;   
            else
                txt = [txt, crlf , 'Pass'] ;   
            end 
            app.ATPTextArea.Value = txt; 
            
        end

        % Button pushed function: ShutDowntestButton
        function ShutDowntestButtonPushed(app, event)
            
            SendObj( [hex2dec('2106'),24] , 123456, GetDataType('long') , 'Kill self' ) ; 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PD2dashboardLabel = uilabel(app.UIFigure);
            app.PD2dashboardLabel.Text = 'PD2 dashboard';
            app.Combiner1Panel = uipanel(app.UIFigure);
            app.Combiner1Panel.Title = 'Combiner#1';
            app.ChargeresistorLampLabel = uilabel(app.Combiner1Panel);
            app.ChargeresistorLampLabel.Text = 'Charge resistor';
            app.ChargeresistorLamp = uilamp(app.Combiner1Panel);
            app.HotswapLampLabel = uilabel(app.Combiner1Panel);
            app.HotswapLampLabel.Text = 'Hotswap';
            app.HotswapLamp = uilamp(app.Combiner1Panel);
            app.RegenerationLampLabel = uilabel(app.Combiner1Panel);
            app.RegenerationLampLabel.Text = 'Regeneration';
            app.RegenerationLamp = uilamp(app.Combiner1Panel);
            app.VBATEditField = uieditfield(app.Combiner1Panel, 'numeric');
            app.VBATEditField.ValueDisplayFormat = '%.1f';
            app.BattryvoltageLabel = uilabel(app.Combiner1Panel);
            app.BattryvoltageLabel.Text = 'Battry voltage';
            app.BatgoodLampLabel = uilabel(app.Combiner1Panel);
            app.BatgoodLampLabel.Text = 'Bat good';
            app.BatgoodLamp = uilamp(app.Combiner1Panel);
            app.ChargeButton = uibutton(app.Combiner1Panel, 'push');
            app.ChargeButton.ButtonPushedFcn = createCallbackFcn(app, @ChargeButtonPushed, true);
            app.ChargeButton.Text = 'Charge';
            app.EnableButton = uibutton(app.Combiner1Panel, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.RegenButton = uibutton(app.Combiner1Panel, 'push');
            app.RegenButton.ButtonPushedFcn = createCallbackFcn(app, @RegenButtonPushed, true);
            app.RegenButton.Text = 'Regen';
            app.SeelctedLampLabel = uilabel(app.Combiner1Panel);
            app.SeelctedLampLabel.Text = 'Seelcted';
            app.SeelctedLamp = uilamp(app.Combiner1Panel);
            app.Combiner2Panel = uipanel(app.UIFigure);
            app.Combiner2Panel.Title = 'Combiner#2';
            app.ChargeresistorLampLabel_3 = uilabel(app.Combiner2Panel);
            app.ChargeresistorLampLabel_3.Text = 'Charge resistor';
            app.ChargeresistorLamp_2 = uilamp(app.Combiner2Panel);
            app.HotswapLampLabel_2 = uilabel(app.Combiner2Panel);
            app.HotswapLampLabel_2.Text = 'Hotswap';
            app.HotswapLamp_2 = uilamp(app.Combiner2Panel);
            app.RegenerationLampLabel_2 = uilabel(app.Combiner2Panel);
            app.RegenerationLampLabel_2.Text = 'Regeneration';
            app.RegenerationLamp_2 = uilamp(app.Combiner2Panel);
            app.VBATEditField_2 = uieditfield(app.Combiner2Panel, 'numeric');
            app.VBATEditField_2.ValueDisplayFormat = '%.1f';
            app.BattryvoltageLabel_2 = uilabel(app.Combiner2Panel);
            app.BattryvoltageLabel_2.Text = 'Battry voltage';
            app.BatgoodLamp_2Label = uilabel(app.Combiner2Panel);
            app.BatgoodLamp_2Label.Text = 'Bat good';
            app.BatgoodLamp_2 = uilamp(app.Combiner2Panel);
            app.ChargeButton_2 = uibutton(app.Combiner2Panel, 'push');
            app.ChargeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChargeButton_2Pushed, true);
            app.ChargeButton_2.Text = 'Charge';
            app.EnableButton_2 = uibutton(app.Combiner2Panel, 'push');
            app.EnableButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnableButton_2Pushed, true);
            app.EnableButton_2.Text = 'Enable';
            app.RegenButton_2 = uibutton(app.Combiner2Panel, 'push');
            app.RegenButton_2.ButtonPushedFcn = createCallbackFcn(app, @RegenButton_2Pushed, true);
            app.RegenButton_2.Text = 'Regen';
            app.SeelctedLamp_2Label = uilabel(app.Combiner2Panel);
            app.SeelctedLamp_2Label.Text = 'Seelcted';
            app.SeelctedLamp_2 = uilamp(app.Combiner2Panel);
            app.VPanel = uipanel(app.UIFigure);
            app.VPanel.Title = '24V';
            app.ONLampLabel = uilabel(app.VPanel);
            app.ONLampLabel.Text = 'ON';
            app.ONLamp24 = uilamp(app.VPanel);
            app.V24EditField = uieditfield(app.VPanel, 'numeric');
            app.V24EditField.ValueDisplayFormat = '%.1f';
            app.OutputvoltageLabel = uilabel(app.VPanel);
            app.OutputvoltageLabel.Text = 'Output voltage';
            app.OutputcurrentLabel = uilabel(app.VPanel);
            app.OutputcurrentLabel.Text = 'Output current';
            app.Cur24EditField = uieditfield(app.VPanel, 'numeric');
            app.Cur24EditField.ValueDisplayFormat = '%.1f';
            app.ON24Button = uibutton(app.VPanel, 'push');
            app.ON24Button.ButtonPushedFcn = createCallbackFcn(app, @ON24ButtonPushed, true);
            app.ON24Button.Text = 'ON';
            app.OutputWattsLabel = uilabel(app.VPanel);
            app.OutputWattsLabel.Text = 'Output Watts';
            app.Watt24EditField = uieditfield(app.VPanel, 'numeric');
            app.Watt24EditField.ValueDisplayFormat = '%.1f';
            app.VPanel_2 = uipanel(app.UIFigure);
            app.VPanel_2.Title = '18V';
            app.ONLampLabel_2 = uilabel(app.VPanel_2);
            app.ONLampLabel_2.Text = 'ON';
            app.ONLamp18 = uilamp(app.VPanel_2);
            app.V18EditField = uieditfield(app.VPanel_2, 'numeric');
            app.V18EditField.ValueDisplayFormat = '%.1f';
            app.OutputvoltageLabel_2 = uilabel(app.VPanel_2);
            app.OutputvoltageLabel_2.Text = 'Output voltage';
            app.OutputcurrentLabel_2 = uilabel(app.VPanel_2);
            app.OutputcurrentLabel_2.Text = 'Output current';
            app.Cur18EditField = uieditfield(app.VPanel_2, 'numeric');
            app.Cur18EditField.ValueDisplayFormat = '%.1f';
            app.LoadswitchLabel = uilabel(app.VPanel_2);
            app.LoadswitchLabel.Text = 'Load switch';
            app.Load18Lamp = uilamp(app.VPanel_2);
            app.ON18Button = uibutton(app.VPanel_2, 'push');
            app.ON18Button.ButtonPushedFcn = createCallbackFcn(app, @ON18ButtonPushed, true);
            app.ON18Button.Text = 'ON';
            app.ON18LSWButton = uibutton(app.VPanel_2, 'push');
            app.ON18LSWButton.ButtonPushedFcn = createCallbackFcn(app, @ON18LSWButtonPushed, true);
            app.ON18LSWButton.Text = 'ON LSW';
            app.Watt18EditField = uieditfield(app.VPanel_2, 'numeric');
            app.Watt18EditField.ValueDisplayFormat = '%.1f';
            app.OutputWattsLabel_2 = uilabel(app.VPanel_2);
            app.OutputWattsLabel_2.Text = 'Output Watts';
            app.VPanel_3 = uipanel(app.UIFigure);
            app.VPanel_3.Title = '12V';
            app.ONLampLabel_3 = uilabel(app.VPanel_3);
            app.ONLampLabel_3.Text = 'ON';
            app.ONLamp12 = uilamp(app.VPanel_3);
            app.V12EditField = uieditfield(app.VPanel_3, 'numeric');
            app.V12EditField.ValueDisplayFormat = '%.1f';
            app.OutputvoltageLabel_3 = uilabel(app.VPanel_3);
            app.OutputvoltageLabel_3.Text = 'Output voltage';
            app.OutputcurrentLabel_3 = uilabel(app.VPanel_3);
            app.OutputcurrentLabel_3.Text = 'Output current';
            app.Cur12EditField = uieditfield(app.VPanel_3, 'numeric');
            app.Cur12EditField.ValueDisplayFormat = '%.1f';
            app.ON12Button = uibutton(app.VPanel_3, 'push');
            app.ON12Button.ButtonPushedFcn = createCallbackFcn(app, @ON12ButtonPushed, true);
            app.ON12Button.Text = 'ON';
            app.Watt12EditField = uieditfield(app.VPanel_3, 'numeric');
            app.Watt12EditField.ValueDisplayFormat = '%.1f';
            app.OutputWattsLabel_3 = uilabel(app.VPanel_3);
            app.OutputWattsLabel_3.Text = 'Output Watts';
            app.VPanel_4 = uipanel(app.UIFigure);
            app.VPanel_4.Title = '5V';
            app.ONLampLabel_4 = uilabel(app.VPanel_4);
            app.ONLampLabel_4.Text = 'ON';
            app.ONLamp5 = uilamp(app.VPanel_4);
            app.V5EditField = uieditfield(app.VPanel_4, 'numeric');
            app.V5EditField.ValueDisplayFormat = '%.1f';
            app.OutputvoltageLabel_4 = uilabel(app.VPanel_4);
            app.OutputvoltageLabel_4.Text = 'Output voltage';
            app.OutputcurrentLabel_4 = uilabel(app.VPanel_4);
            app.OutputcurrentLabel_4.Text = 'Output current';
            app.Cur5EditField = uieditfield(app.VPanel_4, 'numeric');
            app.Cur5EditField.ValueDisplayFormat = '%.1f';
            app.Load1Label = uilabel(app.VPanel_4);
            app.Load1Label.Text = 'Load #1';
            app.Load5V_1Lamp = uilamp(app.VPanel_4);
            app.ON5Button = uibutton(app.VPanel_4, 'push');
            app.ON5Button.ButtonPushedFcn = createCallbackFcn(app, @ON5ButtonPushed, true);
            app.ON5Button.Text = 'ON';
            app.ON5LSW1Button = uibutton(app.VPanel_4, 'push');
            app.ON5LSW1Button.ButtonPushedFcn = createCallbackFcn(app, @ON5LSW1ButtonPushed, true);
            app.ON5LSW1Button.Text = 'ON LSW';
            app.OutputWattsLabel_4 = uilabel(app.VPanel_4);
            app.OutputWattsLabel_4.Text = 'Output Watts';
            app.Watt5EditField = uieditfield(app.VPanel_4, 'numeric');
            app.Watt5EditField.ValueDisplayFormat = '%.1f';
            app.InputsectionPanel = uipanel(app.UIFigure);
            app.InputsectionPanel.Title = 'Input section';
            app.ServobusvoltageLabel = uilabel(app.InputsectionPanel);
            app.ServobusvoltageLabel.Text = 'Servo bus voltage';
            app.VServoEditField = uieditfield(app.InputsectionPanel, 'numeric');
            app.VServoEditField.ValueDisplayFormat = '%.1f';
            app.ServobuscurrentLabel = uilabel(app.InputsectionPanel);
            app.ServobuscurrentLabel.Text = 'Servo bus current';
            app.CurServoEditField = uieditfield(app.InputsectionPanel, 'numeric');
            app.CurServoEditField.ValueDisplayFormat = '%.1f';
            app.ServobuspowerLabel = uilabel(app.InputsectionPanel);
            app.ServobuspowerLabel.Text = 'Servo bus power';
            app.ServoPowerEditField = uieditfield(app.InputsectionPanel, 'numeric');
            app.ServoPowerEditField.ValueDisplayFormat = '%.1f';
            app.ServoSWOnLampLabel = uilabel(app.InputsectionPanel);
            app.ServoSWOnLampLabel.Text = 'Servo SW On';
            app.ServoSWOnLamp = uilamp(app.InputsectionPanel);
            app.TestsPanel = uipanel(app.UIFigure);
            app.TestsPanel.Title = 'Tests';
            app.I2CtestButton = uibutton(app.TestsPanel, 'push');
            app.I2CtestButton.ButtonPushedFcn = createCallbackFcn(app, @I2CtestButtonPushed, true);
            app.I2CtestButton.Text = 'I2C test';
            app.USBtestButton = uibutton(app.TestsPanel, 'push');
            app.USBtestButton.Text = 'USB test';
            app.ShutDowntestButton = uibutton(app.TestsPanel, 'push');
            app.ShutDowntestButton.ButtonPushedFcn = createCallbackFcn(app, @ShutDowntestButtonPushed, true);
            app.ShutDowntestButton.Text = 'ShutDown test';
            app.OverrideswitchCheckBox = uicheckbox(app.TestsPanel);
            app.OverrideswitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverrideswitchCheckBoxValueChanged, true);
            app.OverrideswitchCheckBox.Text = 'Override switch';
            app.EnablelocalcontrolCheckBox = uicheckbox(app.TestsPanel);
            app.EnablelocalcontrolCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablelocalcontrolCheckBoxValueChanged, true);
            app.EnablelocalcontrolCheckBox.Text = 'Enable local control';
            app.BatSwitchTestButton = uibutton(app.TestsPanel, 'push');
            app.BatSwitchTestButton.Text = 'Bat Switch Test';
            app.InstructionTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionTextAreaLabel.Text = 'Instruction';
            app.InstructionTextArea = uitextarea(app.UIFigure);
            app.TroubleTextAreaLabel = uilabel(app.UIFigure);
            app.TroubleTextAreaLabel.Text = 'Trouble';
            app.TroubleTextArea = uitextarea(app.UIFigure);
            app.BAT1Panel = uipanel(app.UIFigure);
            app.BAT1Panel.Title = 'BAT#1';
            app.BAT2Panel = uipanel(app.UIFigure);
            app.BAT2Panel.Title = 'BAT#2';
            app.ConnectedLabel = uilabel(app.UIFigure);
            app.ConnectedLabel.Text = 'Connected';
            app.SwitchesPanel = uipanel(app.UIFigure);
            app.SwitchesPanel.Title = 'Switches';
            app.HS24VButton = uibutton(app.SwitchesPanel, 'push');
            app.HS24VButton.ButtonPushedFcn = createCallbackFcn(app, @HS24VButtonPushed, true);
            app.HS24VButton.Text = 'HS (24V)';
            app.HS12VButton = uibutton(app.SwitchesPanel, 'push');
            app.HS12VButton.ButtonPushedFcn = createCallbackFcn(app, @HS12VButtonPushed, true);
            app.HS12VButton.Text = 'HS (12V)';
            app.LS1Button_2 = uibutton(app.SwitchesPanel, 'push');
            app.LS1Button_2.ButtonPushedFcn = createCallbackFcn(app, @LS1Button_2Pushed, true);
            app.LS1Button_2.Text = 'LS1';
            app.LS2Button = uibutton(app.SwitchesPanel, 'push');
            app.LS2Button.ButtonPushedFcn = createCallbackFcn(app, @LS2ButtonPushed, true);
            app.LS2Button.Text = 'LS2';
            app.ShuntButton = uibutton(app.SwitchesPanel, 'push');
            app.ShuntButton.ButtonPushedFcn = createCallbackFcn(app, @ShuntButtonPushed, true);
            app.ShuntButton.Text = 'Shunt';
            app.EmergencyMushroomLampLabel = uilabel(app.UIFigure);
            app.EmergencyMushroomLampLabel.Text = 'Emergency Mushroom';
            app.EmergencyMushroomLamp = uilamp(app.UIFigure);
            app.HoldSelfenableLampLabel = uilabel(app.UIFigure);
            app.HoldSelfenableLampLabel.Text = 'Hold Self enable';
            app.HoldSelfenableLamp = uilamp(app.UIFigure);
            app.SWrevisionxxxxxxLabel = uilabel(app.UIFigure);
            app.SWrevisionxxxxxxLabel.Text = 'SW revision: xxx.xxx';
            app.RecorderReadyLampLabel = uilabel(app.UIFigure);
            app.RecorderReadyLampLabel.Text = 'Recorder Ready';
            app.RecorderReadyLamp = uilamp(app.UIFigure);
            app.ATPTextAreaLabel = uilabel(app.UIFigure);
            app.ATPTextAreaLabel.Text = 'ATP';
            app.ATPTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pd2Dash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end