classdef GetComPort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectCOMportLabel  matlab.ui.control.Label
        PortsDropDownLabel  matlab.ui.control.Label
        PortsDropDown       matlab.ui.control.DropDown
        OKButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            list = serialportlist ; 
            if isempty(list) 
                error('No COM ports found') ; 
            end
            if isa(list,'char')
                m = list ; 
                list = cell(1,1) ; 
                list{1} = m{1} ; 
            end
            app.PortsDropDown.Items = list ; 
            app.PortsDropDown.Value = app.PortsDropDown.Items{1} ; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global comport 
            comport = app.PortsDropDown.Value ; 
            % evalin('base',['comport=''',app.PortsDropDown.Value,''';'])
            delete(app)
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global comport 
            comport = app.PortsDropDown.Value ; 
            % evalin('base',['comport=''',app.PortsDropDown.Value,''';'])
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectCOMportLabel = uilabel(app.UIFigure);
            app.SelectCOMportLabel.Text = 'Select COM port';
            app.PortsDropDownLabel = uilabel(app.UIFigure);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.UIFigure);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetComPort

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end