classdef HardAtpLB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TestHOSTcommunicationButton    matlab.ui.control.Button
        TestRS485ManpulatorButton      matlab.ui.control.Button
        ROverloadEditField             matlab.ui.control.NumericEditField
        RightOverloadEditField         matlab.ui.control.NumericEditField
        LeftInductiveEditField         matlab.ui.control.NumericEditField
        RightInductiveEditField        matlab.ui.control.NumericEditField
        RightInductiveEditFieldLabel   matlab.ui.control.Label
        LeftOverloadEditField          matlab.ui.control.NumericEditField
        LeftOverloadLabel              matlab.ui.control.Label
        LeftInductiveLabel             matlab.ui.control.Label
        ROverloadEditFieldLabel        matlab.ui.control.Label
        RightOverloadEditFieldLabel    matlab.ui.control.Label
        V12EditField                   matlab.ui.control.NumericEditField
        V12EditFieldLabel              matlab.ui.control.Label
        V5EditField                    matlab.ui.control.NumericEditField
        V5EditFieldLabel               matlab.ui.control.Label
        D2CheckBox                     matlab.ui.control.CheckBox
        D1CheckBox                     matlab.ui.control.CheckBox
        PLCDigitalinLabel              matlab.ui.control.Label
        Ain2EditField                  matlab.ui.control.NumericEditField
        Ain2EditFieldLabel             matlab.ui.control.Label
        LaserEditField                 matlab.ui.control.NumericEditField
        LaserEditFieldLabel            matlab.ui.control.Label
        AnalogsLabel                   matlab.ui.control.Label
        ActuationsLabel                matlab.ui.control.Label
        ManipulatorLabel               matlab.ui.control.Label
        Manipspare2Button              matlab.ui.control.StateButton
        Manipspare1Button              matlab.ui.control.StateButton
        Maniplight2Button              matlab.ui.control.StateButton
        Maniplight1Button              matlab.ui.control.StateButton
        VacuumButton                   matlab.ui.control.StateButton
        PitchEditField                 matlab.ui.control.EditField
        PitchEditFieldLabel            matlab.ui.control.Label
        RollEditField                  matlab.ui.control.EditField
        RollEditFieldLabel             matlab.ui.control.Label
        GyroLabel                      matlab.ui.control.Label
        VoltagesLabel                  matlab.ui.control.Label
        Potentiometer2EditField        matlab.ui.control.EditField
        Potentiometer2EditFieldLabel   matlab.ui.control.Label
        Potentiometer1EditField        matlab.ui.control.EditField
        Potentiometer1EditFieldLabel   matlab.ui.control.Label
        EncoderEditField_2             matlab.ui.control.EditField
        EncoderEditField_2Label        matlab.ui.control.Label
        PotentiometerEditField_2       matlab.ui.control.EditField
        PotentiometerEditField_2Label  matlab.ui.control.Label
        EncoderEditField               matlab.ui.control.EditField
        EncoderEditFieldLabel          matlab.ui.control.Label
        PotentiometerEditField         matlab.ui.control.EditField
        PotentiometerEditFieldLabel    matlab.ui.control.Label
        NeckLabel                      matlab.ui.control.Label
        LeftWheelLabel                 matlab.ui.control.Label
        RightWheelLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        Timer % Timer Object
        TempCnt % Description
        SpareAct1 % Description
        SpareAct2 % Description
        ActLight1 % Description
        ActLight2 % Description
        Vacuum % Description
    end
    
    methods (Access = private)


        function TimerUpdate(app,~,~)
            global DataType 
            global AtpCfg
            AtpCfg.FetchRetry = 1;
            
            try 
            %check all "check" buttens and update wherever "check" is pressed
            CommFunc = [] ; %can be changed
            if ( app.TempCnt > 0 )
                app.TempCnt = app.TempCnt - 1; %junk   
                return ;
            end
            
            SendObj( [hex2dec('2220'),110] , hex2dec('1234') , DataType.long , 'Set test mode' ) ;

            
            if (1) % app.checkRWCheckBox.Value)
                %app.EncoderEditField.Value = string(app.TempCnt); %junk
                
                app.EncoderEditField.Value = string(FetchObj( [hex2dec('2204'),20] , DataType.float , 'R enc' ));
                app.PotentiometerEditField.Value = string(FetchObj( [hex2dec('2204'),9] , DataType.float , 'Get R pot' ));
                app.RightOverloadEditField.Value = FetchObj( [hex2dec('2204'),29] , DataType.float , 'RightOverloadEditField' );
                app.RightInductiveEditField.Value = FetchObj( [hex2dec('2204'),27] , DataType.float , 'RightInductiveEditField' );
                %app.InductivesensorEditField.Value = ...
                %app.OverloadResistorEditField.Value = ...
            end
            if (1) % app.checkLWCheckBox.Value)
                app.EncoderEditField_2.Value = string(FetchObj( [hex2dec('2204'),21] , DataType.float , 'L enc' ));
                app.PotentiometerEditField_2.Value = string(FetchObj( [hex2dec('2204'),8] , DataType.float , 'Get L pot' ));
                app.LeftOverloadEditField.Value = FetchObj( [hex2dec('2204'),28] , DataType.float , 'LeftOverloadEditField' );
                app.LeftInductiveEditField.Value = FetchObj( [hex2dec('2204'),26] , DataType.float , 'LeftOverloadEditField' );
                
                %app.InductivesensorEditField_2.Value = ..
                %app.OverloadResistorEditField_2.Value = ..
            end
            app.Potentiometer1EditField.Value = string(FetchObj( [hex2dec('2204'),1] , DataType.float , 'Get POT1' ));
            app.Potentiometer2EditField.Value = string(FetchObj( [hex2dec('2204'),2] , DataType.float , 'Get POT2' ));
            app.ROverloadEditField.Value = FetchObj( [hex2dec('2204'),25] , DataType.float , 'Get Overload NK volts' ) ;
                %app.OverloadResistorEditField_3.Value = ..
                %app.VEditField.Value = .. %5v
                
                
            v12 = FetchObj( [hex2dec('2204'),14] , DataType.float , 'V12' );  %12V
            v5  = FetchObj( [hex2dec('2204'),15] , DataType.float , 'V5' );  %12V
            app.V12EditField.Value = v12 ; 
            app.V5EditField.Value = v5 ; 
            
            app.PitchEditField.Value = string(FetchObj([hex2dec('2204'),57] , DataType.float ,'SysState.Nav.pitch',CommFunc)* 180 / pi);
            app.RollEditField.Value = string(FetchObj( [hex2dec('2204'),56] , DataType.float ,'SysState.Nav.roll',CommFunc)* 180 / pi);

            word = FetchObj([hex2dec('2220'),110] , DataType.long ,'Manip digital in',CommFunc) ; 
            app.D1CheckBox.Value = mod(word,2) ; 
            app.D2CheckBox.Value = bitand(word,2) / 2  ;  
            
            us1 = FetchObj([hex2dec('2204'),22] , DataType.float ,'Laser US1',CommFunc); 
            us2 = FetchObj([hex2dec('2204'),23] , DataType.float ,'Laser US2',CommFunc); 
            
            app.Ain2EditField.Value = us2 ;
            app.LaserEditField.Value = us1 ; 
            SetManipAct(app); 
            
            catch 
                app.TempCnt = 5 ; 
            end 
        end
        
        
        function results = SetManipAct(app)
            global DataType
            word = 2.^(0:4) * [app.SpareAct1;app.SpareAct2;app.ActLight1; app.ActLight2 ;app.Vacuum ] ;
            SendObj( [hex2dec('2220'),112] , 1 , DataType.long , 'Set discretes bypass' ) ;% Blue
            SendObj( [hex2dec('2220'),111] , word , DataType.long , 'Set Manipulator Actuation' ) ;% Blue
            results = 0 ; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Create timer object
            app.Timer = timer(...
            'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
            'Period', 0.5, ...                     % Period is 0.5 second
            'BusyMode', 'queue',...              % Queue timer callbacks when busy
            'TimerFcn', @app.TimerUpdate);      % Specify callback function  
            
            app.SpareAct1 = 0 ; % Description
            app.SpareAct2 = 0 ; % Description
            app.ActLight1 = 0 ; % Description
            app.ActLight2 = 0 ; % Description
            app.Vacuum = 0 ; % Description

            app.TempCnt = 0; %junk
            start(app.Timer);
            
        end

        % Value changed function: VacuumButton
        function VacuumButtonValueChanged(app, event)
            value = app.VacuumButton.Value;
            app.Vacuum = value ; 
            SetManipAct(app); 
        end

        % Value changed function: Maniplight1Button
        function Maniplight1ButtonValueChanged(app, event)
            value = app.Maniplight1Button.Value;
            app.ActLight1 = value ; 
            SetManipAct(app); 
        end

        % Value changed function: Maniplight2Button
        function Maniplight2ButtonValueChanged(app, event)
            value = app.Maniplight2Button.Value;
            app.ActLight2 = value ; 
            SetManipAct(app); 
        end

        % Value changed function: Manipspare1Button
        function Manipspare1ButtonValueChanged(app, event)
            value = app.Manipspare1Button.Value;
            app.SpareAct1 = value ; 
            SetManipAct(app); 
        end

        % Value changed function: Manipspare2Button
        function Manipspare2ButtonValueChanged(app, event)
            value = app.Manipspare2Button.Value;
            app.SpareAct2 = value ;             
            SetManipAct(app); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer) ; 
            delete(app.Timer) ; 
            delete(app)            
        end

        % Button pushed function: TestRS485ManpulatorButton
        function TestRS485ManpulatorButtonPushed(app, event)
            TestManCom485 ; 
        end

        % Button pushed function: TestHOSTcommunicationButton
        function TestHOSTcommunicationButtonPushed(app, event)
            TestHostCom ;             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RightWheelLabel = uilabel(app.UIFigure);
            app.RightWheelLabel.Text = 'Right Wheel';
            app.LeftWheelLabel = uilabel(app.UIFigure);
            app.LeftWheelLabel.Text = 'Left Wheel';
            app.NeckLabel = uilabel(app.UIFigure);
            app.NeckLabel.Text = 'Neck';
            app.PotentiometerEditFieldLabel = uilabel(app.UIFigure);
            app.PotentiometerEditFieldLabel.Text = 'Potentiometer';
            app.PotentiometerEditField = uieditfield(app.UIFigure, 'text');
            app.PotentiometerEditField.Tooltip = {'Range 0 to 1'};
            app.EncoderEditFieldLabel = uilabel(app.UIFigure);
            app.EncoderEditFieldLabel.Text = 'Encoder';
            app.EncoderEditField = uieditfield(app.UIFigure, 'text');
            app.EncoderEditField.Tooltip = {'About 4000 per count/rotation'};
            app.PotentiometerEditField_2Label = uilabel(app.UIFigure);
            app.PotentiometerEditField_2Label.Text = 'Potentiometer';
            app.PotentiometerEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EncoderEditField_2Label = uilabel(app.UIFigure);
            app.EncoderEditField_2Label.Text = 'Encoder';
            app.EncoderEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Potentiometer1EditFieldLabel = uilabel(app.UIFigure);
            app.Potentiometer1EditFieldLabel.Text = 'Potentiometer 1';
            app.Potentiometer1EditField = uieditfield(app.UIFigure, 'text');
            app.Potentiometer2EditFieldLabel = uilabel(app.UIFigure);
            app.Potentiometer2EditFieldLabel.Text = 'Potentiometer 2';
            app.Potentiometer2EditField = uieditfield(app.UIFigure, 'text');
            app.VoltagesLabel = uilabel(app.UIFigure);
            app.VoltagesLabel.Text = 'Voltages';
            app.GyroLabel = uilabel(app.UIFigure);
            app.GyroLabel.Text = 'Gyro';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'text');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'text');
            app.VacuumButton = uibutton(app.UIFigure, 'state');
            app.VacuumButton.ValueChangedFcn = createCallbackFcn(app, @VacuumButtonValueChanged, true);
            app.VacuumButton.Text = 'Vacuum';
            app.Maniplight1Button = uibutton(app.UIFigure, 'state');
            app.Maniplight1Button.ValueChangedFcn = createCallbackFcn(app, @Maniplight1ButtonValueChanged, true);
            app.Maniplight1Button.Text = 'Manip light 1';
            app.Maniplight2Button = uibutton(app.UIFigure, 'state');
            app.Maniplight2Button.ValueChangedFcn = createCallbackFcn(app, @Maniplight2ButtonValueChanged, true);
            app.Maniplight2Button.Text = 'Manip light 2';
            app.Manipspare1Button = uibutton(app.UIFigure, 'state');
            app.Manipspare1Button.ValueChangedFcn = createCallbackFcn(app, @Manipspare1ButtonValueChanged, true);
            app.Manipspare1Button.Text = 'Manip spare 1';
            app.Manipspare2Button = uibutton(app.UIFigure, 'state');
            app.Manipspare2Button.ValueChangedFcn = createCallbackFcn(app, @Manipspare2ButtonValueChanged, true);
            app.Manipspare2Button.Text = 'Manip spare 2';
            app.ManipulatorLabel = uilabel(app.UIFigure);
            app.ManipulatorLabel.Text = 'Manipulator ';
            app.ActuationsLabel = uilabel(app.UIFigure);
            app.ActuationsLabel.Text = 'Actuations';
            app.AnalogsLabel = uilabel(app.UIFigure);
            app.AnalogsLabel.Text = 'Analogs';
            app.LaserEditFieldLabel = uilabel(app.UIFigure);
            app.LaserEditFieldLabel.Text = 'Laser';
            app.LaserEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ain2EditFieldLabel = uilabel(app.UIFigure);
            app.Ain2EditFieldLabel.Text = 'Ain2';
            app.Ain2EditField = uieditfield(app.UIFigure, 'numeric');
            app.PLCDigitalinLabel = uilabel(app.UIFigure);
            app.PLCDigitalinLabel.Text = 'PLC Digital in';
            app.D1CheckBox = uicheckbox(app.UIFigure);
            app.D1CheckBox.Text = 'D1';
            app.D2CheckBox = uicheckbox(app.UIFigure);
            app.D2CheckBox.Text = 'D2';
            app.V5EditFieldLabel = uilabel(app.UIFigure);
            app.V5EditFieldLabel.Text = 'V5';
            app.V5EditField = uieditfield(app.UIFigure, 'numeric');
            app.V12EditFieldLabel = uilabel(app.UIFigure);
            app.V12EditFieldLabel.Text = 'V12';
            app.V12EditField = uieditfield(app.UIFigure, 'numeric');
            app.RightOverloadEditFieldLabel = uilabel(app.UIFigure);
            app.RightOverloadEditFieldLabel.Text = 'Right Overload';
            app.ROverloadEditFieldLabel = uilabel(app.UIFigure);
            app.ROverloadEditFieldLabel.Text = 'R Overload';
            app.LeftInductiveLabel = uilabel(app.UIFigure);
            app.LeftInductiveLabel.Text = 'Left Inductive';
            app.LeftOverloadLabel = uilabel(app.UIFigure);
            app.LeftOverloadLabel.Text = 'Left Overload';
            app.LeftOverloadEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightInductiveEditFieldLabel = uilabel(app.UIFigure);
            app.RightInductiveEditFieldLabel.Text = 'Right Inductive';
            app.RightInductiveEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightInductiveEditField.Tooltip = {'1.45 or 1.67 tolerance 0.05'};
            app.LeftInductiveEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightOverloadEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightOverloadEditField.Tooltip = {'1.45 to 1.67 tolerance 0.05'};
            app.ROverloadEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROverloadEditField.Tooltip = {'2.02 to 3, tolerance 0.05'};
            app.TestRS485ManpulatorButton = uibutton(app.UIFigure, 'push');
            app.TestRS485ManpulatorButton.ButtonPushedFcn = createCallbackFcn(app, @TestRS485ManpulatorButtonPushed, true);
            app.TestRS485ManpulatorButton.Text = 'Test RS485 - Manpulator';
            app.TestHOSTcommunicationButton = uibutton(app.UIFigure, 'push');
            app.TestHOSTcommunicationButton.ButtonPushedFcn = createCallbackFcn(app, @TestHOSTcommunicationButtonPushed, true);
            app.TestHOSTcommunicationButton.Text = 'Test HOST communication';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HardAtpLB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end