classdef AdditionalTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CurrentLimitsTab             matlab.ui.container.Tab
        Label_10                     matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        GoodLabel_2                  matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        GoodLabel                    matlab.ui.control.Label
        EditNeckPeak                 matlab.ui.control.NumericEditField
        EditLeftSteerPeak            matlab.ui.control.NumericEditField
        EditRightSteerPeak           matlab.ui.control.NumericEditField
        EditLeftWheelPeak            matlab.ui.control.NumericEditField
        EditWightWheelPeak           matlab.ui.control.NumericEditField
        PeakLabel                    matlab.ui.control.Label
        ContinuousLabel              matlab.ui.control.Label
        NeckEditField                matlab.ui.control.NumericEditField
        NeckEditFieldLabel           matlab.ui.control.Label
        LeftsteeringEditField        matlab.ui.control.NumericEditField
        LeftsteeringEditFieldLabel   matlab.ui.control.Label
        RightsteeringEditField       matlab.ui.control.NumericEditField
        RightsteeringEditFieldLabel  matlab.ui.control.Label
        LeftwheelEditField           matlab.ui.control.NumericEditField
        LeftwheelEditFieldLabel      matlab.ui.control.Label
        RightwheelEditField          matlab.ui.control.NumericEditField
        RightwheelEditFieldLabel     matlab.ui.control.Label
        Tab2                         matlab.ui.container.Tab
        Prog2flashButton             matlab.ui.control.Button
        Program2RAMButton            matlab.ui.control.Button
        WheelradicorrectionfactorEditField  matlab.ui.control.NumericEditField
        WheelradicorrectionfactorEditFieldLabel  matlab.ui.control.Label
        AdditionalCalibrationsLabel  matlab.ui.control.Label
        BraketestingPanel            matlab.ui.container.Panel
        ReleaseBothButton            matlab.ui.control.Button
        LockBothButton               matlab.ui.control.Button
        ReleaseLeftButton            matlab.ui.control.Button
        ReleaseRightButton           matlab.ui.control.Button
        ExceptionLabel               matlab.ui.control.Label
        InstructionsLabel            matlab.ui.control.Label
        AdditionaltestsLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        EvtObj % Description
        Timer  % Description
        Geom % Description
        Lock % Description
        Calib % Description
    end
    
    methods (Access = private)
        
        function update_warm_display(app,~,~ )
            global DataType 

            global TmMgrT
        
            try
            cnt = TmMgrT.GetCounter('TEST_ADDITIONAL') ; 
            
            if cnt < -0.5e-6 
                UIFigureCloseRequest(app, []); 
                %delete( hobj) ; 
            else
                TmMgrT.IncrementCounter('TEST_ADDITIONAL',2) ; 
            end     
                
            catch 
            end

            if app.Lock
                return ; 
            end
            app.Lock = 1 ; 

            try
                
            catch 
            end 
            app.Lock = 0 ; 
        end


        function TurnMotorOff(app)
            global DataType 

            vec  = 2.^[0:15] ; 
            
            mon = [0, 0 , 0 , 0 ,0, 1] ; 
            qs = [0,1] ; % [get( handles.CheckQuickBox,'Value'), 1] ;
            rsfail = [1 , 1] ; 
            reserved = [0,0,0,0];
            brr = [1,1 ] ; 
            value = sum( [ mon , reserved , brr,  rsfail , qs] .* vec ) ;  
            % SetMasterBlaster() ; 
            SendObj( [hex2dec('2220'),5] , hex2dec('1234') , DataType.short , 'Set Master Blaster rights' ) ;

            SendObj( [hex2dec('2220'),6] , value , DataType.short ,'Reset motors cmd') ;

            
        end
        
        function IsCorrectValue(~,val1,val2,control )
            if ( abs(val1-val2) < 0.15 ) 
                set(control,'BackgroundColor',[0 1 0]) 
            else
                set(control,'BackgroundColor',[1 0 0]) 
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DataType 
 
            app.Lock = 0 ; 
            global DispT
            if ~exist('DispT','var') || ~isa(DispT,'DlgTimerObj')
                evalin('base','AtpStart') ; 
            end

            app.EvtObj = DlgUserObj(@app.update_warm_display,findobj(app) );
            app.EvtObj.MylistenToTimer(DispT) ;   
            app.Calib = SaveCalib(-1) ; 
            app.WheelradicorrectionfactorEditField.Value = app.Calib.WheelRadiCorrection ;

            CurrentLimitsTabButtonDown(app) ; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global TmMgrT
            try
                app.Lock = 1 ; 
                TmMgrT.SetCounter('TEST_ADDITIONAL',-1000) ; 
                delete( app.EvtObj) ;
            %delete( app.Timer ) ;
            catch 
            end
            delete(app)
            
        end

        % Button pushed function: ReleaseRightButton
        function ReleaseRightButtonPushed(app, event)
            global DataType 
            TurnMotorOff(app); 
            BrakeLock = [0,1] ; 
            SendObj( [hex2dec('0x2220'),117] , BrakeLock(1) , DataType.long , 'CBIT 99' ) ;
            SendObj( [hex2dec('0x2220'),118] , BrakeLock(2) , DataType.long , 'CBIT 99' ) ;
            
        end

        % Button pushed function: ReleaseLeftButton
        function ReleaseLeftButtonPushed(app, event)
            DataType = GetDataType(); 
            TurnMotorOff(app);
            BrakeLock = [1,0] ; 
            SendObj( [hex2dec('0x2220'),117] , BrakeLock(1) , DataType.long , 'CBIT 99' ) ;
            SendObj( [hex2dec('0x2220'),118] , BrakeLock(2) , DataType.long , 'CBIT 99' ) ;


        end

        % Button pushed function: LockBothButton
        function LockBothButtonPushed(app, event)
                        global DataType 
            TurnMotorOff(app);
            BrakeLock = [1,1] ; 
            SendObj( [hex2dec('0x2220'),117] , BrakeLock(1) , DataType.long , 'CBIT 99' ) ;
            SendObj( [hex2dec('0x2220'),118] , BrakeLock(2) , DataType.long , 'CBIT 99' ) ;


        end

        % Button pushed function: ReleaseBothButton
        function ReleaseBothButtonPushed(app, event)
                        global DataType 
            TurnMotorOff(app);
            BrakeLock = [0,0] ; 
            SendObj( [hex2dec('0x2220'),117] , BrakeLock(1) , DataType.long , 'CBIT 99' ) ;
            SendObj( [hex2dec('0x2220'),118] , BrakeLock(2) , DataType.long , 'CBIT 99' ) ;
            

        end

        % Button pushed function: Program2RAMButton
        function Program2RAMButtonPushed(app, event)
            SaveCalib(app.Calib,'TemporaryCalib.jsn');
            ProgCalib('TemporaryCalib.jsn',2); 
        end

        % Value changed function: WheelradicorrectionfactorEditField
        function WheelradicorrectionfactorEditFieldValueChanged(app, event)
            value = app.WheelradicorrectionfactorEditField.Value;
            if ( (value + 1) > 1.08 || (value + 1 )< 0.92)
                  app.WheelradicorrectionfactorEditField.Value  = app.Calib.WheelRadiCorrection ;
            else
                app.Calib.WheelRadiCorrection = value ; 
            end
        end

        % Button pushed function: Prog2flashButton
        function Prog2flashButtonPushed(app, event)
             SaveCalib(app.Calib,'TemporaryCalib.jsn');
            ProgCalib('TemporaryCalib.jsn',1); 
           
        end

        % Button down function: CurrentLimitsTab
        function CurrentLimitsTabButtonDown(app, event)
            Rated = GetCurLimits()  ; 

            goodrated = [30 , 30 ,  5 , 5 ,12] ; 
            goodpeak  = [30 , 30 ,  5 , 5 ,12] ; 

            app.Label.Text  =  num2str(goodrated(1)) ; 
            app.Label_2.Text  = num2str(goodrated(2)) ; 
            app.Label_3.Text  = num2str(goodrated(3)) ; 
            app.Label_4.Text  = num2str(goodrated(4)) ; 
            app.Label_5.Text  = num2str(goodrated(5)) ; 

            app.Label_6.Text  = num2str(goodpeak(1)) ; 
            app.Label_7.Text  = num2str(goodpeak(2)) ; 
            app.Label_8.Text  = num2str(goodpeak(3)) ; 
            app.Label_9.Text  = num2str(goodpeak(4)) ; 
            app.Label_10.Text  = num2str(goodpeak(5)) ; 

            app.EditNeckPeak.Value = Rated.PeakRatio(5) ; 
            app.EditLeftSteerPeak.Value = Rated.PeakRatio(4) ; 
            app.EditRightSteerPeak.Value = Rated.PeakRatio(3) ; 
            app.EditLeftWheelPeak.Value = Rated.PeakRatio(2) ; 
            app.EditWightWheelPeak.Value = Rated.PeakRatio(1) ; 
            app.NeckEditField.Value = Rated.Cont(5) ; 
            app.LeftsteeringEditField.Value = Rated.Cont(4) ; 
            app.RightsteeringEditField.Value = Rated.Cont(3) ; 
            app.LeftwheelEditField.Value = Rated.Cont(2) ; 
            app.RightwheelEditField.Value = Rated.Cont(1) ; 

            IsCorrectValue(app,Rated.Cont(1) ,goodrated(1) , app.RightwheelEditField )  ;
            IsCorrectValue(app,Rated.Cont(2) ,goodrated(2) , app.LeftwheelEditField )  ;
            IsCorrectValue(app,Rated.Cont(3) ,goodrated(3) , app.RightsteeringEditField )  ;
            IsCorrectValue(app,Rated.Cont(4) ,goodrated(4) , app.LeftsteeringEditField )  ;
            IsCorrectValue(app,Rated.Cont(5) ,goodrated(5) , app.NeckEditField )  ;

            IsCorrectValue(app,Rated.PeakRatio(1) ,goodpeak(1) , app.EditWightWheelPeak )  ;
            IsCorrectValue(app,Rated.PeakRatio(2) ,goodpeak(2) , app.EditLeftWheelPeak )  ;
            IsCorrectValue(app,Rated.PeakRatio(3) ,goodpeak(3) , app.EditRightSteerPeak )  ;
            IsCorrectValue(app,Rated.PeakRatio(4) ,goodpeak(4) , app.EditLeftSteerPeak )  ;
            IsCorrectValue(app,Rated.PeakRatio(5) ,goodpeak(5) , app.EditNeckPeak )  ;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AdditionaltestsLabel = uilabel(app.UIFigure);
            app.AdditionaltestsLabel.Text = 'Additional tests';
            app.InstructionsLabel = uilabel(app.UIFigure);
            app.InstructionsLabel.Text = 'Instructions';
            app.ExceptionLabel = uilabel(app.UIFigure);
            app.ExceptionLabel.Text = 'Exception';
            app.BraketestingPanel = uipanel(app.UIFigure);
            app.BraketestingPanel.Title = 'Brake testing';
            app.ReleaseRightButton = uibutton(app.BraketestingPanel, 'push');
            app.ReleaseRightButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseRightButtonPushed, true);
            app.ReleaseRightButton.Text = 'Release Right';
            app.ReleaseLeftButton = uibutton(app.BraketestingPanel, 'push');
            app.ReleaseLeftButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseLeftButtonPushed, true);
            app.ReleaseLeftButton.Text = 'Release Left';
            app.LockBothButton = uibutton(app.BraketestingPanel, 'push');
            app.LockBothButton.ButtonPushedFcn = createCallbackFcn(app, @LockBothButtonPushed, true);
            app.LockBothButton.Text = ':Lock Both';
            app.ReleaseBothButton = uibutton(app.BraketestingPanel, 'push');
            app.ReleaseBothButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseBothButtonPushed, true);
            app.ReleaseBothButton.Text = 'Release Both';
            app.AdditionalCalibrationsLabel = uilabel(app.UIFigure);
            app.AdditionalCalibrationsLabel.Text = 'Additional Calibrations';
            app.WheelradicorrectionfactorEditFieldLabel = uilabel(app.UIFigure);
            app.WheelradicorrectionfactorEditFieldLabel.Text = 'Wheel radi  correction factor';
            app.WheelradicorrectionfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.WheelradicorrectionfactorEditField.ValueDisplayFormat = '%1.4f';
            app.WheelradicorrectionfactorEditField.ValueChangedFcn = createCallbackFcn(app, @WheelradicorrectionfactorEditFieldValueChanged, true);
            app.Program2RAMButton = uibutton(app.UIFigure, 'push');
            app.Program2RAMButton.ButtonPushedFcn = createCallbackFcn(app, @Program2RAMButtonPushed, true);
            app.Program2RAMButton.Text = 'Program 2 RAM';
            app.Prog2flashButton = uibutton(app.UIFigure, 'push');
            app.Prog2flashButton.ButtonPushedFcn = createCallbackFcn(app, @Prog2flashButtonPushed, true);
            app.Prog2flashButton.Text = 'Prog 2 flash';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CurrentLimitsTab = uitab(app.TabGroup);
            app.CurrentLimitsTab.Title = 'CurrentLimits';
            app.CurrentLimitsTab.ButtonDownFcn = createCallbackFcn(app, @CurrentLimitsTabButtonDown, true);
            app.RightwheelEditFieldLabel = uilabel(app.CurrentLimitsTab);
            app.RightwheelEditFieldLabel.Text = 'Right wheel';
            app.RightwheelEditField = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.RightwheelEditField.ValueDisplayFormat = '%.1f';
            app.LeftwheelEditFieldLabel = uilabel(app.CurrentLimitsTab);
            app.LeftwheelEditFieldLabel.Text = 'Left wheel';
            app.LeftwheelEditField = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.LeftwheelEditField.ValueDisplayFormat = '%.1f';
            app.RightsteeringEditFieldLabel = uilabel(app.CurrentLimitsTab);
            app.RightsteeringEditFieldLabel.Text = 'Right steering';
            app.RightsteeringEditField = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.RightsteeringEditField.ValueDisplayFormat = '%.1f';
            app.LeftsteeringEditFieldLabel = uilabel(app.CurrentLimitsTab);
            app.LeftsteeringEditFieldLabel.Text = 'Left steering';
            app.LeftsteeringEditField = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.LeftsteeringEditField.ValueDisplayFormat = '%.1f';
            app.NeckEditFieldLabel = uilabel(app.CurrentLimitsTab);
            app.NeckEditFieldLabel.Text = 'Neck';
            app.NeckEditField = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.NeckEditField.ValueDisplayFormat = '%.1f';
            app.ContinuousLabel = uilabel(app.CurrentLimitsTab);
            app.ContinuousLabel.Text = 'Continuous';
            app.PeakLabel = uilabel(app.CurrentLimitsTab);
            app.PeakLabel.Text = 'Peak';
            app.EditWightWheelPeak = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.EditWightWheelPeak.ValueDisplayFormat = '%.1f';
            app.EditLeftWheelPeak = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.EditLeftWheelPeak.ValueDisplayFormat = '%.1f';
            app.EditRightSteerPeak = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.EditRightSteerPeak.ValueDisplayFormat = '%.1f';
            app.EditLeftSteerPeak = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.EditLeftSteerPeak.ValueDisplayFormat = '%.1f';
            app.EditNeckPeak = uieditfield(app.CurrentLimitsTab, 'numeric');
            app.EditNeckPeak.ValueDisplayFormat = '%.1f';
            app.GoodLabel = uilabel(app.CurrentLimitsTab);
            app.GoodLabel.Text = 'Good';
            app.Label = uilabel(app.CurrentLimitsTab);
            app.Label.Text = '15';
            app.Label_2 = uilabel(app.CurrentLimitsTab);
            app.Label_2.Text = '15';
            app.Label_3 = uilabel(app.CurrentLimitsTab);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.CurrentLimitsTab);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.CurrentLimitsTab);
            app.Label_5.Text = '4.5';
            app.GoodLabel_2 = uilabel(app.CurrentLimitsTab);
            app.GoodLabel_2.Text = 'Good';
            app.Label_6 = uilabel(app.CurrentLimitsTab);
            app.Label_6.Text = '20';
            app.Label_7 = uilabel(app.CurrentLimitsTab);
            app.Label_7.Text = '20';
            app.Label_8 = uilabel(app.CurrentLimitsTab);
            app.Label_8.Text = '3.5';
            app.Label_9 = uilabel(app.CurrentLimitsTab);
            app.Label_9.Text = '3.5';
            app.Label_10 = uilabel(app.CurrentLimitsTab);
            app.Label_10.Text = '13.5';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdditionalTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end