classdef Brakes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TARMLamp                      matlab.ui.control.Lamp
        RotLamp                       matlab.ui.control.Lamp
        NKLamp                        matlab.ui.control.Lamp
        LWLamp                        matlab.ui.control.Lamp
        RWLamp                        matlab.ui.control.Lamp
        ReleaseTapeArmButton          matlab.ui.control.Button
        ReleaseRotatorButton          matlab.ui.control.Button
        ReleaseNeckButton             matlab.ui.control.Button
        ReleaseLeftWheelButton        matlab.ui.control.Button
        ReleaseRightWheelButton       matlab.ui.control.Button
        ExceptionLabel                matlab.ui.control.Label
        DebugPanel                    matlab.ui.container.Panel
        toresumenormaloperationLabel  matlab.ui.control.Label
        AfteryougotomanualbrakemodeyoumustresettherobotLabel  matlab.ui.control.Label
        DebugmodeCheckBox             matlab.ui.control.CheckBox
        ManualBrakeModeButton         matlab.ui.control.StateButton
        BrakeDialogLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        EvtObj % Description
        Timer  % Description
        Lock % Description
        DataType % Description
        BrakeCmd % Description
    end
    
    methods (Access = private)
        
        function update_brake_display(app,~,~ )

            global TmMgrT %#ok<GVMIS> 
        
            try
            cnt = TmMgrT.GetCounter('BRAKES') ; 
            
            if cnt < -0.5e-6 
                UIFigureCloseRequest(app, []); 
                %delete( hobj) ; 
            else
                TmMgrT.IncrementCounter('BRAKES',2) ; 
            end     
                
            catch 
            end

            if app.Lock
                return ; 
            end
            try

                value = FetchObj( [hex2dec('2220'),121], app.DataType.long , 'Read manual brake mode' ) ;
                app.BrakeCmd = value ;
                app.DebugmodeCheckBox.Value = logical( bitand(value,1) ) ; 
                if logical( bitand(value,2) )
                    app.RWLamp.Color = [0,1,0];
                else
                    app.RWLamp.Color = [0,0,1];
                end
                if logical( bitand(value,4) )
                    app.LWLamp.Color = [0,1,0];
                else
                    app.LWLamp.Color = [0,0,1];
                end
                if logical( bitand(value,8) )
                    app.NKLamp.Color = [0,1,0];
                else
                    app.NKLamp.Color = [0,0,1];
                end
                if logical( bitand(value,16) )
                    app.RotLamp.Color = [0,1,0];
                else
                    app.RotLamp.Color = [0,0,1];
                end
                if logical( bitand(value,32 ))
                    app.TARMLamp.Color = [0,1,0];
                else
                    app.TARMLamp.Color = [0,0,1];
                end
            catch 
            end 
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DispT %#ok<GVMIS> 

            app.DataType = GetDataType() ; 
            try 
            dbgstate = FetchObj( [hex2dec('2222'),24] , app.DataType.long , 'SysState.Debug') ; % Left 
            dbgwa  = bitand(dbgstate,1)  ; 
            app.DebugmodeCheckBox.Value = dbgwa ; 
            app.ManualBrakeModeButton.Value= dbgwa ;
            catch
            end
            
            if ~exist('DispT','var') || ~isa(DispT,'DlgTimerObj')
                evalin('base','AtpStart') ; 
            end 
            app.EvtObj = DlgUserObj(@app.update_brake_display,findobj(app) );
            app.EvtObj.MylistenToTimer(DispT) ;   

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global TmMgrT %#ok<GVMIS> 
            try
                app.Lock = 1 ; 
                TmMgrT.SetCounter('BRAKES',-1000) ; 
                delete( app.EvtObj) ;
            %delete( app.Timer ) ;
            catch 
            end
            delete(app)
            
        end

        % Value changed function: ManualBrakeModeButton
        function ManualBrakeModeButtonValueChanged(app, event)
            app.Lock = 1 ; 
            
            % Change debug start
            if ~app.DebugmodeCheckBox.Value 
                SendObj( [hex2dec('2220'),121] , 1234 , app.DataType.long , 'Enter debug mode' ) ;
            else
                SendObj( [hex2dec('2220'),121] , 0 , app.DataType.long , 'Enter debug mode' ) ;
            end
           app.Lock = 0 ; 
            
        end

        % Button pushed function: ReleaseRightWheelButton
        function ReleaseRightWheelButtonPushed(app, event)
            
            if ( bitand(app.BrakeCmd,2) ) 
                SendObj( [hex2dec('2220'),122] , 0 , app.DataType.long , 'Release RW brake' ) ;
            else
                SendObj( [hex2dec('2220'),122] , 1 , app.DataType.long , 'Release RW brake' ) ;
            end
        end

        % Button pushed function: ReleaseLeftWheelButton
        function ReleaseLeftWheelButtonPushed(app, event)
            if ( bitand(app.BrakeCmd,4) ) 
                SendObj( [hex2dec('2220'),123] , 0 , app.DataType.long , 'Release RW brake' ) ;
            else
                SendObj( [hex2dec('2220'),123] , 1 , app.DataType.long , 'Release RW brake' ) ;
            end
            
        end

        % Button pushed function: ReleaseNeckButton
        function ReleaseNeckButtonPushed(app, event)
              
            if ( bitand(app.BrakeCmd,8) ) 
                SendObj( [hex2dec('2220'),124] , 0 , app.DataType.long , 'Release  brake' ) ;
            else
                SendObj( [hex2dec('2220'),124] , 1 , app.DataType.long , 'Release  brake' ) ;
            end
        end

        % Button pushed function: ReleaseRotatorButton
        function ReleaseRotatorButtonPushed(app, event)
            if ( bitand(app.BrakeCmd,16) ) 
                SendObj( [hex2dec('2220'),125] , 0 , app.DataType.long , 'Release  brake' ) ;
            else
                SendObj( [hex2dec('2220'),125] , 1 , app.DataType.long , 'Release  brake' ) ;
            end
            
        end

        % Button pushed function: ReleaseTapeArmButton
        function ReleaseTapeArmButtonPushed(app, event)
            if ( bitand(app.BrakeCmd,32) ) 
                SendObj( [hex2dec('2220'),126] , 0 , app.DataType.long , 'Release  brake' ) ;
            else
                SendObj( [hex2dec('2220'),126] , 1 , app.DataType.long , 'Release  brake' ) ;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BrakeDialogLabel = uilabel(app.UIFigure);
            app.BrakeDialogLabel.Text = 'Brake Dialog';
            app.DebugPanel = uipanel(app.UIFigure);
            app.DebugPanel.Title = 'Debug';
            app.ManualBrakeModeButton = uibutton(app.DebugPanel, 'state');
            app.ManualBrakeModeButton.ValueChangedFcn = createCallbackFcn(app, @ManualBrakeModeButtonValueChanged, true);
            app.ManualBrakeModeButton.Text = 'Manual Brake Mode';
            app.DebugmodeCheckBox = uicheckbox(app.DebugPanel);
            app.DebugmodeCheckBox.Enable = 'off';
            app.DebugmodeCheckBox.Text = 'Debug mode';
            app.AfteryougotomanualbrakemodeyoumustresettherobotLabel = uilabel(app.DebugPanel);
            app.AfteryougotomanualbrakemodeyoumustresettherobotLabel.Text = 'After you go to manual brake mode, you must reset the robot ';
            app.toresumenormaloperationLabel = uilabel(app.DebugPanel);
            app.toresumenormaloperationLabel.Text = 'to resume normal operation';
            app.ExceptionLabel = uilabel(app.UIFigure);
            app.ExceptionLabel.Text = 'Exception';
            app.ReleaseRightWheelButton = uibutton(app.UIFigure, 'push');
            app.ReleaseRightWheelButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseRightWheelButtonPushed, true);
            app.ReleaseRightWheelButton.Text = 'Release  Right Wheel';
            app.ReleaseLeftWheelButton = uibutton(app.UIFigure, 'push');
            app.ReleaseLeftWheelButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseLeftWheelButtonPushed, true);
            app.ReleaseLeftWheelButton.Text = 'Release  Left Wheel';
            app.ReleaseNeckButton = uibutton(app.UIFigure, 'push');
            app.ReleaseNeckButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseNeckButtonPushed, true);
            app.ReleaseNeckButton.Text = 'Release Neck';
            app.ReleaseRotatorButton = uibutton(app.UIFigure, 'push');
            app.ReleaseRotatorButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseRotatorButtonPushed, true);
            app.ReleaseRotatorButton.Text = 'Release Rotator';
            app.ReleaseTapeArmButton = uibutton(app.UIFigure, 'push');
            app.ReleaseTapeArmButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseTapeArmButtonPushed, true);
            app.ReleaseTapeArmButton.Text = 'Release Tape Arm';
            app.RWLamp = uilamp(app.UIFigure);
            app.LWLamp = uilamp(app.UIFigure);
            app.NKLamp = uilamp(app.UIFigure);
            app.RotLamp = uilamp(app.UIFigure);
            app.TARMLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Brakes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end