classdef CheatManip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Title                          matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        AllowtravelwoutmanipulatorCheck  matlab.ui.control.CheckBox
        AllowautowithmanipposunknownCheck  matlab.ui.control.CheckBox
        IndividualaxiscommandCheckBox  matlab.ui.control.CheckBox
        Title_2                        matlab.ui.control.Label
        ManipulatortypeEditFieldLabel  matlab.ui.control.Label
        ManipulatortypeEditField       matlab.ui.control.EditField
        GoDoorButton                   matlab.ui.control.Button
        GoPlateButton                  matlab.ui.control.Button
        GoFlexButton                   matlab.ui.control.Button
        DoortargetEditFieldLabel       matlab.ui.control.Label
        DoortargetEditField            matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        FlextargetEditFieldLabel       matlab.ui.control.Label
        FlextargetEditField            matlab.ui.control.NumericEditField
        AbsoluteCheckBox               matlab.ui.control.CheckBox
        AbsoluteCheckBox_2             matlab.ui.control.CheckBox
        AbsoluteCheckBox_3             matlab.ui.control.CheckBox
        HomevalueEditFieldLabel        matlab.ui.control.Label
        HomevalueEditField             matlab.ui.control.NumericEditField
        HomevalueEditField_2Label      matlab.ui.control.Label
        HomevalueEditField_2           matlab.ui.control.NumericEditField
        HomevalueLabel                 matlab.ui.control.Label
        HomevalueEditField_3           matlab.ui.control.NumericEditField
        GetpresentButton               matlab.ui.control.Button
        SethomeButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        MemoryTimer        % Timer object
        h % Comm andle
    end

    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            
       end        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DataType ; 
            % Configure x- and y- axis
            
            % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            
            prs = FetchObj( [hex2dec('2220'),19] , DataType.long ,'Force permission') ;
            if bitand( prs, 1) 
                app.AllowtravelwoutmanipulatorCheck.Value = 1 ;
            else
                app.AllowtravelwoutmanipulatorCheck.Value = 0 ;
            end 
            if bitand( prs, 1) 
                app.AllowautowithmanipposunknownCheck.Value = 1 ;
            else
                app.AllowautowithmanipposunknownCheck.Value = 0 ;
            end 
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.MemoryTimer.Running, 'off')
               start(app.MemoryTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop the timer
            stop(app.MemoryTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.MemoryTimer);
            delete(app.MemoryTimer);
            delete(app);
        end

        % Value changed function: AllowtravelwoutmanipulatorCheck
        function AllowtravelwoutmanipulatorCheckValueChanged(app, event)
            global DataType ; 
            value = app.AllowtravelwoutmanipulatorCheck.Value;
            prs = FetchObj( [hex2dec('2220'),19] , DataType.long ,'Force permission') ;
            if value 
                prs = bitor(prs,1) ; 
            else
                prs = bitand(prs,hex2dec('fffe')) ; 
            end
            SendObj( [hex2dec('2220'),19] ,  prs , DataType.short ,'Allow travel with no manipulator') ;
        end

        % Value changed function: AllowautowithmanipposunknownCheck
        function AllowautowithmanipposunknownCheckValueChanged(app, event)
            global DataType ; 
            value = app.AllowautowithmanipposunknownCheck.Value;
            prs = FetchObj( [hex2dec('2220'),19] , DataType.long ,'Force permission') ;
            if value 
                prs = bitor(prs,1) ; 
            else
                prs = bitand(prs,hex2dec('fffe')) ; 
            end
            SendObj( [hex2dec('2220'),19] ,  prs , DataType.short ,'Allow travel with no manipulator') ;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Memory Usage';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'CHEATS for manipulator of ITAMAR';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AllowtravelwoutmanipulatorCheck = uicheckbox(app.UIFigure);
            app.AllowtravelwoutmanipulatorCheck.ValueChangedFcn = createCallbackFcn(app, @AllowtravelwoutmanipulatorCheckValueChanged, true);
            app.AllowtravelwoutmanipulatorCheck.Text = 'Allow auto travel without manipulator';
            app.AllowautowithmanipposunknownCheck = uicheckbox(app.UIFigure);
            app.AllowautowithmanipposunknownCheck.ValueChangedFcn = createCallbackFcn(app, @AllowautowithmanipposunknownCheckValueChanged, true);
            app.AllowautowithmanipposunknownCheck.Text = 'Allow auto travel with manipulator servo problem or position unknown ';
            app.IndividualaxiscommandCheckBox = uicheckbox(app.UIFigure);
            app.IndividualaxiscommandCheckBox.Text = 'Individual axis command';
            app.Title_2 = uilabel(app.UIFigure);
            app.Title_2.Text = 'CHEATS for flexible manipulator';
            app.ManipulatortypeEditFieldLabel = uilabel(app.UIFigure);
            app.ManipulatortypeEditFieldLabel.Text = 'Manipulator type';
            app.ManipulatortypeEditField = uieditfield(app.UIFigure, 'text');
            app.GoDoorButton = uibutton(app.UIFigure, 'push');
            app.GoDoorButton.Text = 'Go Door';
            app.GoPlateButton = uibutton(app.UIFigure, 'push');
            app.GoPlateButton.Text = 'Go Plate';
            app.GoFlexButton = uibutton(app.UIFigure, 'push');
            app.GoFlexButton.Text = 'Go Flex';
            app.DoortargetEditFieldLabel = uilabel(app.UIFigure);
            app.DoortargetEditFieldLabel.Text = 'Door target';
            app.DoortargetEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.FlextargetEditFieldLabel = uilabel(app.UIFigure);
            app.FlextargetEditFieldLabel.Text = 'Flex target';
            app.FlextargetEditField = uieditfield(app.UIFigure, 'numeric');
            app.AbsoluteCheckBox = uicheckbox(app.UIFigure);
            app.AbsoluteCheckBox.Text = 'Absolute';
            app.AbsoluteCheckBox_2 = uicheckbox(app.UIFigure);
            app.AbsoluteCheckBox_2.Text = 'Absolute';
            app.AbsoluteCheckBox_3 = uicheckbox(app.UIFigure);
            app.AbsoluteCheckBox_3.Text = 'Absolute';
            app.HomevalueEditFieldLabel = uilabel(app.UIFigure);
            app.HomevalueEditFieldLabel.Text = 'Home value';
            app.HomevalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.HomevalueEditField_2Label = uilabel(app.UIFigure);
            app.HomevalueEditField_2Label.Text = 'Home value';
            app.HomevalueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HomevalueLabel = uilabel(app.UIFigure);
            app.HomevalueLabel.Text = 'Home value';
            app.HomevalueEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.GetpresentButton = uibutton(app.UIFigure, 'push');
            app.GetpresentButton.Text = 'Get present';
            app.SethomeButton = uibutton(app.UIFigure, 'push');
            app.SethomeButton.Text = 'Set home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheatManip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end