classdef Cheats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Title                        matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        AllowtravelwoutmanipulatorCheck  matlab.ui.control.CheckBox
        AllowautowithmanipposunknownCheck  matlab.ui.control.CheckBox
        SimulatedrunCheckBox         matlab.ui.control.CheckBox
        AllowautowithmanipposunknownCheck_2  matlab.ui.control.CheckBox
        AllowautowithmanipposunknownCheck_3  matlab.ui.control.CheckBox
        WorkwithdefectivepotentiometerPanel  matlab.ui.container.Panel
        RightsteerdegEditFieldLabel  matlab.ui.control.Label
        RightsteerdegEditField       matlab.ui.control.NumericEditField
        LeftsteerdegEditFieldLabel   matlab.ui.control.Label
        LeftsteerdegEditField        matlab.ui.control.NumericEditField
        NeckdegEditFieldLabel        matlab.ui.control.Label
        NeckdegEditField             matlab.ui.control.NumericEditField
        LoadfromrobotButton          matlab.ui.control.Button
        SettorobotignorepotentiometersButton  matlab.ui.control.Button
        SimRSteerCheckBox            matlab.ui.control.CheckBox
        SimLSteerCheckBox            matlab.ui.control.CheckBox
        SimNeckCheckBox              matlab.ui.control.CheckBox
        AllowwakeupwithoutmanipulatorCheckBox  matlab.ui.control.CheckBox
        IgnoreWheelArm               matlab.ui.control.CheckBox
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end


    properties (Access = private)
        MemoryTimer        % Timer object
        h % Comm andle
        Rsteer % Description
        Lsteer % Description
        NeckAngle % Description
    end

    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            
       end        
        
        
        function UploadSteer(app)
            global DataType; 
            app.Rsteer = -FetchObj( [hex2dec('2204'),30] , DataType.float ,'Right steer angle') ;
            app.Lsteer = -FetchObj( [hex2dec('2204'),31] , DataType.float ,'Left steer angle') ;
            app.NeckAngle = FetchObj( [hex2dec('2204'),32] , DataType.float ,'NeckAngle') ;
            fac = 180/pi ; 
            app.RightsteerdegEditField.Value = app.Rsteer * fac ; 
            app.LeftsteerdegEditField.Value = app.Lsteer * fac ; 
            app.NeckdegEditField.Value = app.NeckAngle * fac ; 
        end
        
        function DloadSteer(app)
            global DataType; 
            SetMotors(app,0) ; 
            SendObj( [hex2dec('2204'),30],-app.Rsteer , DataType.float ,'Right steer angle') ;
            SendObj( [hex2dec('2204'),31],-app.Lsteer , DataType.float ,'Left steer angle') ; 
            SendObj( [hex2dec('2204'),32],app.NeckAngle , DataType.float ,'NeckAngle') 
            SetMotors(app,1) ; 
        end
        
        function SetMotors(~,mon)
            global DataType; 
            mon1 = mon ; mon2 = mon ; mon3 = mon ; mon4 = mon ; mon5 = mon  ; 
            mon = [mon1, mon2 ,mon3 ,mon4 ,mon5, 1] ; 
            qs = [0,1] ; % [get( handles.CheckQuickBox,'Value'), 1] ;
            rsfail = [1 , 1] ; 
            reserved = [0,0,0,0];
            brr = [0,1] ; 
            vec = 2.^(0:15) ; 
            value = sum( [ mon , reserved , brr,  rsfail , qs] .* vec ) ;  
            %SetMasterBlaster() ; 
            SendObj( [hex2dec('2220'),5] , hex2dec('1234') , DataType.short , 'Set Master Blaster' ) ;
            SendObj( [hex2dec('2220'),6] , value , DataType.short ,'Reset motors cmd') ;
                        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DataType ; 
            % Configure x- and y- axis
            
            % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            
            prs = FetchObj( [hex2dec('2220'),19] , DataType.long ,'Force permission') ;
            if bitand( prs, 1) 
                app.AllowtravelwoutmanipulatorCheck.Value = 1 ;
            else
                app.AllowtravelwoutmanipulatorCheck.Value = 0 ;
            end 
            if bitand( prs, 1) 
                app.AllowautowithmanipposunknownCheck.Value = 1 ;
            else
                app.AllowautowithmanipposunknownCheck.Value = 0 ;
            end 
            
            
            try 
                UploadSteer(app) ; 
            catch
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.MemoryTimer.Running, 'off')
               start(app.MemoryTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop the timer
            stop(app.MemoryTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.MemoryTimer);
            delete(app.MemoryTimer);
            delete(app);
        end

        % Value changed function: AllowtravelwoutmanipulatorCheck
        function AllowtravelwoutmanipulatorCheckValueChanged(app, event)
            global DataType ; 
            value = app.AllowtravelwoutmanipulatorCheck.Value;
            prs = FetchObj( [hex2dec('2220'),19] , DataType.long ,'Force permission') ;
            if value 
                prs = bitor(prs,1) ; 
            else
                prs = bitand(prs,hex2dec('fffe')) ; 
            end
            SendObj( [hex2dec('2220'),19] ,  prs , DataType.short ,'Allow travel with no manipulator') ;
        end

        % Value changed function: AllowautowithmanipposunknownCheck
        function AllowautowithmanipposunknownCheckValueChanged(app, event)
            global DataType ; 
            value = app.AllowautowithmanipposunknownCheck.Value;
            prs = FetchObj( [hex2dec('2220'),19] , DataType.long ,'Force permission') ;
            if value 
                prs = bitor(prs,1) ; 
            else
                prs = bitand(prs,hex2dec('fffe')) ; 
            end
            SendObj( [hex2dec('2220'),19] ,  prs , DataType.short ,'Allow travel with no manipulator') ;
            
        end

        % Value changed function: SimulatedrunCheckBox
        function SimulatedrunCheckBoxValueChanged(app, event)
            value = app.SimulatedrunCheckBox.Value;
            global DataType ; 
            if ( value)
                SendObj( [hex2dec('2222'),11] ,  0 , DataType.long ,'Kill simulated encoder') ;
                SendObj( [hex2dec('2222'),12] ,  1 , DataType.long ,'Start simulated encoder') ;
            else
                SendObj( [hex2dec('2222'),12] ,  0 , DataType.long ,'End mode of simulated encoder') ;
            end
        end

        % Value changed function: 
        % AllowautowithmanipposunknownCheck_2
        function AllowautowithmanipposunknownCheck_2ValueChanged(app, event)
            global DataType ; 
            value = app.AllowautowithmanipposunknownCheck_2.Value;
            
            SendObj( [hex2dec('2222'),14] ,  double(value) , DataType.long ,'Allow work with undetermined shoulder state') ;
            
            
        end

        % Value changed function: 
        % AllowautowithmanipposunknownCheck_3
        function AllowautowithmanipposunknownCheck_3ValueChanged(app, event)
            global DataType ; 
            value = app.AllowautowithmanipposunknownCheck_3.Value;
            if value 
                SendObj( [hex2dec('2222'),15] ,  1 , DataType.long ,'Reset motion fault') ;
            end
            app.AllowautowithmanipposunknownCheck_3.Value = 0 ; 
        end

        % Button pushed function: LoadfromrobotButton
        function LoadfromrobotButtonPushed(app, event)
             UploadSteer(app) ; 
        end

        % Button pushed function: 
        % SettorobotignorepotentiometersButton
        function SettorobotignorepotentiometersButtonPushed(app, event)
            fac = 180/pi ; 
            
            
            app.Rsteer =  max(min(app.RightsteerdegEditField.Value / fac, 1.99) ,-1.99) ; 
            app.Lsteer = max(min(app.LeftsteerdegEditField.Value / fac , 1.99) ,-1.99); 
            app.NeckAngle = max(min(app.NeckdegEditField.Value /  fac , 1.99) ,-1.99); 


            DloadSteer( app) ; 
        end

        % Value changed function: 
        % AllowwakeupwithoutmanipulatorCheckBox
        function AllowwakeupwithoutmanipulatorCheckBoxValueChanged(app, event)
            global DataType 
            value = app.AllowwakeupwithoutmanipulatorCheckBox.Value;
            SendObj( [hex2dec('2222'),14] , double(value) , DataType.long , 'Cheat wakeup without manipulator' ) ;
        end

        % Value changed function: SimNeckCheckBox
        function SimNeckCheckBoxValueChanged(app, event)
            global DataType 
            value = app.SimNeckCheckBox.Value;
            if value
                axval = app.NeckdegEditField.Value ; 
                SendObj( [hex2dec('2204'),35] , axval , DataType.float , 'Cheat set neck position ' ) ;
            else
                SendObj( [hex2dec('2204'),38] , 0 , DataType.long , 'Cheat unset rsteer position ' ) ;
            end 
        end

        % Value changed function: SimLSteerCheckBox
        function SimLSteerCheckBoxValueChanged(app, event)
            global DataType 
            value = app.SimLSteerCheckBox.Value;
            if value
                axval = app.LeftsteerdegEditField.Value ; 
                SendObj( [hex2dec('2204'),34] , axval , DataType.float , 'Cheat set lsteer position ' ) ;
            else
                SendObj( [hex2dec('2204'),37] , 0 , DataType.long , 'Cheat unset rsteer position ' ) ;
            end 
            
        end

        % Value changed function: SimRSteerCheckBox
        function SimRSteerCheckBoxValueChanged(app, event)
            global DataType 
            value = app.SimRSteerCheckBox.Value;
            if value
                axval = app.RightsteerdegEditField.Value ; 
                SendObj( [hex2dec('2204'),33] , axval , DataType.float , 'Cheat set rsteer position ' ) ;
            else
                SendObj( [hex2dec('2204'),36] , 0 , DataType.long , 'Cheat unset rsteer position ' ) ;
            end 
            
        end

        % Value changed function: IgnoreWheelArm
        function IgnoreWheelArmValueChanged(app, event)
            global DataType 
            value = app.IgnoreWheelArm.Value ;
            SendObj( [hex2dec('2204'),39] , double(value) , DataType.long , 'Cheat set wheelarm type ' ) ;
            
        end

        % Value changed function: RightsteerdegEditField
        function RightsteerdegEditFieldValueChanged(app, event)
            value = app.RightsteerdegEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Memory Usage';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'CHEATS for Nimrod';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AllowtravelwoutmanipulatorCheck = uicheckbox(app.UIFigure);
            app.AllowtravelwoutmanipulatorCheck.ValueChangedFcn = createCallbackFcn(app, @AllowtravelwoutmanipulatorCheckValueChanged, true);
            app.AllowtravelwoutmanipulatorCheck.Text = 'Allow auto travel without manipulator';
            app.AllowautowithmanipposunknownCheck = uicheckbox(app.UIFigure);
            app.AllowautowithmanipposunknownCheck.ValueChangedFcn = createCallbackFcn(app, @AllowautowithmanipposunknownCheckValueChanged, true);
            app.AllowautowithmanipposunknownCheck.Text = 'Allow auto travel with manipulator servo problem or position unknown ';
            app.SimulatedrunCheckBox = uicheckbox(app.UIFigure);
            app.SimulatedrunCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulatedrunCheckBoxValueChanged, true);
            app.SimulatedrunCheckBox.Text = 'Simulated run ';
            app.AllowautowithmanipposunknownCheck_2 = uicheckbox(app.UIFigure);
            app.AllowautowithmanipposunknownCheck_2.ValueChangedFcn = createCallbackFcn(app, @AllowautowithmanipposunknownCheck_2ValueChanged, true);
            app.AllowautowithmanipposunknownCheck_2.Text = 'Allow motion with undetermined shoulder width ';
            app.AllowautowithmanipposunknownCheck_3 = uicheckbox(app.UIFigure);
            app.AllowautowithmanipposunknownCheck_3.ValueChangedFcn = createCallbackFcn(app, @AllowautowithmanipposunknownCheck_3ValueChanged, true);
            app.AllowautowithmanipposunknownCheck_3.Text = 'Reset motion fault';
            app.WorkwithdefectivepotentiometerPanel = uipanel(app.UIFigure);
            app.WorkwithdefectivepotentiometerPanel.Title = 'Work with defective potentiometer';
            app.RightsteerdegEditFieldLabel = uilabel(app.WorkwithdefectivepotentiometerPanel);
            app.RightsteerdegEditFieldLabel.Text = 'Right steer (deg)';
            app.RightsteerdegEditField = uieditfield(app.WorkwithdefectivepotentiometerPanel, 'numeric');
            app.RightsteerdegEditField.ValueDisplayFormat = '%8.3g';
            app.RightsteerdegEditField.ValueChangedFcn = createCallbackFcn(app, @RightsteerdegEditFieldValueChanged, true);
            app.LeftsteerdegEditFieldLabel = uilabel(app.WorkwithdefectivepotentiometerPanel);
            app.LeftsteerdegEditFieldLabel.Text = 'Left steer (deg)';
            app.LeftsteerdegEditField = uieditfield(app.WorkwithdefectivepotentiometerPanel, 'numeric');
            app.LeftsteerdegEditField.ValueDisplayFormat = '%8.3g';
            app.NeckdegEditFieldLabel = uilabel(app.WorkwithdefectivepotentiometerPanel);
            app.NeckdegEditFieldLabel.Text = 'Neck (deg)';
            app.NeckdegEditField = uieditfield(app.WorkwithdefectivepotentiometerPanel, 'numeric');
            app.NeckdegEditField.ValueDisplayFormat = '%8.3g';
            app.LoadfromrobotButton = uibutton(app.WorkwithdefectivepotentiometerPanel, 'push');
            app.LoadfromrobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromrobotButtonPushed, true);
            app.LoadfromrobotButton.Text = 'Load from robot';
            app.SettorobotignorepotentiometersButton = uibutton(app.WorkwithdefectivepotentiometerPanel, 'push');
            app.SettorobotignorepotentiometersButton.ButtonPushedFcn = createCallbackFcn(app, @SettorobotignorepotentiometersButtonPushed, true);
            app.SettorobotignorepotentiometersButton.Text = 'Set to robot & ignore potentiometers';
            app.SimRSteerCheckBox = uicheckbox(app.WorkwithdefectivepotentiometerPanel);
            app.SimRSteerCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimRSteerCheckBoxValueChanged, true);
            app.SimRSteerCheckBox.Text = 'Sim';
            app.SimLSteerCheckBox = uicheckbox(app.WorkwithdefectivepotentiometerPanel);
            app.SimLSteerCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimLSteerCheckBoxValueChanged, true);
            app.SimLSteerCheckBox.Text = 'Sim';
            app.SimNeckCheckBox = uicheckbox(app.WorkwithdefectivepotentiometerPanel);
            app.SimNeckCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimNeckCheckBoxValueChanged, true);
            app.SimNeckCheckBox.Text = 'Sim';
            app.AllowwakeupwithoutmanipulatorCheckBox = uicheckbox(app.UIFigure);
            app.AllowwakeupwithoutmanipulatorCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowwakeupwithoutmanipulatorCheckBoxValueChanged, true);
            app.AllowwakeupwithoutmanipulatorCheckBox.Text = 'Allow wakeup without manipulator';
            app.IgnoreWheelArm = uicheckbox(app.UIFigure);
            app.IgnoreWheelArm.ValueChangedFcn = createCallbackFcn(app, @IgnoreWheelArmValueChanged, true);
            app.IgnoreWheelArm.Text = 'Robot shall ignore wheelArm';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.SimLSteerCheckBox.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cheats

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end