classdef CommSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IPLabel                       matlab.ui.control.Label
        OwnIPEditField                matlab.ui.control.EditField
        OwnIPEditFieldLabel           matlab.ui.control.Label
        Range12341240Recommended1235to1240Label  matlab.ui.control.Label
        LocalportEditField            matlab.ui.control.NumericEditField
        LocalportEditFieldLabel       matlab.ui.control.Label
        DefaultGatewayEditField       matlab.ui.control.EditField
        DefaultGatewayEditFieldLabel  matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        RobotEditField                matlab.ui.control.NumericEditField
        RobotEditFieldLabel           matlab.ui.control.Label
        CommunicationtypeButtonGroup  matlab.ui.container.ButtonGroup
        NoneButton                    matlab.ui.control.RadioButton
        UDPButton                     matlab.ui.control.RadioButton
        CANButton                     matlab.ui.control.RadioButton
        SquidCommunicationSetupLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DefaultGateWay  % Description
        LocalPort % Description
    end
    
    methods (Access = private)
        
        function rslt = SelectCommType(app)
            global AtpCfg %#ok<GVMIS,GVMIS> 
            rslt = AtpCfg.CommType; 
            switch (rslt) 
                case 'CAN'
                    app.CommunicationtypeButtonGroup.SelectedObject = app.CANButton ; 
                case 'UDP'
                    app.CommunicationtypeButtonGroup.SelectedObject = app.UDPButton ; 
                otherwise
                    app.CommunicationtypeButtonGroup.SelectedObject = app.NoneButton ; 

            end    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global AtpCfg %#ok<*GVMIS> 
            global UdpStr 


            if ~exist('AtpCfg','var') || isempty(AtpCfg) 
                evalin('base','AtpStart') ;  
            end
            SelectCommType(app) ; 

            x = load('UdpId.mat') ; 
            if ~isfield( x,'DefaultGateWay') || (~isa( x.DefaultGateWay,'char') && ~isa( x.DefaultGateWay,'string')) 
                x.DefaultGateWay = '192.168.10.1' ;  
            end
            if ~isfield( x,'LocalPort') || ~isa( x.LocalPort,'numeric') || isempty(x.LocalPort)  
                x.LocalPort = 1234 ;  
            end
            app.RobotEditField.Value = x.RobotUdpId ; 
            app.DefaultGatewayEditField.Value = x.DefaultGateWay ; 
            app.DefaultGateWay = x.DefaultGateWay ; 
            app.LocalportEditField.Value = x.LocalPort ;
            app.LocalPort =  x.LocalPort ; 
            if isfield(UdpStr,'LocalHost') 
                app.OwnIPEditField.Value = UdpStr.LocalHost ;
            else
                try
                    IPOwnPc   = GetIpV4Address(app.DefaultGateWay) ; 
                    app.OwnIPEditField.Value = IpString(IPOwnPc) ; 
                    app.IPLabel.Visible = 'Off' ; 
                catch
                    app.OwnIPEditField.Value  = 'Cant resolve' ;
                    app.OwnIPEditField.BackgroundColor = [1,0,0] ;  
                    app.IPLabel.Text = 'You are on the wrong LAN?' ; 
                end
            end

        end

        % Selection changed function: CommunicationtypeButtonGroup
        function CommunicationtypeButtonGroupSelectionChanged(app, event)

            global AtpCfg 
            global UseUdp 
            %CreateStruct =  struct('Interpreter','tex','WindowStyle' , 'modal') ; 
            selectedButton = app.CommunicationtypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'CAN' 
                    UseUdp = 0 ; 
                    % evalin('base','AtpStart') ;
                case 'UDP'
                    UseUdp = 1 ; 
                    RobotUdpId = round( app.RobotEditField.Value );
                    DefaultGateWay = app.DefaultGateWay ; 
                    LocalPort = app.LocalPort ; 
                    save UdpId RobotUdpId DefaultGateWay LocalPort
                    % evalin('base','AtpStart') ;
            end
            % SelectCommType(app) ; 


        end

        % Value changed function: RobotEditField
        function RobotEditFieldValueChanged(app, event)
            global UseUdp 
            if ( UseUdp )
                RobotUdpId = round( app.RobotEditField.Value );
                DefaultGateWay = app.DefaultGateWay ; 
                LocalPort = app.LocalPort ; 
                save UdpId RobotUdpId DefaultGateWay LocalPort
                evalin('base','AtpStart') ;
            end
            % SelectCommType(app) ; 

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

            global UseUdp
            selectedButton = app.CommunicationtypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'None' 
                    return 
                case 'CAN' 
                    UseUdp = 0 ; 
                    % evalin('base','AtpStart') ;
                case 'UDP'
                    UseUdp = 1 ; 
                    RobotUdpId = round( app.RobotEditField.Value );
                    DefaultGateWay = app.DefaultGateWay ; 
                    LocalPort = app.LocalPort ; 
                    save UdpId RobotUdpId DefaultGateWay LocalPort
                    % evalin('base','AtpStart') ;
            end
            evalin('base','AtpStart') ;
            SelectCommType(app) ; 
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: DefaultGatewayEditField
        function DefaultGatewayEditFieldValueChanged(app, event)
            value = app.DefaultGatewayEditField.Value;
            value = strtrim(char(value) ) ; 
            vf    = strsplit(value,'.'); 
            good = 1 ; 
            if length(vf) == 4
                try 
                    dgw = [str2double(vf{1}),str2double(vf{2}),str2double(vf{3}),str2double(vf{4})];
                    if  any(dgw<0) || any (dgw>255) || any (~(dgw==fix(dgw))) 
                        good = 0 ; 
                    end
                catch 
                    good = 0 ; 
                end
            else
                good = 0 ; 
            end
            if good 
                app.DefaultGateWay = app.DefaultGatewayEditField.Value ;
            else
                app.DefaultGatewayEditField.Value = app.DefaultGateWay ;
            end
            
            
        end

        % Value changed function: LocalportEditField
        function LocalportEditFieldValueChanged(app, event)
            value = app.LocalportEditField.Value;
            app.LocalPort = value ; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SquidCommunicationSetupLabel = uilabel(app.UIFigure);
            app.SquidCommunicationSetupLabel.Text = 'Squid Communication Setup';
            app.CommunicationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.CommunicationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CommunicationtypeButtonGroupSelectionChanged, true);
            app.CommunicationtypeButtonGroup.Title = 'Communication type';
            app.CANButton = uiradiobutton(app.CommunicationtypeButtonGroup);
            app.CANButton.Text = 'CAN';
            app.CANButton.Value = true;
            app.UDPButton = uiradiobutton(app.CommunicationtypeButtonGroup);
            app.UDPButton.Text = 'UDP';
            app.NoneButton = uiradiobutton(app.CommunicationtypeButtonGroup);
            app.NoneButton.Text = 'None';
            app.RobotEditFieldLabel = uilabel(app.UIFigure);
            app.RobotEditFieldLabel.Text = 'Robot#';
            app.RobotEditField = uieditfield(app.UIFigure, 'numeric');
            app.RobotEditField.Limits = [1 255];
            app.RobotEditField.RoundFractionalValues = 'on';
            app.RobotEditField.ValueChangedFcn = createCallbackFcn(app, @RobotEditFieldValueChanged, true);
            app.RobotEditField.Value = 209;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply ';
            app.DefaultGatewayEditFieldLabel = uilabel(app.UIFigure);
            app.DefaultGatewayEditFieldLabel.Text = 'Default Gateway';
            app.DefaultGatewayEditField = uieditfield(app.UIFigure, 'text');
            app.DefaultGatewayEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultGatewayEditFieldValueChanged, true);
            app.DefaultGatewayEditField.Value = '192.168.10.1';
            app.LocalportEditFieldLabel = uilabel(app.UIFigure);
            app.LocalportEditFieldLabel.Text = 'Local port';
            app.LocalportEditField = uieditfield(app.UIFigure, 'numeric');
            app.LocalportEditField.Limits = [1234 1240];
            app.LocalportEditField.ValueChangedFcn = createCallbackFcn(app, @LocalportEditFieldValueChanged, true);
            app.LocalportEditField.Value = 1234;
            app.Range12341240Recommended1235to1240Label = uilabel(app.UIFigure);
            app.Range12341240Recommended1235to1240Label.Text = 'Range 1234-1240,Recommended 1235 to 1240 ';
            app.OwnIPEditFieldLabel = uilabel(app.UIFigure);
            app.OwnIPEditFieldLabel.Text = 'Own IP';
            app.OwnIPEditField = uieditfield(app.UIFigure, 'text');
            app.OwnIPEditField.Editable = 'off';
            app.IPLabel = uilabel(app.UIFigure);
            app.IPLabel.Text = 'IPLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end