classdef EEFTester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EEFFoundLamp_2             matlab.ui.control.Lamp
        LaserResistorsCalibration  matlab.ui.control.Button
        EEFFoundLamp               matlab.ui.control.Lamp
        EEFTesterversionLabel      matlab.ui.control.Label
        FindEEFButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        IndexToName % Description
        version % Description
    end
    
    methods (Access = private)
        
        % function name = startConnection(app, index)
        %     try %OBB section added to start AtpStart if deployed
        %         % if isdeployed && ~exist('AtpCfg','var')
        %         %     AtpStart();
        %         % end
        %         AtpStart();
        %     catch ME
        %         uiwait(errordlg({'Cant communicate with LP, verify connection to CAN1 - '; ME.message},'Error') ); 
        %     end
        % end
        
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFnc(app)
            global DataType %#ok<*GVMIS,*GVMIS> 
            global DispT
            global RecStruct 

            app.version = '1.0'; 
            if ~isdeployed()
                MatDir   = '..\..\Entity\'; 
            else
                MatDir   = '..\..\LP files\Entity\'; 
            end
            
            load ([MatDir, 'IndexToName.mat']); %OBB
            app.IndexToName = IndexToName;

            app.EEFTesterversionLabel.Text = ['EEF Tester, version  ', app.version]; 
            
            AtpStartCom;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global TmMgrT
            try
                app.Lock = 1 ; 
                TmMgrT.SetCounter('TEST_WARM',-1000) ; 
                delete( app.EvtObj) ;
            %delete( app.Timer ) ;
            catch 
            end
            delete(app)
            
        end

        % Button pushed function: FindEEFButton
        function FindEEFButtonPushed(app, event)
            app.UITable.Data = [];
            try
                InitialDetectedSlaves = [KvaserCom(35)] ; %send NMT with service code 100 (the drivers will answer, the EEF will answer only to 105).
            catch
                msgbox('Error using KvaserCom.');
            end
            
            %TODO: add another kvaserCom(42) for sending NMT 42

            if isempty(InitialDetectedSlaves)
                msgbox('No devices found');
                app.EEFFoundLamp.Color = 'red';
            else
                transSlaves = transpose(InitialDetectedSlaves);
                slaveList = transSlaves(:,1);
                ProjIDList = bitshift(slaveList, -24);

                SlaveNames = getProjFromID(app.IndexToName, ProjIDList);

                if (find( strcmp(SlaveNames,'EEF') ))
                    msgbox('EEF found!');
                    app.EEFFoundLamp.Color = 'green';
                end
                
            end
        end

        % Button pushed function: LaserResistorsCalibration
        function LaserResistorsCalibrationPushed(app, event)
            SendObj([hex2dec('1234'), 10, hex2dec('7a')], 0.5 , DataType.float,''); %rotate wheel fo azimuth drive
            FetchObj( [hex2dec('2512'),2,126] , DataType.long , 'spacer Homing direction' )
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FindEEFButton = uibutton(app.UIFigure, 'push');
            app.FindEEFButton.ButtonPushedFcn = createCallbackFcn(app, @FindEEFButtonPushed, true);
            app.FindEEFButton.Text = 'Find EEF';
            app.EEFTesterversionLabel = uilabel(app.UIFigure);
            app.EEFTesterversionLabel.Text = 'EEF Tester, version ';
            app.EEFFoundLamp = uilamp(app.UIFigure);
            app.LaserResistorsCalibration = uibutton(app.UIFigure, 'push');
            app.LaserResistorsCalibration.ButtonPushedFcn = createCallbackFcn(app, @LaserResistorsCalibrationPushed, true);
            app.LaserResistorsCalibration.Text = 'Send Sync';
            app.EEFFoundLamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEFTester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFnc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end