classdef PackErrHistory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        TextAreaLabel                  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        PackageErrorinvestigatorLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataType % Description
        IsLpResponding
        VersionString 
        nPackErrors 
        PackStates
        ManPackStates
        ManFailures
        ManFails
        ManSides
        MyData
    end
    
    methods (Access = private)
       
        function data = UpdateColumnData( app, msg , colindex ) 
            x = GetPDStat(msg) ;   
            str1 =  MergeStruct( x.ManControlWord , x.PdCBit  );
            str2 =  MergeStruct( x.PdCBit2 , x.PdCBit3); 
            str3 =  MergeStruct(x.PdBitGen , x.Pacgake) ; 
            str2 =  MergeStruct( str3, str2);
            str =  MergeStruct( str1, str2);
            
            rname = app.UITable.RowName ;
            data  = app.UITable.Data ; 
            
            fnames = fieldnames( str ); 
            nNames = length( fnames) ; 
            for cnt = 1:nNames
%                 try
                NextName = fnames{cnt};
                NextRow = find( strcmp(NextName,rname), 1 ); 
                if isequal(NextName,'Package_State') || isequal(NextName,'Package_NextState')
                    data{NextRow,colindex} = GetDictEntryByCode(app,app.PackStates , str.(NextName) );
                elseif startsWith(NextName,'Package_AxisFailStatus') 
                    data{NextRow,colindex} = GetManMotErr(app,NextName(end-1:end), round(str.(NextName)) );
                elseif isequal(NextName,'Package_ManState')                   
                    data{NextRow,colindex} = GetDictEntryByCode(app,app.ManPackStates , str.(NextName) );
                elseif isequal(NextName,'Package_ManStopErr')                   
                    data{NextRow,colindex} = GetDictEntryByCode(app,app.ManFailures , str.(NextName) );
                elseif isequal(NextName,'Package_PendingException') || isequal(NextName,'Package_ManOnState_FailureCode') 
                    data{NextRow,colindex} =Errtext(str.(NextName));
                elseif isequal(NextName,'PdCBit_ManFail') 
                    data{NextRow,colindex} = GetDictEntryByCode(app,app.ManFails , str.(NextName) );
                elseif isequal(NextName,'ManControlWord_Side') 
                    data{NextRow,colindex} = GetDictEntryByCode(app,app.ManSides , str.(NextName) );
                else
                    try 
                        data{NextRow,colindex} = str.(NextName);
                    catch 
                        %disp('Zona') ; 
                    end
                end
%                 catch 
%                     junk = 1; 
%                 end
            end
            
        end
        
        function [str,code] = GetDictEntryByCode( ~,dict , code )
            place = find ( [dict.Code]==code , 1) ; 
            if isempty( place ) 
                str = ['unknown, code:',num2str(code) ]  ; 
            else
                str = dict(place).Label ;
            end
        end
        
        function str = GetManMotErr(~,Name,num)
            str = [] ; 
            if num == 0 
                str = [ Name,':', 'Ok'] ;             
                return ; 
            end
            if num == 128 
                str = 'Comm fail' ; 
            else
                if bitand(num,1)
                    str = [str,' Volt '] ; 
                end
                if bitand(num,2)
                    str = [str,' Hall '] ; 
                end
                if bitand(num,4)
                    str = [str,' OHeat '] ; 
                end
                if bitand(num,8)
                    str = [str,' Enc. '] ; 
                end
                if bitand(num,16)
                    str = [str,' Eshock '] ; 
                end
                if bitand(num,32)
                    str = [str,' Oload '] ; 
                end
                if bitand(num,64)
                    str = [str,' General '] ; 
                end
            end
            if isempty(str) 
                str = 'Unknown' ; 
            end
            str = [ Name,':', str] ;             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        % Create timer object
        % Verify initialization in the correct workspace 
        evalin('base', 'AtpStart') ; 
        CanExist = evalin('base', 'AtpCfg.CanChannelAvailable') ; 

        if CanExist 
            % Test communication  with LP
            global DataType ;
            app.DataType = DataType ; 
            global TargetCanId 
            CommFunc = [] ; % Default 
             is124 = 0 ; 
            app.IsLpResponding = 0 ;
            app.VersionString =  ['LP Version: Cant read it'] ; 
            try
                if isequal(TargetCanId,124) 
                    is124 = 1; 
                end
            catch
            end
            if ~is124 
                app.TextArea.Value = 'Bad startup directory, Start from LPTest'; 
                app.TextArea.BackgroundColor = [0,0,0] ; app.TextArea.FontColor = [1,0,0]; 
            else
                [statusComLp,~,errLp] = FetchObj( [hex2dec('2301'),5,124] , app.DataType.long ,'Project type',CommFunc) ;
                
                if ~isempty( errLp ) 
                    app.TextArea.Value = 'CAN does not respond, please connect to CAN, aconnected try restart Matlab'; 
                    app.TextArea.BackgroundColor = [0,0,1] ; app.TextArea.FontColor = [1,1,1];  
                else
                    if hex2dec('8100')==statusComLp
                        app.IsLpResponding = 1 ; 
                        [VerLp,~,~] = FetchObj( [hex2dec('2301'),4] , app.DataType.long ,'Project version',CommFunc) ;
                        yr = fix(VerLp/2^20) ; mon = bitand(fix(VerLp/2^16),15) ; day = bitand(fix(VerLp/2^8),63) ; subver = bitand(VerLp,127);
                        montable = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec','BadMonh'}; 
                        % daytable = {'Sun','Mon','Tue','Wed','Thu','Fri','Sat','BadDay'}; 
                        verstr = ['LP Version: ',num2str(yr),' : ', montable{min(mon,13)},' : ', num2str(day) ,' Subver:',num2str(subver)]; 
                        app.TextArea.Value = verstr; 
                        app.TextArea.BackgroundColor = [0,1,1] ; app.TextArea.FontColor = [0,0,0];                     
                        app.VersionString = verstr; 
                    else
                        app.TextArea.Value = 'Expected LP software, found something else'; 
                        app.TextArea.BackgroundColor = [0,0,1] ; app.TextArea.FontColor = [1,1,1];  
                    end
                end
            end
        else
            app.TextArea.Value = 'CAN Kvaser is absent, no communication'; 
            app.TextArea.BackgroundColor = [0,0,1] ; app.TextArea.FontColor = [1,1,1];  
        end
            
app.PackStates  = struct ( 'Code' , {0,1,2,3,4,5,14,15},'Label',...
    {'EPackState_Init','EPackState_WaitTailIncidence','EPackState_ManipulatorAction','EPackState_ManipulatorWaitMon',...
    'EPackState_Success','PackSt_TailIncidenceRetract','EPackState_MayUnclimb_Failure','EPackState_Failure'} ) ; 
app.ManPackStates = struct ( 'Code' , {0,1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,20,255},'Label',...
    {'MAN_ST_INIT','MAN_ST_WAIT_STOP_1','MAN_ST_GO_ROUGH_POSITION','MAN_ST_GO_ROUGH_POSITION_A','MAN_ST_WAIT_LASER_POS',...
    'MAN_ST_GO_PRECISE_POSITION','MAN_ST_DO_SUCTION','MAN_ST_FOLDBACK','MAN_ST_RETREAT',...
    'MAN_ST_DO_EDGE_STOP','MAN_ST_RETRACT_TO_STANDBY',...
    'MAN_ST_RELEASE_PUT','MAN_ST_RELEASE_WAIT_STOP','MAN_ST_PUSH_PACK','MAN_ST_RELEASE_RET_HOME','MAN_ST_DONE',...
    'MAN_ST_STDBY','MAN_ST_FAILURE'} ) ; 
app.ManFailures = struct ( 'Code' , {0,1,2,3,4,5,6,7,8,9,10,11,14,15,16,17,18,19,20,21},'Label',...
    { 'MAN_OK','EXCEPT_FINAL_PT_NOT_REACHABLE','EXCEPT_TARGET_NOT_REACHABLE','EXCEPT_INTERMEDIATE_PT_NOT_REACHABLE',...
    'EXCEPT_CANT_SET_MON_AUTO','EXCEPT_MAN_TIMEOUT','EXCEPT_SUCK_ERROR','EXCEPT_LASER_TIMEOUT',...
    'exp_man_servo_over_err','exp_man_shoulder_over_err','exp_man_wrist_over_err' ,'exp_man_elbow_over_err',...
    'EXCEPT_STANDBY_PT_NOT_REACHABLE',...
    'EXCEPT_FAILED_INIT_PACKAGE_SUCTION','EXCEPT_LOST_PACKAGE_GRIP','EXCEPT_FAILED_CALCULATING_PATH',...
    'EXCEPT_SHELF_TIMEOUT','EXCEPT_NO_LASER_AT_RELEASE','EXCEPT_STRAY_SEE_PACK' ,'EXCEPT_PACKAGE_OUT_OF_REACH'} )  ; 
 
app.ManFails  = struct ( 'Code' , {0,1,2,3,4,5,6,7},'Label',...
    {'OK','Shoulder Fail','Elbow Fail','Shoulder & Elbow Fail',...
    'Wrist Fail','Shoulder and wrist Fail','Elbow and wrist Fail','Shoulder,Elbow,Wrist Fail'} ) ; 

app.ManSides  = struct ( 'Code' , {0,1,2},'Label',...
    {'Undefined','Left','Right'} ) ; 

%	'PdCBit_NoSuck2',...
%       'PdCBit_Reserved'...
%	'PdCBit_ShuntFail',...
% 	'PdCBit3_ManSw1',...
% 	'PdCBit3_ManSw2',...
% 	'PdCBit3_StopSw1',...
% 	'PdCBit3_StopSw2',...
% 	'PdCBit3_Disc2In',...
% 	'PdBitGen_Disc1On',...
% 	'PdBitGen_Reserved',...
%'PdBitGen_ServoGateDriveOn',...

% #define MAN_HOLDS_ACTIVE_STICKY 1
% #define MAN_HOLDS_SUSPECT_CONTACT 2
% #define MAN_HOLDS_SUSPECT_CONTACT_W_PUMP 4
% #define MAN_HOLDS_SUSPECT_CONTACT_STICKY 8
% #define MAN_HOLDS_SUSPECT_CONTACT_W_PUMP_STICKY 16
% #define MAN_HOLDS_INACTIVE 32


         app.UITable.RowName = {
	'Package_AxisFailStatusD1',...
	'Package_AxisFailStatusD2',...
	'Package_AxisFailStatusSH',...
	'Package_AxisFailStatusEL',...
	'Package_AxisFailStatusWR',...
	'ManControlWord_Automatic',...
	'ManControlWord_MotorsOn',...
	'ManControlWord_Standby',...
	'ManControlWord_Package',...
	'ManControlWord_PackageGet',...
	'ManControlWord_Side',...
	'ManControlWord_LaserValid',...
	'ManControlWord_BrakeValid',...
	'ManControlWord_ReleaseWheels',...
	'ManControlWord_ReleaseSteer',...
	'ManControlWord_ReleaseNeck',...
	'ManControlWord_LaserPsOn',...
	'ManControlWord_RepeatAction',...
	'ManControlWord_Reserved',...
	'ManControlWord_UnProcFromPdo',...
 	'PdCBit_V24Fail',...
	'PdCBit_V12Fail',...
	'PdCBit_MushroomDepressed',...
	'PdCBit_GripFail',...
	'PdCBit_ManFail',...
        'PdCBit_StopFail',...
	'PdCBit_V54Fail',...
	'PdCBit_NoSuck1',...
	'PdCBit2_Active12V',...
	'PdCBit2_FailCode12V',...
	'PdCBit2_Active24V',...
	'PdCBit2_FailCode24V',...
        'PdCBit2_Active54V',...
	'PdCBit2_FailCode54V',... 
	'PdCBit3_Dyn12NetOn',...
	'PdCBit3_Dyn12InitDone',...
	'PdCBit3_Dyn24NetOn',...
	'PdCBit3_Dyn24InitDone',...
	'PdCBit3_MotorOnMan',...
	'PdCBit3_MotorOnStop',...
	'PdCBit3_PbitDone',...
	'PdCBit3_IndividualAxControl',...
	'PdBitGen_SteerBrakeRelease',...
	'PdBitGen_WheelBrakeRelease',...
	'PdBitGen_NeckBrakeRelease',...
	'PdBitGen_ShuntActive',...
	'PdBitGen_LaserPsSwOn',...
	'PdBitGen_Pump1SwOn',...
	'PdBitGen_Pump2SwOn',...
	'PdBitGen_ChakalakaOn',...
	'PdBitGen_StopBrakeReleased',...
	'PdBitGen_StopRelaySwOn',...
	'PdBitGen_FanSwOn',...
	'PdBitGen_TailLampSwOn',...
	'PdBitGen_ServoPowerOn',...
	'Package_V24',...
	'Package_V12',...
	'Package_V36',...
	'Package_V54',...
	'Package_ManState',...
	'Package_ManStopErr',...
	'Package_XPosition',...
	'Package_YPosition',...
	'Package_ManHolds',...
	'Package_ThtPosition',...
	'Package_LeftStopPosition',...
	'Package_RightStopPosition',...
	'Package_PendingException',...
	'Package_ManOnState_FailureCode',...
	'Package_State',...
	'Package_NextState',...
        'Package_TimeSec' ...
         } ; 


% Object 0x2223 is GetPdErrorStack
% {
%     short unsigned si ;
%     long unsigned * ulPtr ;
%     //float * fPtr ;
%     ulPtr =  ((unsigned long *) pSdo->SlaveBuf);
%     //fPtr =  ((float *) pSdo->SlaveBuf);
%     si = pSdo->SubIndex ;
%     *nData = 4 ;
% 
%     if ( si == 0)
%     {
%         *ulPtr = SysState.PdFaultStackCtr ;
%     }
% 
%     else if ( si <= 32 )
%     {
%         *ulPtr = SysState.PdFaultStack[si-1] ;
%     }
%     else if (si <= 40)
%     {
%         *ulPtr = SysState.PdRawData[si-33];
%     }
%     else if ( si == 128 )
%     {
%         *ulPtr = 0 ;
%         SysState.PdFaultStackCtr = 0 ; // Reset counting errors
%     }
%     else {
%         return Sub_index_does_not_exist;
%     }
%     return 0 ;
% }
    nRows = length(app.UITable.RowName); 
     x= cell( nRows,5) ; 
     cnt = 0; 
     for c2 = 1:5   
         for c1 = 1:nRows
             cnt = cnt + 1 ; 
             x{c1,c2} = ['NA'] ; 
         end
     end
     app.UITable.Data = x ; 

     if CanExist
         try 
             app.nPackErrors = FetchObj( [hex2dec('2223'),0] , app.DataType.long ,'Number of stacked package errors',CommFunc) / 10 ;
             app.TextArea.Value = [app.VersionString ,'  Number of logged package errors: ',num2str(app.nPackErrors) ] ; 
             
            lData = zeros(1,10) ; 
            for cnt = 1:10 
              lData(cnt) = FetchObj( [hex2dec('2223'),40+cnt] , app.DataType.long ,'Read package current state line',CommFunc) ;        
            end
            
        
            app.UITable.Data = UpdateColumnData ( app, lData , 1 ) ; 
            app.MyData = cell(1,1+app.nPackErrors) ; 
            app.MyData = app.UITable.Data  ;
            
            for c1=1:app.nPackErrors
                lData = zeros(1,10) ; 
                for cnt = 1:10 
                  lData(cnt) = FetchObj( [hex2dec('2223'),(c1-1)*10+cnt] , app.DataType.long ,'Read package error line',CommFunc) ;        
                end
                app.UITable.Data = UpdateColumnData ( app, lData , 1+c1 ) ;     
                app.MyData = app.UITable.Data  ;
            end
         catch 
             c1 = 1; 
         end
     end
            
        end

        % Button pushed function: SaveButton
        function Save(app, event)
            [f,fpath] = uiputfile('*.mat','File to save results') ; 
            PackH = app.MyData ;
            PacknErr = app.nPackErrors ; 
            cmd = ['save ''' ,fpath , f, ''' PackH  PacknErr;'] ; 
            eval(cmd) ; 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [f,fpath] = uigetfile('*.mat','File to get results') ; 
            x = load ( [fpath , f]) ; 
            if ~isa( x.PackH{1,1},'char') && length(x.PackH{1,1}) > 2 
                [~,n] = size(x.PackH) ;  
                x.PackH = x.PackH(1,n) ; 
            end
            app.MyData = x.PackH{1} ;
            if ( isa(app.MyData,'numeric') || isa(app.MyData,'char')) 
                app.MyData = x.PackH;
            end
            if isfield( x,'PacknErr') 
                app.nPackErrors = x.PacknErr; 
            else
                [~,n] = size(app.MyData) ; 
                app.nPackErrors = n-1; 
            end
            app.UITable.Data = app.MyData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Now'; 'Oldest'; 'Next'; 'Next'; 'Next'};
            app.UITable.RowName = {'hupi'; 'Yupi'; 'Lupi'};
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.PackageErrorinvestigatorLabel = uilabel(app.UIFigure);
            app.PackageErrorinvestigatorLabel.Text = 'Package Error investigator';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Save, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PackErrHistory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end