classdef RobotCfg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MessagetotheuserLabel         matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        RailteethPitchListBox         matlab.ui.control.ListBox
        RailteethPitchListBoxLabel    matlab.ui.control.Label
        WheelArmListBox               matlab.ui.control.ListBox
        WheelArmListBoxLabel          matlab.ui.control.Label
        ManipulatorTypeListBox        matlab.ui.control.ListBox
        ManipulatorTypeListBoxLabel   matlab.ui.control.Label
        ConfiguiratorforRobotV3Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Cfg % Description
        E_ManipulatorType % Description
        E_WheelArmType % Description       
        E_VerticalRailPitchType % Description
        version % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            app.version = '1.0';
            app.ConfiguiratorforRobotV3Label.Text = ['Robot Config, Version ', app.version]; 
            
            try %OBB section added to start AtpStart if deployed
                if isdeployed && ~exist('AtpCfg','var')
                    AtpStart();
                end
            catch
                uiwait(errordlg({'Cant run AtpStart'},'Error') ); 
            end

            % See manipulator type 
            try 
                app.Cfg = SaveConfig ( ); 
            catch 
                uiwait( errordlg(["Cannot comunicate with robot","for reading its configuration"],"Error") )  ; 
                app.UIFigureCloseRequest(app) ; 
                return ; 
            end 
            app.E_ManipulatorType = struct( 'None', 0 , 'Scara' , 1 , 'Flex_Arm' , 2 )  ; 
            app.E_WheelArmType    = struct( 'Rigid' , 0 , 'Wheel_Arm28' , 1, 'Wheel_Arm24' , 2 ,...
                 'Wheel_Arm30' , 3, 'Wheel_Arm32' , 4 )  ; 
            app.E_VerticalRailPitchType  = struct( 'Old_6p28' , 0 , 'New_6p35' , 1)  ; 


            ManipType = app.Cfg.ManipType;
            switch ManipType 
                case app.E_ManipulatorType.None 
                    app.ManipulatorTypeListBox.Value = 'None';
                case app.E_ManipulatorType.Scara 
                    app.ManipulatorTypeListBox.Value = 'Scara';
                case app.E_ManipulatorType.Flex_Arm 
                    app.ManipulatorTypeListBox.Value = 'Flex_Arm';
                otherwise
                    app.ManipulatorTypeListBox.Value = 'Scara';
                    uiwait( warndlg(["Unidentified manipulator configuration","Default to Scara"],"Warning") ) ;                 
            end

            WheelArmType = app.Cfg.WheelArmType ;
            switch WheelArmType 
                case app.E_WheelArmType.Rigid 
                    app.WheelArmListBox.Value = 'Rigid';
                case app.E_WheelArmType.Wheel_Arm28 
                    app.WheelArmListBox.Value = 'Wheel_Arm28';
                case app.E_WheelArmType.Wheel_Arm24 
                    app.WheelArmListBox.Value = 'Wheel_Arm24';
                case app.E_WheelArmType.Wheel_Arm30 
                    app.WheelArmListBox.Value = 'Wheel_Arm30';
%                 case app.E_WheelArmType.Wheel_Arm32 
%                     app.WheelArmListBox.Value = 'Wheel_Arm32';
                otherwise
                    app.WheelArmListBox.Value = 'Rigid';
                    uiwait( warndlg(["Unidentified wheel arm configuration","Default to Rigid"],"Warning") ) ;                 
            end
            

            VerticalRailPitchType = app.Cfg.VerticalRailPitchType;
            switch VerticalRailPitchType 
                case app.E_VerticalRailPitchType.Old_6p28 
                    app.RailteethPitchListBox.Value = 'Old_6p28';
                case app.E_VerticalRailPitchType.New_6p35 
                    app.RailteethPitchListBox.Value = 'New_6p35';
                otherwise
                    app.RailteethPitchListBox.Value = 'New_6p35';
                    uiwait( warndlg(["Unidentified vertical pitch configuration","Default to Old_6p28"],"Warning") ) ;                 
            end


            app.MessagetotheuserLabel.Text = "Data was read from robot" ;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            switch app.ManipulatorTypeListBox.Value 
                case 'None' 
                    app.Cfg.ManipType = app.E_ManipulatorType.None; 
                case 'Scara' 
                    app.Cfg.ManipType = app.E_ManipulatorType.Scara; 
                case 'Flex_Arm' 
                    app.Cfg.ManipType = app.E_ManipulatorType.Flex_Arm; 
                otherwise
                    uiwait( warndlg(["Unidentified manipulator configuration","Default to Scara"],"Warning") ) ; 
                    app.Cfg.ManipType = app.E_ManipulatorType.Scara; 
            end

            switch app.WheelArmListBox.Value 
                case 'Rigid' 
                    app.Cfg.WheelArmType = app.E_WheelArmType.Rigid ;
                case 'Wheel_Arm28' 
                    app.Cfg.WheelArmType = app.E_WheelArmType.Wheel_Arm28 ;
                case 'Wheel_Arm24' 
                    app.Cfg.WheelArmType = app.E_WheelArmType.Wheel_Arm24 ;
                case 'Wheel_Arm30' 
                    app.Cfg.WheelArmType = app.E_WheelArmType.Wheel_Arm30 ;
%                 case 'Wheel_Arm32' 
%                     app.Cfg.WheelArmType = app.E_WheelArmType.Wheel_Arm32 ;
                otherwise
                    app.Cfg.WheelArmType = app.E_WheelArmType.Rigid ;
                    uiwait( warndlg(["Unidentified wheel arm configuration","Default to Rigid"],"Warning") ) ;                 
            end
            

            switch app.RailteethPitchListBox.Value 
                case  'Old_6p28'
                     app.Cfg.VerticalRailPitchType = app.E_VerticalRailPitchType.Old_6p28 ;
                case  'New_6p35'
                     app.Cfg.VerticalRailPitchType = app.E_VerticalRailPitchType.New_6p35 ;
                otherwise
                     app.Cfg.VerticalRailPitchType = app.E_VerticalRailPitchType.Old_6p28 ;
                    uiwait( warndlg(["Unidentified vertical pitch configuration","Default to Old_6p28"],"Warning") ) ;                 
            end


            try
                ProgConfig(app.Cfg  );
                uiwait( warndlg(["Configuration will only be valid","At the next robot power up"],"Warning") )  ;            
                app.MessagetotheuserLabel.Text = "Configuration programmed into robot" ;
            catch
                app.MessagetotheuserLabel.Text = "Could not program robot" ;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConfiguiratorforRobotV3Label = uilabel(app.UIFigure);
            app.ConfiguiratorforRobotV3Label.Text = 'Configuirator for Robot V3';
            app.ManipulatorTypeListBoxLabel = uilabel(app.UIFigure);
            app.ManipulatorTypeListBoxLabel.Text = 'Manipulator Type';
            app.ManipulatorTypeListBox = uilistbox(app.UIFigure);
            app.ManipulatorTypeListBox.Items = {'None', 'Scara', 'Flex_Arm'};
            app.ManipulatorTypeListBox.Value = 'Scara';
            app.WheelArmListBoxLabel = uilabel(app.UIFigure);
            app.WheelArmListBoxLabel.Text = 'Wheel Arm';
            app.WheelArmListBox = uilistbox(app.UIFigure);
            app.WheelArmListBox.Items = {'Rigid', 'Wheel_Arm28', 'Wheel_Arm24', 'Wheel_Arm30'};
            app.WheelArmListBox.Value = 'Rigid';
            app.RailteethPitchListBoxLabel = uilabel(app.UIFigure);
            app.RailteethPitchListBoxLabel.Text = 'Rail teeth Pitch';
            app.RailteethPitchListBox = uilistbox(app.UIFigure);
            app.RailteethPitchListBox.Items = {'Old_6p28', 'New_6p35'};
            app.RailteethPitchListBox.Value = 'Old_6p28';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MessagetotheuserLabel = uilabel(app.UIFigure);
            app.MessagetotheuserLabel.Text = 'Message to the user';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotCfg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end