classdef SideCamCalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SideCameraCalibrationappforRobotV3versionLabel  matlab.ui.control.Label
        StartCalibrationButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        IndexToName % Description
        version % Description
        DataType % Description
        CalibData % Description
    end
    
    methods (Access = private)
        
        function name = startConnection(app, index)
            try %OBB section added to start AtpStart if deployed
                % if isdeployed && ~exist('AtpCfg','var')F
                %     AtpStart();
                % end
                AtpStart();
            catch
                uiwait(errordlg({'Cant communicate with LP, verify connection to CAN1.'},'Error') ); 
            end
        end
        
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFnc(app)
            global DataType %#ok<*GVMIS,*GVMIS> 
            global DispT
            global RecStruct 

            app.version = '1.0'; 
            MatDir   = '.\Mat\'; 

            app.SideCameraCalibrationappforRobotV3versionLabel.Text = ['Side Camera Calibration app for Robot V3, version  ', app.version]; 

            startConnection(app);

            app.DataType = GetDataType() ; 

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global TmMgrT
            try
                app.Lock = 1 ; 
                TmMgrT.SetCounter('TEST_WARM',-1000) ; 
                delete( app.EvtObj) ;
            %delete( app.Timer ) ;
            catch 
            end
            delete(app)
            
        end

        % Button pushed function: StartCalibrationButton
        function StartCalibrationButtonPushed(app, event)
            
            answer1 = questdlg({'\fontsize{12}Which side camera do you want to calibrate?'}, 'Calibration', ...
                                         'Right', 'Left' , 'Abort', struct ('Default','Abort','Interpreter','tex') );     %the default button is Abort, the interpreter is tex
            if isequal(answer1,'Abort')
                return ; 
            end  

            answer2 = questdlg(strcat('\fontsize{12}Are you sure you want to calibrate the  ' , answer1 , ' side camera?'), 'Calibration', ...
                                         'Yes', 'Abort', struct ('Default','Abort','Interpreter','tex') );     %the default button is Abort, the interpreter is tex
            if ~isequal(answer2,'Yes')
                return ; 
            end  

            HalfDistBetweenWheelAxes = GetFloatPar('Geom.HalfDistBetweenWheelAxes','1') ; 
            DistWheelAxis2WagonTip = GetFloatPar('Geom.DistWheelAxis2WagonTip','1') ; 
            TerminalClimbClearance = GetFloatPar('Geom.TerminalClimbClearance','1') ; 

            switch answer1
                case 'Left'
                        CalibYNominal = DistWheelAxis2WagonTip + HalfDistBetweenWheelAxes + TerminalClimbClearance;
                case 'Right'
                        CalibYNominal = -(DistWheelAxis2WagonTip + HalfDistBetweenWheelAxes + TerminalClimbClearance);
            end

            questdlg(strcat('Locate the calibration jig at its position (', num2str(CalibYNominal), 'm from camera) and press Continue:'), 'Side Camera Calibration', ...
                                     'Continue', struct ('Default','Continue','Interpreter','tex') );     %the default button is Continue, the interpreter is tex
            
            try
                switch answer1
                    case 'Right'
                        SendObj([hex2dec('2220'), 153], 1 , app.DataType.long,''); %calibration start, request reports from LP, start capturing 
                    case 'Left'
                        SendObj([hex2dec('2220'), 153], 2 , app.DataType.long,''); %calibration start, request reports from LP, start capturing 
                end

                %Transmit the LP to measure the jig position
                SendObj([hex2dec('2220'), 150],1, app.DataType.long,''); 
            catch ME
                errordlg ( ['Could not communicate with LP: ',ME.message] ) ; 
            end
            
            tic
            try
                while( FetchObj([hex2dec('2220'), 157],app.DataType.float,'') ~= 0 )
                    T = toc;
                    if T > 15
                        errordlg ( 'Measurement time is too long' ) ; 
                        break;
                    end
                end
            catch ME
                errordlg ( ['Could not communicate with LP: ',ME.message] ) ; 
            end

             %todo: get from ParRecords

            try
                CalibX = FetchObj([hex2dec('2220'), 154],app.DataType.float,'');
                CalibY = FetchObj([hex2dec('2220'), 155],app.DataType.float,'') - CalibYNominal;
                CalibAz = FetchObj([hex2dec('2220'), 156],app.DataType.float,'');

                answer2 = questdlg({'\fontsize{12}Calibration results deviations are as following:' ; strcat( 'X: ', num2str(CalibX), ', Y: ', num2str(CalibY), ', Az: ', num2str(CalibAz)) } , 'Side Camera Calibration', ...
                                         'Continue', 'Abort', struct ('Default','Abort','Interpreter','tex') );     %the default button is Continue, the interpreter is tex
    
                if isequal(answer2,'Abort')
                    %SendObj([hex2dec('2220'), 153],0, app.DataType.long,'');  %abort calibration
                    return ; 
                end  
    
                %SendObj([hex2dec('2220'), 153],4567, app.DataType.long,'');  %approve sending calibration results to LP RAM
                %SendObj([hex2dec('2220'), 153],0, app.DataType.long,'');  %abort calibration
            catch ME
                errordlg ( ['Could not communicate with LP: ',ME.message] ) ; 
            end

            try
                [fname,PathName] = uiputfile('*.jsn','Select the calibration JSON file');

                app.CalibData = SaveCalib( [],[PathName,fname] ) ;
                switch answer1
                    case 'Right'
                        app.CalibData.XCalibShiftR = CalibX;
                        app.CalibData.YCalibShiftR = CalibY;
                        app.CalibData.AzCalibShiftR = CalibAz;
                    case 'Left'
                        app.CalibData.XCalibShiftL = CalibX;
                        app.CalibData.YCalibShiftL = CalibY;
                        app.CalibData.AzCalibShiftL = CalibAz;
                end
                SaveCalib( app.CalibData,[PathName,fname] ) ;

                dcd = questdlg('Save to flash?', ...
                                         'Please specify', ...
                                         'Yes', 'No' , 'No');  
                if isequal( dcd , 'Yes' )                 
                    ProgCalib([PathName,fname], 1 ); 
                    %questdlg('\fontsize{12}Calibration done.' , 'Side Camera Calibration', ...
                    %                 'Continue', struct ('Default','Continue','Interpreter','tex') );     %the default button is Continue, the interpreter is tex
                end 
            catch ME
                errordlg ( ['Calibration writing error: ',ME.message] ) ; 
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartCalibrationButton = uibutton(app.UIFigure, 'push');
            app.StartCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartCalibrationButtonPushed, true);
            app.StartCalibrationButton.Text = 'Start Calibration';
            app.SideCameraCalibrationappforRobotV3versionLabel = uilabel(app.UIFigure);
            app.SideCameraCalibrationappforRobotV3versionLabel.Text = 'Side Camera Calibration app for Robot V3, version ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SideCamCalib

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFnc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end