classdef WheelArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExceptionLabel              matlab.ui.control.Label
        CalibrationdataPanel        matlab.ui.container.Panel
        CalibdateEditField          matlab.ui.control.NumericEditField
        CalibdateEditFieldLabel     matlab.ui.control.Label
        CalibIDEditField            matlab.ui.control.NumericEditField
        CalibIDEditFieldLabel       matlab.ui.control.Label
        CalibexistsCheckBox         matlab.ui.control.CheckBox
        DebugPanel                  matlab.ui.container.Panel
        CurrentEditField            matlab.ui.control.NumericEditField
        CurrentEditFieldLabel       matlab.ui.control.Label
        DebugmodeCheckBox           matlab.ui.control.CheckBox
        DebugModeButton             matlab.ui.control.StateButton
        InstructionsLabel           matlab.ui.control.Label
        RightPanel_2                matlab.ui.container.Panel
        CalibratepinButton_2        matlab.ui.control.Button
        CalibrateangleButton_2      matlab.ui.control.Button
        NextStepButton_2            matlab.ui.control.Button
        FeedbackPanel_2             matlab.ui.container.Panel
        PindisengagedCheckBox_2     matlab.ui.control.CheckBox
        PosEditField_2              matlab.ui.control.NumericEditField
        PosEditField_2Label         matlab.ui.control.Label
        FaultReason_2               matlab.ui.control.Label
        MotorfaultCheckBox_2        matlab.ui.control.CheckBox
        MotoronCheckBox_2           matlab.ui.control.CheckBox
        PinengagedCheckBox_2        matlab.ui.control.CheckBox
        PinencoderEditField_2       matlab.ui.control.NumericEditField
        PinencoderEditField_2Label  matlab.ui.control.Label
        ArmangleEditField_2         matlab.ui.control.NumericEditField
        ArmangleEditField_2Label    matlab.ui.control.Label
        PotEditField                matlab.ui.control.NumericEditField
        PotEditFieldLabel           matlab.ui.control.Label
        CommandPanel_2              matlab.ui.container.Panel
        PinButtonGroup_2            matlab.ui.container.ButtonGroup
        RetractButton_2             matlab.ui.control.RadioButton
        PushButton_2                matlab.ui.control.RadioButton
        OffButton_3                 matlab.ui.control.RadioButton
        LeftPanel                   matlab.ui.container.Panel
        CalibratepinButton          matlab.ui.control.Button
        NextStepButton              matlab.ui.control.Button
        CalibrateangleButton        matlab.ui.control.Button
        FeedbackPanel               matlab.ui.container.Panel
        PindisengagedCheckBox       matlab.ui.control.CheckBox
        PosEditField                matlab.ui.control.NumericEditField
        PosEditFieldLabel           matlab.ui.control.Label
        FaultReason                 matlab.ui.control.Label
        MotorfaultCheckBox          matlab.ui.control.CheckBox
        MotoronCheckBox             matlab.ui.control.CheckBox
        PinengagedCheckBox          matlab.ui.control.CheckBox
        PinencoderEditField         matlab.ui.control.NumericEditField
        PinencoderEditFieldLabel    matlab.ui.control.Label
        ArmangleEditField           matlab.ui.control.NumericEditField
        ArmangleEditFieldLabel      matlab.ui.control.Label
        PotEditField_2              matlab.ui.control.NumericEditField
        PotEditField_2Label         matlab.ui.control.Label
        CommandPanel                matlab.ui.container.Panel
        PinButtonGroup              matlab.ui.container.ButtonGroup
        RetractButton               matlab.ui.control.RadioButton
        PushButton                  matlab.ui.control.RadioButton
        OffButton                   matlab.ui.control.RadioButton
        WheelArmDialogLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        EvtObj % Description
        Timer  % Description
        LCalib  % Description
        RCalib % Description
        InRCalib % Description
        Lock % Description
        LCalibBackup % Description
        CalibInit % Description
        CalibRslt % Description
        CalibExist % Description
        Geom % Description
        RCalibBackup % Description
        InLPinCalib % Description
        InRPinCalib % Description
        LPinCalib % Description
        RPinCalib % Description
        InLCalib % Description
        ModeEntryCnt % Description
        InRock % Description
        ManDesc % Description
        WheelArmRZeroCnt % Description
        WheelArmLZeroCnt % Description
        RWheelArmLatchTravel % Description
        LWheelArmLatchTravel % Description
    end
    
    methods (Access = private)
        
        function update_warm_display(app,~,~ )
            global DataType 

            global TmMgrT
        
            try
            cnt = TmMgrT.GetCounter('TEST_WARM') ; 
            
            if cnt < -0.5e-6 
                UIFigureCloseRequest(app, []); 
                %delete( hobj) ; 
            else
                TmMgrT.IncrementCounter('TEST_WARM',2) ; 
            end     
                
            catch 
            end

            if app.Lock
                return ; 
            end
            try
                dbgstate = 2^32 + FetchObj( [hex2dec('2222'),24] , DataType.long , 'SysState.Debug') ; % Left 
                dbgwa  = bitand(dbgstate,1)  ; 
                app.DebugmodeCheckBox.Value = dbgwa ; 
                rdisengage = bitand(dbgstate,2) / 2 ; 
                ldisengage = bitand(dbgstate,4) / 4 ; 
                rengage = bitand(dbgstate,128) / 128 ; 
                lengage = bitand(dbgstate,256) / 256 ; 
                rmotoron = bitand(dbgstate,8) / 8 ; 
                lmotoron = bitand(dbgstate,16) / 16 ; 
                app.RPinCalib.motorfault = bitand(dbgstate,32) / 32 ; 
                app.LPinCalib.motorfault = bitand(dbgstate,64) / 64 ; 
                TrackWidthCtlActive = bitand(dbgstate,32768) / 32768 ; 
                rmotorfaultCode = bitand(dbgstate,255*2^16) / 2^16 ; 
                lmotorfaultCode = bitand(dbgstate,255*2^24) / 2^24 ; 
                
                if TrackWidthCtlActive > 0 
                    app.ModeEntryCnt = min(max(app.ModeEntryCnt+1,2),10); 
                else
                    app.ModeEntryCnt = max(app.ModeEntryCnt-1,0); 
                end
                
                app.FaultReason.Text = Xm430FaultReason(app,lmotorfaultCode) ;
                app.FaultReason_2.Text = Xm430FaultReason(app,rmotorfaultCode) ; 
                
                RPinPos = FetchObj( [hex2dec('2222'),25] , DataType.long , 'RPinPos') ; % Left 
                LPinPos = FetchObj( [hex2dec('2222'),26] , DataType.long , 'LPinPos') ; % Left 
     
                RWheelArmAngle =  FetchObj( [hex2dec('2204'),16] , DataType.float , 'RWheelArmAngle') ; % Left 
                LWheelArmAngle =  FetchObj( [hex2dec('2204'),17] , DataType.float , 'LWheelArmAngle') ; % Ritght
                
                WheelArmPot1 =  FetchObj( [hex2dec('2204'),18] , DataType.float , 'OverLoadkRSt1') ; % Left 
                WheelArmPot2 =  FetchObj( [hex2dec('2204'),19] , DataType.float , 'OverLoadkRSt2') ; % Ritght
                
                app.ArmangleEditField.Value   = LWheelArmAngle  ; 
                app.ArmangleEditField.BackgroundColor = GetArmAngleFieldColor( app, LWheelArmAngle) ;

                app.ArmangleEditField_2.Value = RWheelArmAngle  ; 
                app.ArmangleEditField_2.BackgroundColor = GetArmAngleFieldColor( app, RWheelArmAngle) ;
    
                app.PotEditField_2.Value = WheelArmPot1 ;
                app.PotEditField.Value = WheelArmPot2 ; 
                
                app.PinencoderEditField.Value = LPinPos ;
                app.PinencoderEditField_2.Value = RPinPos ;
                
                
                app.PosEditField.Value  = LPinPos - app.WheelArmLZeroCnt; 
                app.PosEditField_2.Value  = RPinPos - app.WheelArmRZeroCnt ;
                
                
                app.MotoronCheckBox.Value = lmotoron ;
                app.MotoronCheckBox_2.Value = rmotoron ;
                
                app.MotorfaultCheckBox.Value = app.LPinCalib.motorfault ; 
                app.MotorfaultCheckBox_2.Value = app.RPinCalib.motorfault ;
                
                app.PinengagedCheckBox.Value = lengage ;
                app.PinengagedCheckBox_2.Value = rengage ;
                app.PindisengagedCheckBox.Value = ldisengage;
                app.PindisengagedCheckBox_2.Value = rdisengage;
                
                
                if ( app.ModeEntryCnt == 1 )
                    app.OffButton_2.Value = 1 ; 
                    app.OffButton_4.Value = 1 ; 
                end
                
                UnitStat =  2^32+FetchObj( [hex2dec('220b'),1] , DataType.long , 'UnitStatus') ; % Ritght
                Mot_mode = bitand(UnitStat,15) ;  
                expnum = FetchObj( [hex2dec('220b'),2] , DataType.long ,'Captured exceptions') ;
                expnow = bitand( expnum , 65535  ) ; 
                if expnow == 0 
                    app.ExceptionLabel.BackgroundColor = [0.65,0.65,0.65];
                else
                    app.ExceptionLabel.BackgroundColor = [1,0,0];
                end
                [etext,elabtext] = Errtext(expnow); 
                errstr = ['1: 0x',dec2hex(expnow),' : ',etext ,' : ',elabtext]  ; 
                app.ExceptionLabel.Text = errstr ; 
               
                if ( app.InRock ) 
                    if ( Mot_mode == 15) % Fault 
                         ResetRockingDemand(app) ; 
                        uiwait( errordlg({"Wheel arm aborted : ",errstr})) ; 
                    end
                end
                
                if ( app.InLCalib )
                    try
                        app.LCalib = ManageCalib(app, app.LCalib,WheelArmPot1,LPinPos );
                        app.NextStepButton.Visible = app.LCalib.NextButtonVisible ;
                        app.InLCalib = app.LCalib.InCalib ; 
                    catch
                        app.NextStepButton.Visible = 'off' ;
                        app.InLCalib = 0 ; 
                        app.InstructionsLabel.Text = 'Calibration aborted by problem' ;
                    end
                end
                if ( app.InLPinCalib )
                    try
                        app.LPinCalib = ManagePinCalib(app, app.LPinCalib,WheelArmPot1,LPinPos );
                        app.NextStepButton.Visible = app.LPinCalib.NextButtonVisible ;
                        app.InLPinCalib = app.LPinCalib.InCalib ; 
                    catch 
                        app.NextStepButton.Visible = 'off' ;
                        app.InLPinCalib = 0 ; 
                        app.InstructionsLabel.Text = 'Calibration aborted by problem' ;
                    end
                end
                if ( ~ (app.InLCalib || app.InLPinCalib ))
                    if ( isequal(app.NextStepButton.Visible , "on"))
                        app.NextStepButton.Visible = 'off' ;
                        app.InstructionsLabel.Text = 'Calibration canceled' ;
                    end
                    app.CalibrateangleButton.Text = 'Calibrate angle'; 
                    app.CalibratepinButton.Text = 'Calibrate pin'; 
                end

                % Right calibration 
                if ( app.InRCalib )
                    try
                        app.RCalib = ManageCalib(app, app.RCalib,WheelArmPot2,RPinPos );
                        app.NextStepButton_2.Visible = app.RCalib.NextButtonVisible ;
                        app.InRCalib = app.RCalib.InCalib ; 
                    catch
                        app.NextStepButton_2.Visible = 'off' ;
                        app.InRCalib = 0 ; 
                        app.InstructionsLabel.Text = 'Calibration aborted by problem' ;
                    end
                end
                if ( app.InRPinCalib )
                    try
                        app.RPinCalib = ManagePinCalib(app, app.RPinCalib,WheelArmPot2,RPinPos );
                        app.NextStepButton_2.Visible = app.RPinCalib.NextButtonVisible ;
                        app.InRPinCalib = app.RPinCalib.InCalib ; 
                    catch
                        app.NextStepButton_2.Visible = 'off' ;
                        app.InRPinCalib = 0 ; 
                        app.InstructionsLabel.Text = 'Calibration aborted by problem' ;
                    end
                end
                if ( ~( app.InRCalib || app.InRPinCalib ) )
                    if ( isequal(app.NextStepButton_2.Visible , "on"))
                        app.NextStepButton_2.Visible = 'off' ;
                        app.InstructionsLabel.Text = 'Calibration canceled' ;
                    end
                    app.CalibrateangleButton_2.Text = 'Calibrate angle'; 
                    app.CalibratepinButton_2.Text = 'Calibrate pin'; 
                end
                
            catch 
            end 
        end
        
        function calib = ManageCalib(app, calib_in , pot , pinencoder  )
            global DataType 
            calib = calib_in ; 
            if calib.Cntr 
                if  calib.Cntr == calib.CntrMax
                    calib.PotSum    = 0 ; 
                    calib.PinEncoderSum = 0 ; 
                end 
                calib.Cntr = calib.Cntr - 1 ; 
                calib.PotSum = calib.PotSum + pot ; 
                calib.PinEncoderSum = calib.PinEncoderSum + pinencoder ; 
                if (calib.Cntr == 0  ) 
                    calib.Pot = calib.PotSum / calib.CntrMax ;
                    calib.PinEncoder = calib.PinEncoderSum / calib.CntrMax ;
                    calib.State = calib.State + 1 ; 
                    calib.Next = 1 ;
                else
                    calib.NextButtonVisible = 0 ; 
                    app.InstructionsLabel.Text = ['Averaging...',num2str(calib.Cntr) ,' Wait'];
                    return ; 
                end
            end
            
            if calib.Next == 0 
                return ; 
            end
            calib.NextButtonVisible = 0 ; 
            calib.Next = 0 ; 
            switch ( calib.State )
                case 0
                    SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Kill motor inst.' ) ;                             
                    SendObj( [hex2dec('2222'),6] , 1 , DataType.long , 'Enter debug mode' ) ;
                    calib.Cntr  = calib.CntrMax ; 
                case 1
                    calib.PotRslt = [calib.Pot, 0 ] ; 
                    app.InstructionsLabel.Text = ["Set the wheel arm joint to extended state","And press Next Step"] ; 
                    calib.NextButtonVisible = 1 ;
                    calib.State = 2 ; 
                case 2
                    calib.Cntr = calib.CntrMax ; 
                case 3
                    calib.PotRslt(2) = calib.Pot ; 
                    str = struct('Geom',app.Geom,'Calib',calib,'Type','PotGain') ;                                     
                    save CalibWheelArm.mat str ;
                    evalin('base','CalcCalibWeelArm(''CalibWheelArm.mat'')' ); 
                    r = load('CalibWheelArmRslt.mat') ; 
                    %delta = calib.Direction * diff(calib.PotRslt)   ; 
                    %tol = [0.12,0.18] ; 
                    %if delta < tol(1) || delta > tol(2)
                    %    errorstr = {'Failure: Potentiometer difference between the sides does not make sense',...
                    %        ['Must be in range ',num2str(tol(1)),'..',num2str(tol(2)) ,' actually:' , num2str(delta)]} ;
                    if ( ~isempty(r.errorstr) )
                        uiwait( errordlg(r.errorstr) ) ; 
                        app.InstructionsLabel.Text = r.errorstr ;
                        calib.InCalib = 0 ; 
                    else
                        calib.State = 4 ; 
                        calib.NextButtonVisible = 1 ;
                        app.InstructionsLabel.Text = ["If you want to save in Flash, press Next Step","Otherwise press Stop Calib"] ;   
                    end
                case 4
                    r = load('CalibWheelArmRslt.mat') ; 

                    CalibRsltBup = SaveCalib([]) ;

                    if  isequal( calib.ID,'LCalib' )
                        CalibRsltBup.LWheelArmGain = r.PotGainCorrection ; 
                        CalibRsltBup.LWheelArmOffset = r.PotOffsetCorrection ; 
                    else
                        CalibRsltBup.RWheelArmGain = r.PotGainCorrection ; 
                        CalibRsltBup.RWheelArmOffset = r.PotOffsetCorrection ; 
                    end
                    [~,fname] = SaveCalib(CalibRsltBup,[]) ; 

                    ProgCalib(fname,1) ; 
                    app.InstructionsLabel.Text = {'Calibration done'} ;   
                    calib.InCalib = 0  ;

            end
        end
        
        function str = Xm430FaultReason(~,code)
            switch code 
                case 0
                   str = 'Ok'; 
                case 128
                    str = 'No comm';
                case 32
                   str = 'Overload'; 
                case 16 
                   str = 'El. shock'; 
                case 8
                    str = 'Enc error'; 
                case 4 
                    str = 'Overheat'; 
                case 1
                    str = 'Voltage ';
                otherwise
                    str = str2double(code) ;
            end
        end
        
        function calib = ManagePinCalib(app,calib_in,pot,pinencoder)
            
            global DataType 
            
            if ( calib_in.motorfault)
                app.InstructionsLabel.Text = {'Pin motor failed'} ; 
                calib.NextButtonVisible = 0 ; 
                calib.InCalib = 0 ; 
                return ;
            end
            
            if isequal(calib_in.ID,'RCalib') 
                axis = 1 ; 
            else
                axis = 2 ; 
            end
            
            calib = calib_in ; 
            if calib.Cntr 
                if  calib.Cntr == calib.CntrMax
                    calib.PotSum    = 0 ; 
                    calib.PinEncoderSum = 0 ; 
                end 
                calib.Cntr = calib.Cntr - 1 ; 
                calib.PotSum = calib.PotSum + pot ; 
                calib.PinEncoderSum = calib.PinEncoderSum + pinencoder ; 
                if (calib.Cntr == 0  ) 
                    calib.Pot = calib.PotSum / calib.CntrMax ;
                    calib.PinEncoder = calib.PinEncoderSum / calib.CntrMax ;
                    calib.State = calib.State + 1 ; 
                    calib.Next = 1 ;
                else
                    calib.NextButtonVisible = 0 ; 
                    app.InstructionsLabel.Text = ['Averaging...',num2str(calib.Cntr) ,' Wait'];
                    return ; 
                end
            end
            
            if calib.Next == 0 
                return ; 
            end
            calib.NextButtonVisible = 0 ; 
            calib.Next = 0 ; 
            switch ( calib.State )
                case 0
                    SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Kill motor inst.' ) ;                             
                    SendObj( [hex2dec('2222'),6] , 1 , DataType.long , 'Enter debug mode' ) ;
                    
                    % Release pin 
                    SendObj( [hex2dec('2222'),5] , 2 , DataType.long , 'Retract' ) ;            
                    SendObj( [hex2dec('2222'),4] , axis , DataType.long , 'Start motor' ) ;  
                    
                    app.InstructionsLabel.Text = {'Rock the wheel arm until the latch jumps out','Then press next'} ;
                    calib.NextButtonVisible = 1 ; 
                    calib.State  = 1 ; 
                case 1
                    calib.Cntr = calib.CntrMax ; 
                case 2
                    calib.PinEncoders = [ calib.PinEncoder , 0 , 0 , 0 ] ; % ! : Get the released position 
                    SendObj( [hex2dec('2222'),5] , 1 , DataType.long , 'Push' ) ;      
                    app.InstructionsLabel.Text = {'Rock the wheel arm until the latch jumps in','Then press next'} ;
                    calib.NextButtonVisible = 1 ; 
                    calib.State = 31 ; 
                case 31
                    calib.Cntr = calib.CntrMax ; 
                case 32
                    calib.PinEncoders(2) = calib.PinEncoder ; % Get the pushed position 
                    SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Shut motor' ) ;  
                    app.InstructionsLabel.Text = {'Measuring pin height as normal (in pin with motor release)'} ;
                    calib.State = 33 ; 
                    calib.Next  = 1 ; % Generate a unit delay 
                case 33 
                    calib.Cntr = calib.CntrMax ; 
                case 34
                    calib.PinEncoders(3)  = calib.PinEncoder ; % Get the "normal" (in pin with motor release)  position 
%                     app.InstructionsLabel.Text = {'Rock the wheel arm until the latch jumps out','Then place the cam so that the pin is !!NOT!! over a dip','Then press next'} ;
%                     calib.NextButtonVisible = 1 ; 
%                     SendObj( [hex2dec('2222'),5] , 2 , DataType.long , 'Retract' ) ;            
%                     SendObj( [hex2dec('2222'),4] , axis , DataType.long , 'Start motor' ) ;  
%                    calib.State  = 35 ; 
                    SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Shut motor' ) ;  
                    SendObj( [hex2dec('2222'),5] , 0 , DataType.long , 'Kill motor' ) ;      
                    calib.State = 37 ; 
                    calib.Next  = 1 ; 
%                 case 35
%                     SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Shut motor' ) ;  
%                     calib.State = 36 ; 
%                     app.InstructionsLabel.Text = {'Measuring pin height when pressing cam'} ;
%                     calib.Next  = 1 ; % Generate a unit delay 
%                 case 36
%                     calib.Cntr = calib.CntrMax ; 
                case 37
%                     calib.PinEncoders(4)  = calib.PinEncoder ; % Collect the "IOn restig on CAM position
                    
                    str = struct('Geom',app.Geom,'Calib',calib,'Type','WheelArmPin') ;                                     
                    save CalibWheelArmPin.mat str ;
                    evalin('base','CalcCalibWeelArmPin(''CalibWheelArmPin.mat'')' ); 
                    r = load('CalibWheelArmRsltPin.mat') ; 

                    if ( ~isempty(r.errorstr) )
                        uiwait( errordlg(r.errorstr) ) ; 
                        app.InstructionsLabel.Text = r.errorstr ;
                        calib.InCalib = 0 ; 
                    else
                        calib.State = 5 ; 
                        calib.NextButtonVisible = 1 ;
                        app.InstructionsLabel.Text = ["If you want to save in Flash, press Next Step","Otherwise press Stop Calib"] ;   
                    end
                case 5
                    r = load('CalibWheelArmRsltPin.mat') ; 

                    CalibRsltBup = SaveCalib([]) ;

                    if  axis == 1 
                        CalibRsltBup.WheelArmRZeroCnt = r.WheelArmZeroCnt ; 
                        CalibRsltBup.RWheelArmLatchTravel = r.WheelArmTravel ; 
                        app.WheelArmRZeroCnt = r.WheelArmZeroCnt ; 
                        app.RWheelArmLatchTravel = r.WheelArmTravel ; 
                    else
                        CalibRsltBup.WheelArmLZeroCnt = r.WheelArmZeroCnt ; 
                        CalibRsltBup.LWheelArmLatchTravel = r.WheelArmTravel ; 
                        app.WheelArmLZeroCnt = r.WheelArmZeroCnt ; 
                        app.LWheelArmLatchTravel = r.WheelArmTravel ; 
                    end
                    [~,fname] = SaveCalib(CalibRsltBup,[]) ; 

                    if ~isempty(fname) 
                        ProgCalib(fname,1) ;                 
                        app.InstructionsLabel.Text = {'Calibration done'} ;   
                    else
                        app.InstructionsLabel.Text = {'Flash save aborted'} ;   
                    end
                    calib.InCalib = 0  ;                                        
           end
        end
        
        function ResetRockingDemand(app)
            global DataType 
            app.RockButtonGroup.SelectedObject = app.OffButton_2  ;
            app.InRock = 0  ; 
            SendObj( [hex2dec('2207'),57] , 32767 , DataType.long , 'Kill motor' ) ;            
            %app.OffButton_2.Value = 1 ; 
        end
        
        function ResetPinDemand(app)
            app.PinButtonGroup.SelectedObject = app.OffButton ; 
        end
        
        function ResetPinDemand_2(app)
             app.PinButtonGroup_2.SelectedObject = app.OffButton_3;          
        end
        
        function SetMotorCurrent(app,cur)
            global DataType 
            if ( cur <= 0.005 || cur > 2 )
                cur = app.Geom.PinMotorCurrentAmp ; 
            end
            app.Geom.PinMotorCurrentAmp = cur ; 
            app.CurrentEditField.Value = cur ; 
            SendObj( [hex2dec('2222'),8] , cur , DataType.float , 'Pin motor current' ) ;   

        end
        
        function color = GetArmAngleFieldColor(app,value)
            tol = app.Geom.WheelArmAngleTolerance ; 
            if ( value > app.Geom.WheelArmAngleRetract - tol ) && ( value < app.Geom.WheelArmAngleRetract + tol )
                color = [0 1 0] ; 
            elseif ( value > app.Geom.WheelArmAngleExtend - tol ) && ( value < app.Geom.WheelArmAngleExtend + tol )
                color = [0 1 1] ; 
            else
                color = [1 0 0 ] * 0.9 ;  
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DataType %#ok<*GVMIS,*GVMIS> 
            global DispT
            try 
            dbgstate = FetchObj( [hex2dec('2222'),24] , DataType.long , 'SysState.Debug') ; % Left 
            dbgwa  = bitand(dbgstate,1)  ; 
            app.DebugmodeCheckBox.Value = dbgwa ; 
            app.DebugModeButton.Value= dbgwa ;
            catch
            end
            
            app.InRock = 0 ; 
            app.InLCalib = 0 ; 
            app.InRCalib = 0 ; 
            app.InLPinCalib = 0 ; 
            app.InRPinCalib = 0 ; 
            app.Lock = 0 ; 
            app.ModeEntryCnt = 0 ; 
            app.RWheelArmLatchTravel = 170 ; % Description
            app.LWheelArmLatchTravel = 170 ; % Description

 
            if ~exist('DispT','var') || ~isa(DispT,'DlgTimerObj')
                evalin('base','AtpStart') ; 
            end 
            app.EvtObj = DlgUserObj(@app.update_warm_display,findobj(app) );
            app.EvtObj.MylistenToTimer(DispT) ;   

            app.NextStepButton.Visible = 'off' ;
            app.NextStepButton_2.Visible = 'off' ;
            
            % Bring calibration from flash 
            stat = SendObj( [hex2dec('2302'),251] , 0 , DataType.long ,'Load the calibration from the flash to the programming struct') ;  
            if ( stat )
                app.CalibInit = SaveCalib ( 0   ) ; 
                app.CalibdateEditField.Value = 0 ; 
                app.CalibIDEditField.Value = 0 ; 
                app.CalibExist = 0 ;
            else
                app.CalibInit = SaveCalib ( []   ) ; 
                app.CalibdateEditField.Value = app.CalibInit.CalibDate ; 
                app.CalibIDEditField.Value = app.CalibInit.CalibData ; 
                app.CalibExist = 1 ;
            end
            app.CalibexistsCheckBox.Value = app.CalibExist ; 
            app.CalibRslt = app.CalibInit ; 
            
             a = GetFloatPar('Geom.RWheelArmPotOffset'); 
             b = GetFloatPar('Geom.LWheelArmPotOffset'); 
             c = GetFloatPar('Geom.WheelArmPotGain');
             
             d = GetFloatPar('Geom.WheelArmJointDist');
             e = GetFloatPar('Geom.WheelArmRodLength4Pin');
             f = GetFloatPar('Geom.WheelArmAngleRetract');

             g = FetchObj( [hex2dec('2224'),4] , DataType.float , 'WheelArmAngleExtend' ) ; 
             k = GetFloatPar('Geom.PinMotorCurrentAmp')  ; 
             m = GetFloatPar('Geom.WheelArmAngleTolerance') ; 
             app.CurrentEditField.Value = k ; 
           
             app.Geom = struct('RWheelArmPotOffset',a,'LWheelArmPotOffset',b,'WheelArmPotGain',c,...
                 'WheelArmJointDist',d,'RodLength4Pin',e,'WheelArmAngleRetract',f,'WheelArmAngleExtend',g,'WheelArmAngleTolerance',m,...
                 'PinMotorCurrentAmpBackup',k,'PinMotorCurrentAmp',min(k,0.9)) ; 
             
             app.LPinCalib  = struct('State',0,'InCalib',0,'Next',0,'Cntr',0,'CntrMax',5,'NextButtonVisible',...
                    'off','Direction',1,'ID','LCalib','motorfault',0)  ; 
             app.RPinCalib  = struct('State',0,'InCalib',0,'Next',0,'Cntr',0,'CntrMax',5,'NextButtonVisible',...
                    'off','Direction',1,'ID','RCalib','motorfault',0)  ; 
             
            app.WheelArmRZeroCnt = FetchObj( [hex2dec('2302'),23] , DataType.float , 'WheelArmRZeroCnt' ) ; 
            app.WheelArmLZeroCnt = FetchObj( [hex2dec('2302'),24] , DataType.float , 'WheelArmLZeroCnt' ) ;
            app.ManDesc = GetDrive( );
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global TmMgrT
            try
                app.Lock = 1 ; 
                TmMgrT.SetCounter('TEST_WARM',-1000) ; 
                delete( app.EvtObj) ;
            %delete( app.Timer ) ;
            catch 
            end
            delete(app)
            
        end

        % Value changed function: DebugModeButton
        function DebugModeButtonValueChanged(app, event)
            app.Lock = 1 ; 
            global DataType 
            value = app.DebugModeButton.Value;
            
            % Before all command motors to kill
            SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Kill motors' ) ;            
            app.OffButton.Value = 1 ; 
            
            % Change debug state
            SendObj( [hex2dec('2222'),6] , double(value) , DataType.long , 'Enter debug mode' ) ;
            
            % Set to lower current at the motor, enable sustained operation
            % SetFloatPar('Geom.PinMotorCurrentAmp',app.Geom.PinMotorCurrentAmp) ; 
            
            app.Lock = 0 ; 
            
        end

        % Selection changed function: PinButtonGroup
        function PinButtonGroupSelectionChanged(app, event)
            global DataType 
            app.Lock = 1 ; 

            %selectedButton = app.PinButtonGroup.SelectedObject;
            if app.DebugmodeCheckBox.Value 
                if app.RetractButton.Value
                    ResetPinDemand_2(app) ; 
                    SendObj( [hex2dec('2222'),5] , 2 , DataType.long , 'Retract' ) ;            
                    SendObj( [hex2dec('2222'),4] , 2 , DataType.long , 'Start motor' ) ;   
                    SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
                    
                elseif app.PushButton.Value
                    ResetPinDemand_2(app) ; 
                    SendObj( [hex2dec('2222'),5] , 1 , DataType.long , 'Push' ) ;            
                    SendObj( [hex2dec('2222'),4] , 2 , DataType.long , 'Start motor' ) ;                                
                    SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
                    
                elseif app.OffButton.Value
                    SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Kill motor' ) ;            
                end
            else
                uiwait(errordlg('This control works only in debug mode, please check debug mode') )  ; 
                ResetPinDemand(app) ; 
            end
            
            app.Lock = 0 ; 

            
        end

        % Button pushed function: CalibrateangleButton
        function CalibrateangleButtonPushed(app, event)
            global DataType 
            app.Lock = 1 ; 

            % Read the relevant calibration from the robot 
            WheelArmLZeroCnt = FetchObj( [hex2dec('2302'),24] , DataType.float , 'WheelArmLZeroCnt' ) ;
            LWheelArmGain = FetchObj( [hex2dec('2302'),27] , DataType.float , 'LWheelArmGain' ) ;
            LWheelArmOffset = FetchObj( [hex2dec('2302'),29] , DataType.float , 'LWheelArmOffset' ) ;
            app.LCalibBackup  = struct('LWheelArmGain',LWheelArmGain,'LWheelArmOffset',LWheelArmOffset,'WheelArmLZeroCnt',WheelArmLZeroCnt) ;           
            
            
            if ~isequal(app.CalibrateangleButton_2.Text,'Calibrate angle')
                uiwait( errordlg('Calibration is active at the other side') ) ; 
                return ; 
            end
            if isequal(app.CalibrateangleButton.Text,'Calibrate angle')  
                app.NextStepButton.Visible = 'on' ;
                app.CalibrateangleButton.Text = 'StopCalib'; 
                app.InLCalib = 1 ; 
                app.LCalib  = struct('State',0,'InCalib',1,'Next',0,'Cntr',0,'CntrMax',5,'NextButtonVisible','on','Direction',1,'ID','LCalib')  ; 
                % Change debug state
                SendObj( [hex2dec('2222'),6] , 1 , DataType.long , 'Enter debug mode' ) ;

                app.InstructionsLabel.Text = ["Set the left wheel arm joint to retracted state","And press Next Step"] ; 
                SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
            else
                app.InstructionsLabel.Text = 'Calibration canceled' ;
                app.InLCalib = 0 ; 
            end
            
            app.Lock = 0 ; 
            
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
           
            if app.InLCalib
                app.LCalib.Next = 1 ; 
            end
            if app.InLPinCalib
                app.LPinCalib.Next = 1 ; 
            end           
            
        end

        % Button pushed function: CalibrateangleButton_2
        function CalibrateangleButton_2Pushed(app, event)

            global DataType 
            app.Lock = 1 ; 

            % Read the relevant calibration from the robot 
            WheelArmRZeroCnt = FetchObj( [hex2dec('2302'),23] , DataType.float , 'WheelArmRZeroCnt' ) ;
            RWheelArmGain = FetchObj( [hex2dec('2302'),26] , DataType.float , 'RWheelArmGain' ) ;
            RWheelArmOffset = FetchObj( [hex2dec('2302'),28] , DataType.float , 'RWheelArmOffset' ) ;
            app.RCalibBackup  = struct('RWheelArmGain',RWheelArmGain,'RWheelArmOffset',RWheelArmOffset,'WheelArmRZeroCnt',WheelArmRZeroCnt) ;           
            
            
            if ~isequal(app.CalibrateangleButton.Text,'Calibrate angle')
                uiwait( errordlg('Calibration is active at the other side') ) ; 
                return ; 
            end
            if isequal(app.CalibrateangleButton_2.Text,'Calibrate angle')  
                app.NextStepButton_2.Visible = 'on' ;
                app.CalibrateangleButton_2.Text = 'StopCalib'; 
                app.InRCalib = 1 ; 
                app.RCalib  = struct('State',0,'InCalib',1,'Next',0,'Cntr',0,'CntrMax',5,'NextButtonVisible','on','Direction',-1,'ID','RCalib')  ; 
                app.InstructionsLabel.Text = ["Set the right wheel arm joint to retracted state","And press Next Step"] ; 
                % Change debug state
                SendObj( [hex2dec('2222'),6] , 1 , DataType.long , 'Enter debug mode' ) ;
                SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
            else
                app.InstructionsLabel.Text = 'Calibration canceled' ;
                app.InRCalib = 0 ; 
            end
            
            app.Lock = 0 ;             
            
        end

        % Button pushed function: NextStepButton_2
        function NextStepButton_2Pushed(app, event)
           
            if app.InRCalib
                app.RCalib.Next = 1 ; 
            end
            if app.InRPinCalib
                app.RPinCalib.Next = 1 ; 
            end           

        end

        % Selection changed function: PinButtonGroup_2
        function PinButtonGroup_2SelectionChanged(app, event)
            %selectedButton = app.PinButtonGroup_2.SelectedObject;

            global DataType 
            app.Lock = 1 ; 

            if app.DebugmodeCheckBox.Value 
                if app.RetractButton_2.Value
                    SendObj( [hex2dec('2222'),5] , 2 , DataType.long , 'Retract' ) ;            
                    SendObj( [hex2dec('2222'),4] , 1 , DataType.long , 'Start motor' ) ;            
                    SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
                    ResetPinDemand(app); 
                elseif app.PushButton_2.Value
                    SendObj( [hex2dec('2222'),5] , 1 , DataType.long , 'Push' ) ;            
                    SendObj( [hex2dec('2222'),4] , 1 , DataType.long , 'Start motor' ) ;                                
                    SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
                    ResetPinDemand(app); 
                elseif app.OffButton_3.Value
                    SendObj( [hex2dec('2222'),4] , 0 , DataType.long , 'Kill motor' ) ;            
                end
            else
                uiwait(errordlg('This control works only in debug mode, please check debug mode') )  ; 
                ResetPinDemand_2(app) ; 
            end
            
            app.Lock = 0 ; 
            
        end

        % Button pushed function: CalibratepinButton
        function CalibratepinButtonPushed(app, event)

            global DataType 
            %global DataType 
            app.Lock = 1 ; 

            if ~isequal(app.CalibratepinButton_2.Text,'Calibrate pin')
                uiwait( errordlg('Calibration is active at the other side') ) ; 
                return ; 
            end
            if isequal(app.CalibratepinButton.Text,'Calibrate pin')  
                app.NextStepButton.Visible = 'on' ;
                app.CalibratepinButton.Text = 'StopCalib'; 
                app.InLPinCalib = 1 ; 
                app.LPinCalib  = struct('State',0,'InCalib',1,'Next',0,'Cntr',0,'CntrMax',5,'NextButtonVisible',...
                    'on','Direction',1,'ID','LCalib','motorfault',0)  ; 
                app.InstructionsLabel.Text = ["Place the robot so that wheel arms can move without load","And press Next Step"] ; 
                % Change debug state
                SendObj( [hex2dec('2222'),6] , 1 , DataType.long , 'Enter debug mode' ) ;
                SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
            else
                app.InstructionsLabel.Text = 'Calibration canceled' ;
                app.InLPinCalib = 0 ; 
            end
            
            app.Lock = 0 ; 
            
            
        end

        % Button pushed function: CalibratepinButton_2
        function CalibratepinButton_2Pushed(app, event)

            global DataType 
            %global DataType 
            app.Lock = 1 ; 

            if ~isequal(app.CalibratepinButton.Text,'Calibrate pin')
                uiwait( errordlg('Calibration is active at the other side') ) ; 
                return ; 
            end
            if isequal(app.CalibratepinButton_2.Text,'Calibrate pin')  
                app.NextStepButton_2.Visible = 'on' ;
                app.CalibratepinButton_2.Text = 'StopCalib'; 
                app.InRPinCalib = 1 ; 
                app.RPinCalib  = struct('State',0,'InCalib',1,'Next',0,'Cntr',0,'CntrMax',5,'NextButtonVisible',...
                    'on','Direction',1,'ID','RCalib','motorfault',0)  ; 
                app.InstructionsLabel.Text = ["Place the robot so that wheel arms can move without load","And press Next Step"] ; 
                % Change debug state
                SendObj( [hex2dec('2222'),6] , 1 , DataType.long , 'Enter debug mode' ) ;
                SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
            else
                app.InRPinCalib = 0 ; 
                app.InstructionsLabel.Text = 'Calibration canceled' ;
            end
            
            app.Lock = 0 ; 
            
        end

        % Callback function
        function RockButtonGroupSelectionChanged(app, event)
            % selectedButton = app.RockButtonGroup.SelectedObject;
            global DataType 
            app.Lock = 1 ; 

            %selectedButton = app.PinButtonGroup.SelectedObject;
            if app.DebugmodeCheckBox.Value 
                if app.ExtendButton.Value
%                     app.OffButton_3.Value = 1 ; 
                    SendObj( [hex2dec('2222'),7] , 1 , DataType.long , 'SysState.Debug.bAllowManualWheelArm' ) ;   
                    SendObj( [hex2dec('2222'),9] , 1 , DataType.long , 'SysState.Debug.bEnableMotionCheats' ) ;   
                    NextMove = FetchObj( [hex2dec('2207'),9] , DataType.long , 'GetAppMotion' ) ; 
                    if ( NextMove == 0)
                        uiwait( msgbox("Nothing to do") ) ;
                        ResetRockingDemand(app);
                    else
                        Target = bitand( 2^32 + NextMove , 15*16) / 16 ; 
                        if ( Target == 0 ) || abs( NextMove ) > 128 
                            if abs( NextMove ) > 128
                                e1 = bitand( NextMove + 2^32 , 65535 ) ; 
                                e2 = bitand( NextMove + 2^32 , 2^16 * 65535 ) / 2^16  ; 
                                uiwait( errordlg({Errtext(e1),Errtext(e2)}  ,'Error in mode entry') ) ;                                
                            else
                                uiwait( errordlg('Error - cant extend or already extended'  ,'Error in mode entry') ) ;
                            end
                            ResetRockingDemand(app);
                        elseif Target==1 
                            uiwait( msgbox({'Already extended','Nothing to do'}) ) ;
                            ResetRockingDemand(app);                           
                        else
                            app.ModeEntryCnt = 3 ; 
                            SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
                            Retcode = SendObj( [hex2dec('2207'),57] , 2 , DataType.long , 'Extend' ) ;            
                            if ( Retcode)
                                [txt,txtelab] = Errtext(Retcode) ; 
                                uiwait( errordlg({txt,txtelab}  ,'Error in mode entry') ) ;
                                ResetRockingDemand(app);
                            else
                                app.InRock = 1  ; 
                            end
                        end
                    end
                    
                elseif app.NormalButton.Value
%                     app.OffButton_3.Value = 1 ; 
                    SendObj( [hex2dec('2222'),9] , 1 , DataType.long , 'SysState.Debug.bEnableMotionCheats' ) ;   
                    NextMove = FetchObj( [hex2dec('2207'),9] , DataType.long , 'GetAppMotion' ) ; 
                    if ( NextMove == 0)
                        uiwait( msgbox("Nothing to do") ) ;
                        ResetRockingDemand(app);
                    else
                        Target = bitand( 2^32 + NextMove , 15*16) / 16 ; 
                        if ( Target == 0 ) || abs( NextMove ) > 128
                            if abs( NextMove ) > 128
                                e1 = bitand( NextMove + 2^32 , 65535 ) ; 
                                e2 = bitand( NextMove + 2^32 , 2^16 * 65535 ) / 2^16  ; 
                                uiwait( errordlg({Errtext(e1),Errtext(e2)}  ,'Error in mode entry') ) ;                                
                            else
                                uiwait( errordlg('Error - cant retract or already retracted'  ,'Error in mode entry') ) ;
                            end
                            ResetRockingDemand(app);
                        elseif Target==2 
                            uiwait( msgbox({'Already retracted','Nothing to do'}) ) ;
                            ResetRockingDemand(app);                           
                        else                    
                            app.ModeEntryCnt = 3 ; 
                            SendObj( [hex2dec('2222'),8] , app.Geom.PinMotorCurrentAmp , DataType.float , 'Pin motor current' ) ;   
                            Retcode = SendObj( [hex2dec('2207'),57] , 1 , DataType.long , 'Retract' ) ;            
                            if ( Retcode)
                                [txt,txtelab] = Errtext(Retcode) ; 
                                uiwait( errordlg({txt,txtelab}  ,'Error in mode entry') ) ;
                                ResetRockingDemand(app);
                            else
                                app.InRock = 1  ; 
                            end
                        end
                    end
                    
                elseif app.OffButton_2.Value
                    ResetRockingDemand(app);
%                     app.InRock = 0  ; 
%                     SendObj( [hex2dec('2207'),57] , 32767 , DataType.long , 'Kill motor' ) ;            
                end
            else
                uiwait(errordlg('This control works only in debug mode, please check debug mode') )  ; 
                ResetRockingDemand(app);
            end
            
            app.Lock = 0 ; 
            
        end

        % Callback function
        function HeadButtonPushed(app, event)
            
            app.ManDesc.CrabCrawl = 0 ; 
            DriveIt(app.ManDesc) ; 
            
        end

        % Callback function
        function RightButtonPushed(app, event)

            app.ManDesc.CrabCrawl = 1 ; 
            DriveIt(app.ManDesc) ; 
            
        end

        % Callback function
        function LeftButtonPushed(app, event)

            app.ManDesc.CrabCrawl = -1 ; 
            DriveIt(app.ManDesc) ;             
            
        end

        % Value changed function: CurrentEditField
        function CurrentEditFieldValueChanged(app, event)
            value = app.CurrentEditField.Value;
            SetMotorCurrent(app,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WheelArmDialogLabel = uilabel(app.UIFigure);
            app.WheelArmDialogLabel.Text = 'Wheel Arm Dialog';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LeftPanel.Title = 'Left';
            app.CommandPanel = uipanel(app.LeftPanel);
            app.CommandPanel.Title = 'Command';
            app.PinButtonGroup = uibuttongroup(app.CommandPanel);
            app.PinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PinButtonGroupSelectionChanged, true);
            app.PinButtonGroup.Title = 'Pin';
            app.OffButton = uiradiobutton(app.PinButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.PushButton = uiradiobutton(app.PinButtonGroup);
            app.PushButton.Text = 'Push';
            app.RetractButton = uiradiobutton(app.PinButtonGroup);
            app.RetractButton.Text = 'Retract';
            app.FeedbackPanel = uipanel(app.LeftPanel);
            app.FeedbackPanel.Title = 'Feedback';
            app.PotEditField_2Label = uilabel(app.FeedbackPanel);
            app.PotEditField_2Label.Text = 'Pot';
            app.PotEditField_2 = uieditfield(app.FeedbackPanel, 'numeric');
            app.ArmangleEditFieldLabel = uilabel(app.FeedbackPanel);
            app.ArmangleEditFieldLabel.Text = 'Arm angle';
            app.ArmangleEditField = uieditfield(app.FeedbackPanel, 'numeric');
            app.PinencoderEditFieldLabel = uilabel(app.FeedbackPanel);
            app.PinencoderEditFieldLabel.Text = 'Pin encoder';
            app.PinencoderEditField = uieditfield(app.FeedbackPanel, 'numeric');
            app.PinengagedCheckBox = uicheckbox(app.FeedbackPanel);
            app.PinengagedCheckBox.Text = 'Pin engaged';
            app.MotoronCheckBox = uicheckbox(app.FeedbackPanel);
            app.MotoronCheckBox.Text = 'Motor on';
            app.MotorfaultCheckBox = uicheckbox(app.FeedbackPanel);
            app.MotorfaultCheckBox.Text = 'Motor fault';
            app.FaultReason = uilabel(app.FeedbackPanel);
            app.PosEditFieldLabel = uilabel(app.FeedbackPanel);
            app.PosEditFieldLabel.Text = 'Pos';
            app.PosEditField = uieditfield(app.FeedbackPanel, 'numeric');
            app.PindisengagedCheckBox = uicheckbox(app.FeedbackPanel);
            app.PindisengagedCheckBox.Text = 'Pin disengaged';
            app.CalibrateangleButton = uibutton(app.LeftPanel, 'push');
            app.CalibrateangleButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateangleButtonPushed, true);
            app.CalibrateangleButton.Text = 'Calibrate angle';
            app.NextStepButton = uibutton(app.LeftPanel, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.CalibratepinButton = uibutton(app.LeftPanel, 'push');
            app.CalibratepinButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratepinButtonPushed, true);
            app.CalibratepinButton.Text = 'Calibrate pin';
            app.RightPanel_2 = uipanel(app.UIFigure);
            app.RightPanel_2.Title = 'Right';
            app.CommandPanel_2 = uipanel(app.RightPanel_2);
            app.CommandPanel_2.Title = 'Command';
            app.PinButtonGroup_2 = uibuttongroup(app.CommandPanel_2);
            app.PinButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @PinButtonGroup_2SelectionChanged, true);
            app.PinButtonGroup_2.Title = 'Pin';
            app.OffButton_3 = uiradiobutton(app.PinButtonGroup_2);
            app.OffButton_3.Text = 'Off';
            app.OffButton_3.Value = true;
            app.PushButton_2 = uiradiobutton(app.PinButtonGroup_2);
            app.PushButton_2.Text = 'Push';
            app.RetractButton_2 = uiradiobutton(app.PinButtonGroup_2);
            app.RetractButton_2.Text = 'Retract';
            app.FeedbackPanel_2 = uipanel(app.RightPanel_2);
            app.FeedbackPanel_2.Title = 'Feedback';
            app.PotEditFieldLabel = uilabel(app.FeedbackPanel_2);
            app.PotEditFieldLabel.Text = 'Pot';
            app.PotEditField = uieditfield(app.FeedbackPanel_2, 'numeric');
            app.ArmangleEditField_2Label = uilabel(app.FeedbackPanel_2);
            app.ArmangleEditField_2Label.Text = 'Arm angle';
            app.ArmangleEditField_2 = uieditfield(app.FeedbackPanel_2, 'numeric');
            app.PinencoderEditField_2Label = uilabel(app.FeedbackPanel_2);
            app.PinencoderEditField_2Label.Text = 'Pin encoder';
            app.PinencoderEditField_2 = uieditfield(app.FeedbackPanel_2, 'numeric');
            app.PinengagedCheckBox_2 = uicheckbox(app.FeedbackPanel_2);
            app.PinengagedCheckBox_2.Text = 'Pin engaged';
            app.MotoronCheckBox_2 = uicheckbox(app.FeedbackPanel_2);
            app.MotoronCheckBox_2.Text = 'Motor on';
            app.MotorfaultCheckBox_2 = uicheckbox(app.FeedbackPanel_2);
            app.MotorfaultCheckBox_2.Text = 'Motor fault';
            app.FaultReason_2 = uilabel(app.FeedbackPanel_2);
            app.PosEditField_2Label = uilabel(app.FeedbackPanel_2);
            app.PosEditField_2Label.Text = 'Pos';
            app.PosEditField_2 = uieditfield(app.FeedbackPanel_2, 'numeric');
            app.PindisengagedCheckBox_2 = uicheckbox(app.FeedbackPanel_2);
            app.PindisengagedCheckBox_2.Text = 'Pin disengaged';
            app.NextStepButton_2 = uibutton(app.RightPanel_2, 'push');
            app.NextStepButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_2Pushed, true);
            app.NextStepButton_2.Text = 'Next Step';
            app.CalibrateangleButton_2 = uibutton(app.RightPanel_2, 'push');
            app.CalibrateangleButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalibrateangleButton_2Pushed, true);
            app.CalibrateangleButton_2.Text = 'Calibrate angle';
            app.CalibratepinButton_2 = uibutton(app.RightPanel_2, 'push');
            app.CalibratepinButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalibratepinButton_2Pushed, true);
            app.CalibratepinButton_2.Text = 'Calibrate pin';
            app.InstructionsLabel = uilabel(app.UIFigure);
            app.InstructionsLabel.Text = 'Instructions';
            app.DebugPanel = uipanel(app.UIFigure);
            app.DebugPanel.Title = 'Debug';
            app.DebugModeButton = uibutton(app.DebugPanel, 'state');
            app.DebugModeButton.ValueChangedFcn = createCallbackFcn(app, @DebugModeButtonValueChanged, true);
            app.DebugModeButton.Text = 'Debug Mode';
            app.DebugmodeCheckBox = uicheckbox(app.DebugPanel);
            app.DebugmodeCheckBox.Enable = 'off';
            app.DebugmodeCheckBox.Text = 'Debug mode';
            app.CurrentEditFieldLabel = uilabel(app.DebugPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.DebugPanel, 'numeric');
            app.CurrentEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentEditFieldValueChanged, true);
            app.CalibrationdataPanel = uipanel(app.UIFigure);
            app.CalibrationdataPanel.Title = 'Calibration data ';
            app.CalibexistsCheckBox = uicheckbox(app.CalibrationdataPanel);
            app.CalibexistsCheckBox.Text = 'Calib exists';
            app.CalibIDEditFieldLabel = uilabel(app.CalibrationdataPanel);
            app.CalibIDEditFieldLabel.Text = 'Calib ID';
            app.CalibIDEditField = uieditfield(app.CalibrationdataPanel, 'numeric');
            app.CalibIDEditField.ValueDisplayFormat = '%d';
            app.CalibdateEditFieldLabel = uilabel(app.CalibrationdataPanel);
            app.CalibdateEditFieldLabel.Text = 'Calib date';
            app.CalibdateEditField = uieditfield(app.CalibrationdataPanel, 'numeric');
            app.CalibdateEditField.ValueDisplayFormat = '%d';
            app.ExceptionLabel = uilabel(app.UIFigure);
            app.ExceptionLabel.Text = 'Exception';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WheelArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end