classdef calibManip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShoulderSliderLabel     matlab.ui.control.Label
        ShoulderSlider          matlab.ui.control.Slider
        ElbowSlider             matlab.ui.control.Slider
        WristSlider             matlab.ui.control.Slider
        ShoulderSliderLabel_2   matlab.ui.control.Label
        ShoulderSliderLabel_3   matlab.ui.control.Label
        ShoulderLabel           matlab.ui.control.Label
        EditShoulderOffset      matlab.ui.control.NumericEditField
        OffsetsLabel            matlab.ui.control.Label
        ElbowLabel              matlab.ui.control.Label
        EditElbowOffset         matlab.ui.control.NumericEditField
        EditWristOffset         matlab.ui.control.NumericEditField
        WristLabel_2            matlab.ui.control.Label
        CommandsdegreesLabel    matlab.ui.control.Label
        SLabel                  matlab.ui.control.Label
        EditShoulderActPos      matlab.ui.control.NumericEditField
        ELabel                  matlab.ui.control.Label
        EditElbowActPos         matlab.ui.control.NumericEditField
        EditWristActPos         matlab.ui.control.NumericEditField
        WristActPos             matlab.ui.control.Label
        ActualpositionLabel     matlab.ui.control.Label
        ShoulderLabel_3         matlab.ui.control.Label
        ElbowLabel_3            matlab.ui.control.Label
        CommandpositionLabel    matlab.ui.control.Label
        EditShoulderCmd         matlab.ui.control.NumericEditField
        EditElbowCmd            matlab.ui.control.NumericEditField
        WristActPos_2           matlab.ui.control.Label
        EditWristCmd            matlab.ui.control.NumericEditField
        FixButton               matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        IDEditFieldLabel        matlab.ui.control.Label
        IDEditField             matlab.ui.control.NumericEditField
        ManipulatoroffsetcalibratorLabel  matlab.ui.control.Label
        OnButton                matlab.ui.control.StateButton
        OnLampLabel             matlab.ui.control.Label
        OnLampShoulder          matlab.ui.control.Lamp
        OnLamp_2Label           matlab.ui.control.Label
        OnLampElbow             matlab.ui.control.Lamp
        OnLamp_3Label           matlab.ui.control.Label
        OnLampWrist             matlab.ui.control.Lamp
        VPSVEditFieldLabel      matlab.ui.control.Label
        VPSVEditField           matlab.ui.control.NumericEditField
        BatteryVEditFieldLabel  matlab.ui.control.Label
        BatteryVEditField       matlab.ui.control.NumericEditField
        IndividualLampLabel     matlab.ui.control.Label
        IndividualLamp          matlab.ui.control.Lamp
        DateEditFieldLabel      matlab.ui.control.Label
        DateEditField           matlab.ui.control.NumericEditField
        NowButton               matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
    end


    properties (Access = private)
        WindSpeedTimer % Timer object
        ShoulderPos
        ElbowPos
        WristPos
        ShoulderCmd 
        WristCmd 
        ElbowCmd
        MotorOnShoulder
        MotorOnElbow
        MotorOnWrist
        Individual
        CalibFieldNames
        CalibInit
        ShulderCenter
        ElbowCenter
        WristCenter
        PositionScale
        r2d
        d2r
        bubu
        IsPdResponding % Tell if PD responds to communication 
        DataType % Data types for CAN communications 
    end

    methods (Access = private)
    
        function WindSpeedTimerFcn(app, varargin)
            % get current time from timer's event data
            % tnow = datetime(event.Data.time);
            % global DataType ; 
            CommFunc = [] ; 
            try
                app.ShoulderPos = Precision(app,app.r2d * FetchObj( [hex2dec('2103'),14] , app.DataType.float ,'Shoulder position',CommFunc),4) ;
                app.EditShoulderActPos.Value = app.ShoulderPos;
                app.EditShoulderActPos.BackgroundColor = [0,1,1] ; 
            catch 
                app.EditShoulderActPos.BackgroundColor = [1,0,0] ; 
            end 
            try
                app.ElbowPos = Precision(app,app.r2d * FetchObj( [hex2dec('2103'),24] , app.DataType.float ,'Elbow position',CommFunc),4) ;
                app.EditElbowActPos.Value = app.ElbowPos;
                app.EditElbowActPos.BackgroundColor = [0,1,1] ; 
            catch 
                app.EditElbowActPos.BackgroundColor = [1,0,0] ; 
            end 
            try
                app.WristPos = Precision(app, app.r2d * FetchObj( [hex2dec('2103'),34] , app.DataType.float ,'Wrist position',CommFunc),4) ;
                app.EditWristActPos.Value = app.WristPos;
                app.EditWristActPos.BackgroundColor = [0,1,1] ; 
            catch 
                app.EditWristActPos.BackgroundColor = [1,0,0] ; 
            end      
            
            try
                status = FetchObj( [hex2dec('2104'),2] , app.DataType.long ,'Status',CommFunc) ;
                if ( status < 0 ) 
                    status = status + 2^32 ; 
                end 
                
                app.MotorOnShoulder = bitand( status , 512) / 512 ; 
                app.MotorOnElbow = bitand( status , 1024) / 1024 ; 
                app.MotorOnWrist = bitand( status , 2048) / 2048 ; 
                app.Individual   = bitand( status , 2^15) / 2^15 ; 
                
                app.OnLampShoulder.Color = GetLampColor( app, app.MotorOnShoulder) ; 
                app.OnLampElbow.Color = GetLampColor( app, app.MotorOnElbow) ; 
                app.OnLampWrist.Color = GetLampColor( app, app.MotorOnWrist) ; 
                app.IndividualLamp.Color = GetLampColor( app,app.Individual ) ; 
            catch 
                app.OnLampShoulder.Color = GetLampColor( app, -1) ; 
                app.OnLampElbow.Color = GetLampColor( app, -1) ; 
                app.OnLampWrist.Color = GetLampColor( app, -1) ; 
                app.IndividualLamp.Color = GetLampColor( app,app.Individual,-1) ; 
            end 
            
            try
                v24 = Precision(app,FetchObj( [hex2dec('2204'),1] , app.DataType.float ,'24V',CommFunc),4)  ;
                app.VPSVEditField.Value = v24 ;
                app.VPSVEditField.BackgroundColor = [0,1,1] ;
                v54 = Precision(app,FetchObj( [hex2dec('2204'),2] , app.DataType.float ,'Vservo',CommFunc),4)  ;
                app.BatteryVEditField.Value = v54 ; 
                app.BatteryVEditField.BackgroundColor = [0,1,1] ;
            catch
                app.VPSVEditField.BackgroundColor = [1,0,0] ;
                app.BatteryVEditField.BackgroundColor = [1,0,0] ;
            end
% SendObj( [hex2dec('2207'),10] , CrabCrawl , app.DataType.float ,'CrabCrawl cmd') ;
            
            %             app.EditShoulderActPos.Value  = app.bubu ; 
            app.bubu = app.bubu+1 ; 
        end        

        function SetCmd(app )
            
            if nargin < 2 
                tx = 0 ;
            end 
            % global DataType
            CommFunc = []; 

            value = sat( app, app.ShoulderCmd, 90) ;
            value = Precision(app, value ,4)  ; 
            app.ShoulderCmd = value ; 
            app.EditShoulderCmd.Value = value ;  
            app.ShoulderSlider.Value = sat(app,value,4)  ;            
            
            value = sat( app, app.ElbowCmd, 90) ;
            value = Precision(app, value, 4) ; 
            app.ElbowCmd = value ; 
            app.EditElbowCmd.Value = value ;
            app.ElbowSlider.Value = sat( app, value, 4)  ;
            
            value = sat( app , app.WristCmd, 90) ;
            value = Precision(app, value, 4) ; 
            app.WristCmd = value ; 
            app.EditWristCmd.Value = value ;
            app.WristSlider.Value = sat( app ,value, 4)  ;
            
            if app.Individual  
                
                fac = 1000 * pi / 180 ;
                if app.MotorOnShoulder
                    SendObj( [hex2dec('2103'),11] , app.ShoulderCmd * fac , app.DataType.long ,'Shoulder cmd') ;
                end
                if app.MotorOnElbow
                    SendObj( [hex2dec('2103'),21] , app.ElbowCmd * fac , app.DataType.long ,'Elbow cmd') ;
                end
                if app.MotorOnWrist
                    SendObj( [hex2dec('2103'),31] , app.WristCmd * fac , app.DataType.long ,'Wrist cmd') ;
                end
            end
        end
        
        function value = Precision(app,value,n)
            value = round( value * 10^n) / 10^n ; 
            
        end
        
        function [s,e,w] = GetCmd(app)
            % global DataType
            CommFunc = []; 
            try 
                s = Precision(app,app.r2d * FetchObj( [hex2dec('2103'),16] , app.DataType.float ,'Shoulder cmd',CommFunc),4) ;
                e = Precision(app,app.r2d * FetchObj( [hex2dec('2103'),26] , app.DataType.float ,'Elbow cmd',CommFunc),4) ;
                w = Precision(app,app.r2d * FetchObj( [hex2dec('2103'),36] , app.DataType.float ,'Wrist cmd',CommFunc),4) ;
            catch
                s = app.ShoulderCmd ;
                e = app.ElbowCmd ;
                w = app.WristCmd ;
            end 
            if nargout == 0 
                app.ShoulderCmd = s ;
                app.ElbowCmd = e ;
                app.WristCmd = w ;
                SetCmd(app); 
            end 
        end
        
        function x = sat(app,x,s)
            if x > s 
                x = s ; 
            elseif x < -s
                    x = -s ; 
            end
            
        end
        
        function rgb = GetLampColor(app, n )
            if n < 0 
                rgb = [1 ,0,0] ; % Red 
            elseif n ==0 
                rgb = [1 , 1, 1] * 0.7 ; % Grey 
            elseif n==1
                rgb = [0 , 1,0] ; % Green 
            elseif n==10
                rgb = [1,1,1] * 0.7 ; % Grey 
            elseif n==11
                rgb = [0,0,1]  ; % Blue 
            else
                rgb = [1 , 1,0] ; % Yellow 
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create timer object
            % Verify initialization in the correct workspace 
            evalin('base', 'AtpStart') ; 
            
            % Test communication  with PD 
            global DataType ;
            
            app.DataType = DataType ; 
            global TargetCanId 
            CommFunc = [] ; % Default 
            
            is126 = 0 ; 
            app.IsPdResponding = 0 ; 
            try
                if isequal(TargetCanId,126) 
                    is126 = 1; 
                end
            catch
            end
            if ~is126 
                app.TextArea.Value = 'Bad startup directory, Start from PDTest'; 
                app.TextArea.BackgroundColor = [0,0,0] ; app.TextArea.FontColor = [1,0,0]; 
            else
                [statusComPd,~,errPd] = FetchObj( [hex2dec('2301'),5] , app.DataType.long ,'Project type',CommFunc) ;
                [~,~,errLp] = FetchObj( [hex2dec('2301'),5,124] , app.DataType.long ,'Project type',CommFunc) ;
                
                if ~isempty(errPd) 
                    if isempty( errLp ) 
                        app.TextArea.Value = 'You are connected to CAN1, please connect to CAN2 instead and restart app'; 
                        app.TextArea.BackgroundColor = [1,0,0] ; app.TextArea.FontColor = [1,1,1];  
                    else
                        app.TextArea.Value = 'CAN does not respond, please connect to CAN#2'; 
                        app.TextArea.BackgroundColor = [0,0,1] ; app.TextArea.FontColor = [1,1,1];  
                    end
                else
                    if hex2dec('8200')==statusComPd
                        app.IsPdResponding = 1 ; 
                        [VerPd,~,~] = FetchObj( [hex2dec('2301'),4] , app.DataType.long ,'Project version',CommFunc) ;
                        yr = fix(VerPd/2^20) ; mon = bitand(fix(VerPd/2^16),15) ; day = bitand(fix(VerPd/2^8),63) ; subver = bitand(VerPd,127);
                        montable = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec','BadMonh'}; 
                        % daytable = {'Sun','Mon','Tue','Wed','Thu','Fri','Sat','BadDay'}; 
                        verstr = ['PD Version: ',num2str(yr),' : ', montable{min(mon,13)},' : ', num2str(day) ,' Subver:',num2str(subver)]; 
                        app.TextArea.Value = verstr; 
                        app.TextArea.BackgroundColor = [0,1,1] ; app.TextArea.FontColor = [0,0,0];                     
                    else
                        app.TextArea.Value = 'Expected PD software, found something else'; 
                        app.TextArea.BackgroundColor = [0,0,1] ; app.TextArea.FontColor = [1,1,1];  
                    end
                end
            end
            
            if ~app.IsPdResponding
                app.OnButton.Visible = 0 ; 
                app.NowButton.Visible = 0 ; 
                app.FixButton.Visible = 0 ;
                app.SaveButton.Visible = 0 ; 
                app.ShoulderSlider.Visible = 0 ; 
                app.ElbowSlider.Visible = 0 ; 
                app.WristSlider.Visible = 0 ; 
                return ; 
            end
            
            app.bubu = 0 ; 
            app.r2d  = 180 / pi ;
            app.d2r  = pi / 180 ;
            
            app.ShoulderCmd = 0 ; 
            app.WristCmd = 0 ; 
            app.ElbowCmd = 0 ; 
            app.Individual = 0 ; 
            app.CalibFieldNames = {'RDoorCenter' , 'RDoorGainFac' , 'LDoorCenter' , 'LDoorGainFac' ,'ShoulderCenter' , 'ElbowCenter' , 'WristCenter' ,...
                'V36Gain','V36Offset', ...
                 'Spare10' , 'Spare11' , 'Spare12' , 'Spare13','Spare14','Spare15','Spare16','Spare17','Spare18'} ;
            try 
                GetCmd(app) ; 
                app.CalibInit = SaveCalibPD ( [], [] , app.CalibFieldNames ) ;         
            catch
                s = datestr(now,'ddmmyyyy');
                app.CalibInit.CalibDate = str2num(s) ; %#ok<ST2NM> 
                app.CalibInit.CalibData = 0          ; 
            end 
            
            app.ShulderCenter = GetFloatParPDLocal('ManGeo.ShulderCenter') ;  
            app.ElbowCenter = GetFloatParPDLocal('ManGeo.ElbowCenter') ;  
            app.WristCenter = GetFloatParPDLocal('ManGeo.WristCenter') ;  
            
            app.PositionScale(1) = GetFloatParPDLocal('DynStat24[0].PositionScale') ; 
            app.PositionScale(2) = GetFloatParPDLocal('DynStat24[1].PositionScale') ; 
            app.PositionScale(3) = GetFloatParPDLocal('DynStat24[2].PositionScale') ; 
            
            app.DateEditField.Value = app.CalibInit.CalibDate  ; 
            app.IDEditField.Value = app.CalibInit.CalibData ;
            
            
            try
                status = FetchObj( [hex2dec('2104'),2] , app.DataType.long ,'Status',CommFunc) ;
                if ( status < 0 ) 
                    status = status + 2^32 ; 
                end 
                
                app.MotorOnShoulder = bitand( status , 512) / 512 ; 
                app.MotorOnElbow = bitand( status , 1024) / 1024 ; 
                app.MotorOnWrist = bitand( status , 2048) / 2048 ; 
                app.Individual   = bitand( status , 2^15) / 2^15 ; 
                if (app.Individual &&  app.MotorOnShoulder && app.MotorOnElbow && app.MotorOnWrist) 
                    app.OnButton.Value = 1 ; 
                end 
            catch 
            end
            
            app.WindSpeedTimer = timer(...
            'ExecutionMode', 'fixedSpacing', ...      % Run timer repeatedly
            'Period', 0.2, ...                       % Period 
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.WindSpeedTimerFcn);   % Callback that runs every period
        start(app.WindSpeedTimer);
        end

        % Value changed function: EditElbowCmd
        function EditElbowCmdValueChanged(app, event)
            app.ElbowCmd = app.EditElbowCmd.Value;
            SetCmd(app); 
            
        end

        % Value changed function: EditShoulderCmd
        function EditShoulderCmdValueChanged(app, event)
            app.ShoulderCmd = app.EditShoulderCmd.Value;
            SetCmd(app); 
        end

        % Value changed function: EditWristCmd
        function EditWristCmdValueChanged(app, event)
            app.WristCmd = app.EditWristCmd.Value;
            SetCmd(app); 
        end

        % Value changed function: ElbowSlider
        function ElbowSliderValueChanged(app, event)
            app.ElbowCmd = app.ElbowSlider.Value;
            SetCmd(app); 
        end

        % Button pushed function: FixButton
        function FixButtonPushed(app, event)
            % Get the values for fixation 
            calibRslt = [] ; % Kadawa
            CommFunc  = [] ; 
            % global DataType
            
            dcd = questdlg( 'Do you want this as the [0,0,0] position?', 'Please approve','Yes', 'No' , 'No');  
            if isequal(dcd , 'Yes')                    
                % RNeckPotCenter = 6 , LNeckPotCenter = 7 ,
                % RNeckPotGainFac = 8 , LNeckPotGainFac = 9 ; 
                try
                    % Get the calibration offsets now 
                    os = FetchObj( [hex2dec('2103'),2] , app.DataType.long ,'Shoulder offset',CommFunc)*app.PositionScale(1);
                    oe = FetchObj( [hex2dec('2103'),3] , app.DataType.long ,'Elbow offset',CommFunc)*app.PositionScale(2);
                    ow = FetchObj( [hex2dec('2103'),4] , app.DataType.long ,'Wrist offset',CommFunc)*app.PositionScale(3);
                    
                    % The positions now should be added to the offset 
                    ps = FetchObj( [hex2dec('2103'),14] , app.DataType.float ,'Shoulder position',CommFunc)  ;
                    pe = FetchObj( [hex2dec('2103'),24] , app.DataType.float ,'Elbow position',CommFunc)  ;
                    pw = FetchObj( [hex2dec('2103'),34] , app.DataType.float ,'Wrist position',CommFunc)  ;
                    
                    app.EditShoulderOffset.Value = (os+ps) * app.r2d; 
                    app.EditElbowOffset.Value = (oe+pe) * app.r2d; 
                    app.EditWristOffset.Value = (ow+pw) * app.r2d; 
                    
                    
                    SendObj( [hex2dec('2302'),1] ,hex2dec('12345678') , app.DataType.long , 'Password for calib') ;
            
                    SendObj( [hex2dec('2302'),31] ,1 , app.DataType.float , 'Initialize programming calibration from flash') ;
                
                    SendObj( [hex2dec('2302'),6] ,(os+ps)/app.PositionScale(1) , app.DataType.float , 'ShoulderCenter calib') ;
                    SendObj( [hex2dec('2302'),7] ,(oe+pe)/app.PositionScale(2) , app.DataType.float , 'ElbowCenter calib') ;
                    SendObj( [hex2dec('2302'),8] ,(ow+pw)/app.PositionScale(3) , app.DataType.float , 'WristCenter calib') ;
    
                    SendObj( [hex2dec('2302'),20] ,app.DateEditField.Value , app.DataType.float , 'CalibDate') ;
                    SendObj( [hex2dec('2302'),21] ,app.IDEditField.Value , app.DataType.float , 'CalibData') ;
                    
                    
                    % apply calibration 
                    SendObj( [hex2dec('2302'),29] ,0 , app.DataType.float , 'Apply calibration') ;
                    good = 1 ;
                catch 
                    uiwait( msgbox('Calibration data transfer failed' , 'Probably parameter out of range') ) ;  
                end 
            else
                uiwait( msgbox('Not gonna happen' , 'Aborted by user') ) ;  
            end
            
        end

        % Button pushed function: NowButton
        function NowButtonPushed(app, event)
            s = datestr(now,'ddmmyyyy');
            app.CalibInit.CalibDate = str2num(s) ; 
            app.DateEditField.Value = app.CalibInit.CalibDate  ; 
        end

        % Value changed function: OnButton
        function OnButtonValueChanged(app, event)
            value = app.OnButton.Value;
            if value 
                try 
                    SendObj( [hex2dec('2103'),7] , hex2dec('1234') , app.DataType.long ,'Set individual') ;
                    SendObj( [hex2dec('2103'),10] , 1 , app.DataType.long ,'Set motor ON shoulder') ;
                    SendObj( [hex2dec('2103'),20] , 1 , app.DataType.long ,'Set motor ON elbow') ;
                    SendObj( [hex2dec('2103'),30] , 1 , app.DataType.long ,'Set motor ON wrist') ;
                     GetCmd(app);  
                catch 
                    app.OnButton.Value = 0 ; 
                    SendObj( [hex2dec('2103'),8] , hex2dec('1234') , app.DataType.long ,'Set non-individual') ;
                end 
            else
                    SendObj( [hex2dec('2103'),10] , 0 , app.DataType.long ,'Set motor Off shoulder') ;
                    SendObj( [hex2dec('2103'),20] , 0 , app.DataType.long ,'Set motor Off elbow') ;
                    SendObj( [hex2dec('2103'),30] , 0 , app.DataType.long ,'Set motor Off wrist') ;
                    SendObj( [hex2dec('2103'),8] , hex2dec('1234') , app.DataType.long ,'Set non-individual') ;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            calibRslt = [] ; % Kadawa
            CommFunc = [] ; 
            
            dcd = questdlg( 'Do you want to burn the offsets you fixed?', 'Please approve','Yes', 'No' , 'No');  
            if isequal(dcd , 'Yes')                    
                % RNeckPotCenter = 6 , LNeckPotCenter = 7 ,
                % RNeckPotGainFac = 8 , LNeckPotGainFac = 9 ; 
                try
                    % Get the calibration offsets now 
                    SendObj( [hex2dec('2302'),21] ,app.IDEditField.Value , app.DataType.long , 'CalibData') ;
                    SendObj( [hex2dec('2302'),33] ,app.IDEditField.Value , app.DataType.long , 'CalibData',[],1000) ;
                    uiwait( msgbox('Save to flash success' , 'Haleluja') ) ;  
                catch 
                    uiwait( msgbox('Save to flash failed' , 'Kadawa') ) ;  
                end 
            else
                uiwait( msgbox('Not gonna happen' , 'Aborted by user') ) ;  
            end              
        end

        % Value changed function: ShoulderSlider
        function ShoulderSliderValueChanged(app, event)
            app.ShoulderCmd = app.ShoulderSlider.Value;
            SetCmd(app); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsPdResponding
                stop(app.WindSpeedTimer);
                delete(app.WindSpeedTimer);
            end
            delete(app);           
        end

        % Value changed function: WristSlider
        function WristSliderValueChanged(app, event)
            app.WristCmd = app.WristSlider.Value;
            SetCmd(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ShoulderSliderLabel = uilabel(app.UIFigure);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.UIFigure);
            app.ShoulderSlider.Limits = [-4 4];
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderSliderValueChanged, true);
            app.ElbowSlider = uislider(app.UIFigure);
            app.ElbowSlider.Limits = [-4 4];
            app.ElbowSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowSliderValueChanged, true);
            app.WristSlider = uislider(app.UIFigure);
            app.WristSlider.Limits = [-4 4];
            app.WristSlider.ValueChangedFcn = createCallbackFcn(app, @WristSliderValueChanged, true);
            app.ShoulderSliderLabel_2 = uilabel(app.UIFigure);
            app.ShoulderSliderLabel_2.Text = 'Elbow';
            app.ShoulderSliderLabel_3 = uilabel(app.UIFigure);
            app.ShoulderSliderLabel_3.Text = 'Wrist';
            app.ShoulderLabel = uilabel(app.UIFigure);
            app.ShoulderLabel.Text = 'Shoulder';
            app.EditShoulderOffset = uieditfield(app.UIFigure, 'numeric');
            app.OffsetsLabel = uilabel(app.UIFigure);
            app.OffsetsLabel.Text = 'Offsets';
            app.ElbowLabel = uilabel(app.UIFigure);
            app.ElbowLabel.Text = 'Elbow';
            app.EditElbowOffset = uieditfield(app.UIFigure, 'numeric');
            app.EditWristOffset = uieditfield(app.UIFigure, 'numeric');
            app.WristLabel_2 = uilabel(app.UIFigure);
            app.WristLabel_2.Text = 'Wrist';
            app.CommandsdegreesLabel = uilabel(app.UIFigure);
            app.CommandsdegreesLabel.Text = 'Commands (degrees)';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S';
            app.EditShoulderActPos = uieditfield(app.UIFigure, 'numeric');
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Text = 'E';
            app.EditElbowActPos = uieditfield(app.UIFigure, 'numeric');
            app.EditWristActPos = uieditfield(app.UIFigure, 'numeric');
            app.WristActPos = uilabel(app.UIFigure);
            app.WristActPos.Text = 'W';
            app.ActualpositionLabel = uilabel(app.UIFigure);
            app.ActualpositionLabel.Text = 'Actual position';
            app.ShoulderLabel_3 = uilabel(app.UIFigure);
            app.ShoulderLabel_3.Text = 'Shoulder';
            app.ElbowLabel_3 = uilabel(app.UIFigure);
            app.ElbowLabel_3.Text = 'Elbow';
            app.CommandpositionLabel = uilabel(app.UIFigure);
            app.CommandpositionLabel.Text = 'Command position';
            app.EditShoulderCmd = uieditfield(app.UIFigure, 'numeric');
            app.EditShoulderCmd.ValueChangedFcn = createCallbackFcn(app, @EditShoulderCmdValueChanged, true);
            app.EditElbowCmd = uieditfield(app.UIFigure, 'numeric');
            app.EditElbowCmd.ValueChangedFcn = createCallbackFcn(app, @EditElbowCmdValueChanged, true);
            app.WristActPos_2 = uilabel(app.UIFigure);
            app.WristActPos_2.Text = 'Wrist';
            app.EditWristCmd = uieditfield(app.UIFigure, 'numeric');
            app.EditWristCmd.ValueChangedFcn = createCallbackFcn(app, @EditWristCmdValueChanged, true);
            app.FixButton = uibutton(app.UIFigure, 'push');
            app.FixButton.ButtonPushedFcn = createCallbackFcn(app, @FixButtonPushed, true);
            app.FixButton.Text = 'Fix';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.IDEditFieldLabel = uilabel(app.UIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ManipulatoroffsetcalibratorLabel = uilabel(app.UIFigure);
            app.ManipulatoroffsetcalibratorLabel.Text = 'Manipulator offset calibrator';
            app.OnButton = uibutton(app.UIFigure, 'state');
            app.OnButton.ValueChangedFcn = createCallbackFcn(app, @OnButtonValueChanged, true);
            app.OnButton.Text = 'On';
            app.OnLampLabel = uilabel(app.UIFigure);
            app.OnLampLabel.Text = 'On';
            app.OnLampShoulder = uilamp(app.UIFigure);
            app.OnLamp_2Label = uilabel(app.UIFigure);
            app.OnLamp_2Label.Text = 'On';
            app.OnLampElbow = uilamp(app.UIFigure);
            app.OnLamp_3Label = uilabel(app.UIFigure);
            app.OnLamp_3Label.Text = 'On';
            app.OnLampWrist = uilamp(app.UIFigure);
            app.VPSVEditFieldLabel = uilabel(app.UIFigure);
            app.VPSVEditFieldLabel.Text = '24V PS (V)';
            app.VPSVEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatteryVEditFieldLabel = uilabel(app.UIFigure);
            app.BatteryVEditFieldLabel.Text = 'Battery (V)';
            app.BatteryVEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndividualLampLabel = uilabel(app.UIFigure);
            app.IndividualLampLabel.Text = 'Individual';
            app.IndividualLamp = uilamp(app.UIFigure);
            app.DateEditFieldLabel = uilabel(app.UIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.UIFigure, 'numeric');
            app.DateEditField.ValueDisplayFormat = '%d';
            app.NowButton = uibutton(app.UIFigure, 'push');
            app.NowButton.ButtonPushedFcn = createCallbackFcn(app, @NowButtonPushed, true);
            app.NowButton.Text = 'Now';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Disconnected'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibManip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end