classdef TriggerSel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RecordertriggerselectorLabel  matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        SignalindexEditFieldLabel     matlab.ui.control.Label
        SignalindexEditField          matlab.ui.control.NumericEditField
        SignalnameEditFieldLabel      matlab.ui.control.Label
        SignalnameEditField           matlab.ui.control.EditField
        DropDownLabel                 matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        TriggerEditFieldLabel         matlab.ui.control.Label
        TriggerPercentEditField       matlab.ui.control.NumericEditField
        AcceptButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        ValueEditFieldLabel           matlab.ui.control.Label
        ValueEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        RecNames % Description
        RecStruct % Description
        RecGroups % Description
        RecHelps % Description
        RecCpu % Description
        UniqueGroups % Description
        UniqueGroupNodes % Description
        Sig2NodeMap % Description
        RecFlags % Description
        Descriptor % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg, recstr)
            %global RecStruct %#ok<GVMIS> 

            if nargin < 2 
                arg = struct('TriggerType','Immediate','TrigVal',0,'TrigPercent',10); 
            end 

            app.TriggerPercentEditField.Value = arg.TrigPercent ; 
            app.DropDown.Value = arg.TriggerType ; 
            app.ValueEditField.Value = arg.TrigVal ; 

            app.RecStruct = recstr ; 
            app.Descriptor = [] ; 
            
            firstRec = app.RecStruct.SigList{1}; 
            nFieldsInRec = length(firstRec) ;
            puk=[app.RecStruct.SigList{:}];
            
            if isfield(app.RecStruct,'SigList2') 
                Cpu2Exist = 1 ; 
            else
                Cpu2Exist = 0; 
            end
            
            if Cpu2Exist
                firstRec2 = app.RecStruct.SigList2{1}; 
                nFieldsInRec2 = length(firstRec2) ;
                puk2=[app.RecStruct.SigList2{:}];
            else
                nFieldsInRec2 = 0 ; 
                puk2=[]; 
            end
            app.RecFlags = [puk(1:nFieldsInRec:end),puk2(1:nFieldsInRec2:end)];
            app.RecNames = [puk(2:nFieldsInRec:end),puk2(2:nFieldsInRec2:end)];


            if Cpu2Exist
                gr2 = puk2(3:nFieldsInRec2:end) ; 
                for cnt = 1:length(gr2) 
                    gr2{cnt} = [gr2{cnt},'_2'] ; 
                end 
                app.RecGroups = [puk(3:nFieldsInRec:end),gr2 ] ; 
                app.RecHelps = [puk(4:nFieldsInRec:end),puk2(4:nFieldsInRec2:end)];
                app.RecCpu = [ones(1,fix(length(puk)/4) ),2 * ones(1,fix(length(puk2)/4) )] ;
            else
                app.RecGroups = puk(3:nFieldsInRec:end) ; 
                app.RecHelps =  puk(4:nFieldsInRec:end) ; 
                app.RecCpu = ones(1,fix(length(puk)/4) ) ; 
            end


            app.UniqueGroups = unique(app.RecGroups) ;
            nGroups = length(app.UniqueGroups);
            nTotalSig = length(app.RecFlags) ; 
            app.UniqueGroupNodes = cell( 1, nGroups ) ; 
            app.Sig2NodeMap = cell( 1 , nTotalSig ) ; 
            
            for cnt = 1:nGroups   
                sind  = find ( strcmp( app.RecGroups,app.UniqueGroups{cnt})); 
                sname = app.RecNames(sind) ; 
                shelp = app.RecHelps(sind) ; 
                [sname,ssort]= sort(sname) ; 
                sind = sind( ssort) ; 
                shelp = shelp(ssort);

                Node1 = uitreenode(app.Tree,'Text',app.UniqueGroups{cnt} ,'NodeData',sind) ; 

                % Node1 = CheckboxTreeNode('Name',app.UniqueGroups{cnt},'Parent',app.t.Root ,'UserData', sind  );
                app.UniqueGroupNodes{cnt} = Node1 ; 
                nSig = length(sind) ; 
                for ind = 1:nSig 
                    next = uitreenode(app.UniqueGroupNodes{cnt},'Text',sname{ind},'NodeData',[sind(ind),1] ) ; 
                    app.Sig2NodeMap{sind(ind)}  = next ; 
%                     CheckboxTreeNode('Name',sname{ind},'Parent',Node1,...
%                         'TooltipString',shelp{ind},'UserData',[sind(ind),1]) ; 
                end 
                app.UniqueGroups{cnt} = { app.UniqueGroups{cnt} , Node1 , sind , sname , shelp} ; 
            end 
            
            %app = WriteRecTable(app,RecSetup) ; 
                      
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if ( ~length(selectedNodes) == 1 )
                return 
            end 
            app.Descriptor = selectedNodes ; 
            app.SignalnameEditField.Value = app.Descriptor.Text ; 
            app.SignalindexEditField.Value = app.Descriptor.NodeData(1) ; 
            app.AcceptButton.Enable = 'on' ; 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Descriptor  = [] ; 
            UIFigureCloseRequest(app) ; 
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes) 
                app.Descriptor = 1 ; 
            end 
            UIFigureCloseRequest(app) ; 
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            desc = struct('Descriptor',app.Descriptor,'Percent',app.TriggerPercentEditField.Value,'Action',app.DropDown.Value,'TrigVal',app.ValueEditField.Value)  ; 
            save TrigSelect desc

            delete(app)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %value = app.DropDown.Value;
            app.AcceptButton.Enable = 'on' ; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RecordertriggerselectorLabel = uilabel(app.UIFigure);
            app.RecordertriggerselectorLabel.Text = 'Recorder trigger selector';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SignalindexEditFieldLabel = uilabel(app.UIFigure);
            app.SignalindexEditFieldLabel.Text = 'Signal index';
            app.SignalindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalnameEditFieldLabel = uilabel(app.UIFigure);
            app.SignalnameEditFieldLabel.Text = 'Signal  name';
            app.SignalnameEditField = uieditfield(app.UIFigure, 'text');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Immediate', 'Up', 'Down', 'Equal', 'Triggered'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Up';
            app.TriggerEditFieldLabel = uilabel(app.UIFigure);
            app.TriggerEditFieldLabel.Text = 'Trigger %';
            app.TriggerPercentEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Enable = 'off';
            app.AcceptButton.Text = 'Accept';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TriggerSel(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end